classdef exploreFNIRS_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        unitgroup                       matlab.ui.container.ButtonGroup
        listbox_optode                  matlab.ui.control.ListBox
        pushbutton_optodes_select_all   matlab.ui.control.Button
        pushbutton_optodes_select_none  matlab.ui.control.Button
        text_optode_label               matlab.ui.control.Label
        text_biomarker_label            matlab.ui.control.Label
        listbox_biomarker               matlab.ui.control.ListBox
        pushbutton_biomarker_select_all  matlab.ui.control.Button
        pushbutton_biomarker_select_none  matlab.ui.control.Button
        listbox_raw_methods             matlab.ui.control.ListBox
        listbox_oxy_methods             matlab.ui.control.ListBox
        text25                          matlab.ui.control.Label
        pushbutton_refresh_methods      matlab.ui.control.Button
        pushbutton_import_raw           matlab.ui.control.Button
        pushbutton_import_oxy           matlab.ui.control.Button
        text31                          matlab.ui.control.Label
        pushbutton_clear_processed      matlab.ui.control.Button
        popupmenu_ChannelMode           matlab.ui.control.DropDown
        checkbox_process_raw            matlab.ui.control.CheckBox
        uipanel1                        matlab.ui.container.Panel
        listbox_subjectID               matlab.ui.control.ListBox
        text8                           matlab.ui.control.Label
        listbox_group                   matlab.ui.control.ListBox
        text9                           matlab.ui.control.Label
        listbox_session                 matlab.ui.control.ListBox
        text10                          matlab.ui.control.Label
        listbox_trial                   matlab.ui.control.ListBox
        text11                          matlab.ui.control.Label
        listbox_condition               matlab.ui.control.ListBox
        text12                          matlab.ui.control.Label
        pushbutton_group_select_all     matlab.ui.control.Button
        pushbutton_group_select_none    matlab.ui.control.Button
        pushbutton_session_select_all   matlab.ui.control.Button
        pushbutton_session_select_none  matlab.ui.control.Button
        pushbutton_trial_select_all     matlab.ui.control.Button
        pushbutton_trial_select_none    matlab.ui.control.Button
        pushbutton_condition_select_all  matlab.ui.control.Button
        pushbutton_condition_select_none  matlab.ui.control.Button
        pushbutton_subjectID_select_all  matlab.ui.control.Button
        pushbutton_subjectID_select_none  matlab.ui.control.Button
        checkbox_subjectID_plotby       matlab.ui.control.CheckBox
        checkbox_group_plotby           matlab.ui.control.CheckBox
        checkbox_session_plotby         matlab.ui.control.CheckBox
        checkbox_trial_plotby           matlab.ui.control.CheckBox
        checkbox_condition_plotby       matlab.ui.control.CheckBox
        listbox_block                   matlab.ui.control.ListBox
        text30                          matlab.ui.control.Label
        pushbutton_block_select_all     matlab.ui.control.Button
        pushbutton_block_select_none    matlab.ui.control.Button
        checkbox_block_plotby           matlab.ui.control.CheckBox
        checkbox_auto_update            matlab.ui.control.CheckBox
        listbox_subgroup                matlab.ui.control.ListBox
        text40                          matlab.ui.control.Label
        checkbox_use_group              matlab.ui.control.CheckBox
        checkbox_use_subgroup           matlab.ui.control.CheckBox
        pushbutton_process_selection    matlab.ui.control.Button
        popupmenu_groupby_info_field    matlab.ui.control.DropDown
        listbox_info_groupby            matlab.ui.control.ListBox
        pushbutton_info_groupby_select_all  matlab.ui.control.Button
        pushbutton_info_groupby_select_none  matlab.ui.control.Button
        checkbox_info_groupby_plotby    matlab.ui.control.CheckBox
        text57                          matlab.ui.control.Label
        uipanel5                        matlab.ui.container.Panel
        text41                          matlab.ui.control.Label
        popupmenu_legend_mode           matlab.ui.control.DropDown
        checkbox_ylim_fixed             matlab.ui.control.CheckBox
        checkbox_ylim_manual            matlab.ui.control.CheckBox
        edit_ylim_min                   matlab.ui.control.EditField
        edit_ylim_max                   matlab.ui.control.EditField
        text48                          matlab.ui.control.Label
        text49                          matlab.ui.control.Label
        edit_plot_start                 matlab.ui.control.EditField
        edit_plot_end                   matlab.ui.control.EditField
        text35                          matlab.ui.control.Label
        text22                          matlab.ui.control.Label
        text_status                     matlab.ui.control.Label
        uipanel6                        matlab.ui.container.Panel
        popupmenu_colors                matlab.ui.control.DropDown
        checkbox_auto_color             matlab.ui.control.CheckBox
        checkbox_gui_colors             matlab.ui.control.CheckBox
        pushbutton_gui_color_1          matlab.ui.control.Button
        pushbutton_gui_color_3          matlab.ui.control.Button
        pushbutton_gui_color_5          matlab.ui.control.Button
        pushbutton_gui_color_7          matlab.ui.control.Button
        pushbutton_gui_color_9          matlab.ui.control.Button
        pushbutton_gui_color_2          matlab.ui.control.Button
        pushbutton_gui_color_4          matlab.ui.control.Button
        pushbutton_gui_color_6          matlab.ui.control.Button
        pushbutton_gui_color_8          matlab.ui.control.Button
        pushbutton_gui_color_10         matlab.ui.control.Button
        pushbutton_gui_color_save       matlab.ui.control.Button
        pushbutton_gui_color_load       matlab.ui.control.Button
        listbox_color_order             matlab.ui.control.ListBox
        pushbutton_gui_color_move_up    matlab.ui.control.Button
        pushbutton_gui_color_move_down  matlab.ui.control.Button
        uipanel7                        matlab.ui.container.Panel
        listbox_hierarchy               matlab.ui.control.ListBox
        text43                          matlab.ui.control.Label
        pushbutton_move_hierarchy_up    matlab.ui.control.Button
        pushbutton_move_hierarchy_down  matlab.ui.control.Button
        popupmenu_within_sub_avg        matlab.ui.control.DropDown
        text44                          matlab.ui.control.Label
        edit_baseline_start             matlab.ui.control.EditField
        edit_baseline_end               matlab.ui.control.EditField
        text33                          matlab.ui.control.Label
        edit_block_start                matlab.ui.control.EditField
        edit_block_end                  matlab.ui.control.EditField
        text34                          matlab.ui.control.Label
        text20                          matlab.ui.control.Label
        text21                          matlab.ui.control.Label
        text59                          matlab.ui.control.Label
        text60                          matlab.ui.control.Label
        text16                          matlab.ui.control.Label
        checkbox_usebaseline            matlab.ui.control.CheckBox
        text_versInfo                   matlab.ui.control.Label
        uipanel12                       matlab.ui.container.Panel
        checkbox_LME_enable             matlab.ui.control.CheckBox
        checkbox_LME_all_interactions   matlab.ui.control.CheckBox
        pushbutton_lme_plot_topo        matlab.ui.control.Button
        checkbox_LME_use_intercept      matlab.ui.control.CheckBox
        checkbox_discreteTime           matlab.ui.control.CheckBox
        text63                          matlab.ui.control.Label
        popupmenu_lmer_randomeffects    matlab.ui.control.DropDown
        checkbox_lme_usecustom          matlab.ui.control.CheckBox
        pushbutton_custom_lme           matlab.ui.control.Button
        popupmenu_topoSig               matlab.ui.control.DropDown
        uipanel13                       matlab.ui.container.Panel
        checkbox_plot_all_data          matlab.ui.control.CheckBox
        checkbox_plot_grandaverage      matlab.ui.control.CheckBox
        popupmenu_grandaverage_feature  matlab.ui.control.DropDown
        checkbox_plot_error             matlab.ui.control.CheckBox
        popupmenu_errorbar_feature      matlab.ui.control.DropDown
        edit_error_multiplier           matlab.ui.control.EditField
        text28                          matlab.ui.control.Label
        text36                          matlab.ui.control.Label
        edit_grandavg_resample_size     matlab.ui.control.EditField
        text37                          matlab.ui.control.Label
        checkbox_mark_task              matlab.ui.control.CheckBox
        popupmenu_errorbar_style        matlab.ui.control.DropDown
        text50                          matlab.ui.control.Label
        pushbutton_plot_temporal        matlab.ui.control.Button
        checkbox_yaxis                  matlab.ui.control.CheckBox
        uipanel15                       matlab.ui.container.Panel
        text23                          matlab.ui.control.Label
        edit_barchart_resample_size     matlab.ui.control.EditField
        checkbox_plot_barchart_ga       matlab.ui.control.CheckBox
        text32                          matlab.ui.control.Label
        popupmenu_bar_feature           matlab.ui.control.DropDown
        checkbox_plot_barchart_error    matlab.ui.control.CheckBox
        popupmenu_bar_error_feature     matlab.ui.control.DropDown
        edit_bar_error_multiplier       matlab.ui.control.EditField
        text38                          matlab.ui.control.Label
        checkbox_plot_barchart_all_points  matlab.ui.control.CheckBox
        pushbutton_plot_barchart        matlab.ui.control.Button
        pushbutton_export_csv           matlab.ui.control.Button
        checkbox_export_9999            matlab.ui.control.CheckBox
        uipanel16                       matlab.ui.container.Panel
        checkbox_plot_scatter_error     matlab.ui.control.CheckBox
        popupmenu_scatter_error_feature  matlab.ui.control.DropDown
        edit_scatter_error_multiplier   matlab.ui.control.EditField
        text55                          matlab.ui.control.Label
        checkbox_plot_scatter_nonparametric  matlab.ui.control.CheckBox
        checkbox_plot_scatter_line      matlab.ui.control.CheckBox
        popupmenu_scatter_error_style   matlab.ui.control.DropDown
        text56                          matlab.ui.control.Label
        checkbox_plot_scatter_extend    matlab.ui.control.CheckBox
        checkbox_plot_scatter_flipxy    matlab.ui.control.CheckBox
        pushbutton_plot_scatter         matlab.ui.control.Button
        text62                          matlab.ui.control.Label
        pushbutton_plot_scatter_topo    matlab.ui.control.Button
        uipanel17                       matlab.ui.container.Panel
        popupmenu_info_group            matlab.ui.control.DropDown
        uipanel9                        matlab.ui.container.Panel
        popupmenu_info_field            matlab.ui.control.DropDown
        text45                          matlab.ui.control.Label
        pushbutton_infoData_barchart    matlab.ui.control.Button
        checkbox_LME_info_covariate     matlab.ui.control.CheckBox
        pushbutton_infoData_scatter     matlab.ui.control.Button
        text_status_text                matlab.ui.control.Label
        uipanel19                       matlab.ui.container.Panel
        pushbutton_exLoad               matlab.ui.control.Button
        pushbutton_exSave               matlab.ui.control.Button
    end

    
    methods (Access = private)
        function outTable = BuildSegmentInfoTable(app, FNIRS_array)
            
            warning off MATLAB:table:RowsAddedExistingVars
            
            global ProgressHandles
            
            if(isempty(FNIRS_array))
                return;
            else
                pf2_base.closeProgressHandles();
            
                numF=length(FNIRS_array);
                hF=waitbar(0,sprintf('ExploreFNIRS\nBuilding Table: Row %i of %i',1,numF));
            
                outTable=table();
                for i=1:numF
                    try
                        waitbar(i/numF,hF,sprintf('ExploreFNIRS\nBuilding Table: Row %i of %i',i,numF));
                    catch
                        hF=waitbar(i/numF,0,sprintf('ExploreFNIRS\nBuilding Table: Row %i of %i',i,numF));
                    end
                   curFNIRseg=FNIRS_array{i};
            
                   if(~isfield(curFNIRseg,'info'))
                       warning('All fNIRS segments must have a .info section');
                       continue;
                   end
                   curFields=fields(curFNIRseg.info);
                   for j=1:length(curFields)
                       curFieldName=curFields{j};
            
                       curField=curFNIRseg.info.(curFieldName);


                       if(isempty(curField)|| ...
                               (isnumeric(curField)&&length(curField)==1)||...  %numeric items of 1
                               ischar(curField)||...        %strings or chars
                               (islogical(curField)&&length(curField)==1)||...   %logical values
                               (istable(curField)&&size(curField,1)==1&&size(curField,2)==1)) %singular tables
            
                          if(istable(curField)&&size(curField,1)==1&&size(curField,2)==1)
                              curField=curField{1,1};
                          end
            
                          if(ismember(curFieldName,outTable.Properties.VariableNames)&&~isempty(curField))
                              if(strcmpi(curField,'missing')&&isnumeric(outTable.(curFieldName)(1,1)))
                                  outTable.(curFieldName)(i,1)=nan;
                              else
                                  outTable.(curFieldName)(i,1)=curField;
                              end
                          elseif(~isempty(curField))
                              if(ischar(curField)) % adds columns
                                  outTable.(curFieldName)=strings(size(outTable,1),1);
                                  outTable.(curFieldName)(i,1)=nominal(curField);
                              elseif(isstring(curField))
                                  outTable.(curFieldName)=strings(size(outTable,1),1);
                                  outTable.(curFieldName)(i,1)=nominal(curField);
                              elseif(isnumeric(curField))
                                  outTable.(curFieldName)=nan(size(outTable,1),1);
                                  outTable.(curFieldName)(i,1)=curField;
                              elseif(islogical(curField))
                                  outTable.(curFieldName)=strings(size(outTable,1),1);
                                  outTable.(curFieldName)(i,1)=nominal(string(curField));
                              end
            
                          end
                       end
                   end
                end
                close(hF);
            end
        end
        
        function PopulateGUIfields(dataTable, app)
            
            global ExFNIRS
            
            uSub=unique(dataTable.('SubjectID'));
            uGroup=unique(dataTable.('Group'));
            uSubgroup=unique(dataTable.('Subgroup'));
            uSession=unique(dataTable.('Session'));
            uTrial=unique(dataTable.('Trial'));
            uCondition=unique(dataTable.('Condition'));
            uBlock=unique(dataTable.('Block'));
            
            
            set(app.listbox_subjectID,'Items',uSub);
            set(app.listbox_subjectID,'Value',1:length(uSub));
            if(length(uSub)==1)
                set(app.listbox_subjectID,'Enable','off');
                set(app.checkbox_subjectID_plotby,'Enable','off');
                set(app.checkbox_subjectID_plotby,'Value',0);
                ExFNIRS.settings.plotby.SubjectID=0;
            end
            set(app.listbox_group,'Items',uGroup);
            set(app.listbox_group,'Value',1:length(uGroup));
            if(length(uGroup)==1)
                set(app.listbox_group,'Enable','off');
                set(app.checkbox_use_group,'Enable','off');
            end
            set(app.listbox_subgroup,'Items',uSubgroup);
            set(app.listbox_subgroup,'Value',1:length(uSubgroup));
            if(length(uSubgroup)==1)
                set(app.listbox_subgroup,'Enable','off');
                set(app.checkbox_use_subgroup,'Enable','off');
                set(app.checkbox_use_subgroup,'Value',0);
            end
            
            if(length(uGroup)==1&&length(uSubgroup)==1)
                set(app.checkbox_group_plotby,'Enable','off');
                set(app.checkbox_group_plotby,'Value',0);
                ExFNIRS.settings.plotby.Group=0;
            elseif(length(uGroup)==1&&length(uSubgroup)>1)
                set(app.checkbox_use_group,'Value',0);
                set(app.checkbox_use_subgroup,'Value',1);
            end
            
            set(app.listbox_session,'Items',uSession);
            set(app.listbox_session,'Value',1:length(uSession));
            if(length(uSession)==1)
                set(app.listbox_session,'Enable','off');
                set(app.checkbox_session_plotby,'Enable','off');
                set(app.checkbox_session_plotby,'Value',0);
                ExFNIRS.settings.plotby.Session=0;
            end
            set(app.listbox_trial,'Items',uTrial);
            set(app.listbox_trial,'Value',1:length(uTrial));
            if(length(uTrial)==1)
                set(app.listbox_trial,'Enable','off');
                set(app.checkbox_trial_plotby,'Enable','off');
                set(app.checkbox_trial_plotby,'Value',0);
                ExFNIRS.settings.plotby.Trial=0;
            end
            set(app.listbox_condition,'Items',uCondition);
            set(app.listbox_condition,'Value',1:length(uCondition));
            if(length(uCondition)==1)
                set(app.listbox_condition,'Enable','off');
                set(app.checkbox_condition_plotby,'Enable','off');
                set(app.checkbox_condition_plotby,'Value',0);
                ExFNIRS.settings.plotby.Condition=0;
            end
            set(app.listbox_block,'Items',uBlock);
            set(app.listbox_block,'Value',1:length(uBlock));
            if(length(uBlock)==1)
                set(app.listbox_block,'Enable','off');
                set(app.checkbox_block_plotby,'Enable','off');
                set(app.checkbox_block_plotby,'Value',0);
                ExFNIRS.settings.plotby.Block=0;
            end
            
            
            curRawMethods=processFNIRS2_configureMethods('currentRawMethodsCallback',1,app);
            if(~isempty(curRawMethods))
               set(app.listbox_raw_methods,'Items',curRawMethods);
            end
            curOxyMethods=processFNIRS2_configureMethods('currentOxyMethodsCallback',1,app);
            if(~isempty(curOxyMethods))
               set(app.listbox_oxy_methods,'Items',curOxyMethods);
            end
            
            setExChannelMode('ROI',app,true)%initialize ROI fields
            setExChannelMode('fNIR',app);
        end
        
        function addDebugAnnotation(app, figHandle, optionalstring)
            global ExFNIRS
            curTime = datetime(now,'ConvertFrom','datenum');
            debugString=sprintf('%s\n%s (%s)\n%s',ExFNIRS.curMethodName,ExFNIRS.statusGroupByStr,ExFNIRS.settings.within_sub_avg_mode_label,curTime);
            if(nargin>1)
                debugString=sprintf('%s\n%s',debugString,optionalstring);
            end
            
            debugString(debugString==('_'))='-';
            th=annotation(figHandle,'textbox',[0 0 0.1 1],'Items',debugString,'FitBoxToText','on');
            th.FontSize = 6;
            th.LineStyle='none';
            th.HorizontalAlignment='left';
            th.VerticalAlignment='bottom';
            curPos=th.Position;
        end
        
        function contrastTable = autoContrast(app, mdl, pThreshold)
            
            if(nargin<2)
                pThreshold=0.1;
            end
            
            coefNames=mdl.CoefficientNames;
            numCoef=length(coefNames);
            
            [uCoefParts,b,uCoefIdx]=unique(strsplit(sprintf('%s:',coefNames{:}),':'));
            
            [~,idx]=sort(b);
            uCoefParts=uCoefParts(idx);
            if(contains(uCoefParts{1},'(Intercept)'))
                uCoefParts{1}='Intercept';
                posthocmode='effects';
            else
                posthocmode='full';
            end
            anv=anova(mdl,'DFMethod','satterthwaite');
            hasIntercept=false;
            anvTerms=anv.Term;
            numAnv=length(anvTerms);
            for i=1:numAnv % Get "root' terms (non-interaction terms)
            
               curTerm=strsplit(anvTerms{i},':');
               numAnvTerms(i)=length(curTerm);
               if(contains(curTerm{1},'Intercept'))
                  hasIntercept=true;
                  anvTerms{i}='Intercept';
               end
               if(length(curTerm)==1)
                   curTerm=curTerm{1};
                   curTerm(curTerm=='('|curTerm==')')=[];
                   rootAnvTerm{i}=curTerm;
                   uCoefTerms{i}=uCoefParts(contains(uCoefParts,sprintf('%s',curTerm)));
               elseif(numAnv==1&&length(curTerm)>1&&~hasIntercept)
                   rootAnvTerm=curTerm;
                   for j=1:length(curTerm)
                        uCoefTerms{j}=uCoefParts(contains(uCoefParts,sprintf('%s',curTerm{j})));
                    end
               end
            end
            
            
            rootCoefNames=cell(length(rootAnvTerm),1);
            rootCoefIdx=nan(length(rootAnvTerm),max(numAnvTerms));
            coefTermParts=cell(length(coefNames),size(rootAnvTerm,2));
            coefTermPartsIdx=nan(size(coefTermParts));
            for i=1:numCoef %find which are the root terms in coefficients
               curTerms=strsplit(coefNames{i},':');
               numCoefTerms=length(curTerms);
               curAnvTerms='';
               curRootTerm=nan(size(curTerms));
               for t=1:numCoefTerms
                   curTerm=curTerms{t};
                   curTerm(curTerm=='('|curTerm==')')=[];
                   for j=1:length(rootAnvTerm)
                       if(~isempty(regexp(curTerm,sprintf('^%s',rootAnvTerm{j}))))
                           rootCoefNames{j}=[{curTerm},rootCoefNames{j}];
                           rootCoefIdx(i,j)=find(ismember(uCoefTerms{j},curTerm));
                           coefTermParts{i,j}=curTerm;
                           curRootTerm(t)=j;
                           curAnvTerms=sprintf('%s:%s',curAnvTerms,rootAnvTerm{j});
                           break;
                       end
                   end
               end
               curAnvTerms(1)='';
               coefTermAnv(i)=find(ismember(anvTerms,curAnvTerms));
               if(numCoefTerms>1)
                   curCoefIdx=rootCoefIdx(i,:);
                   for t=1:length(curTerms)
                       j=curRootTerm(t);
                       curCoefIdx_loo=curCoefIdx;
                       curCoefIdx_loo(j)=nan; %set current term to 0
                       strParts={};
                       for(t2=1:length(curCoefIdx_loo))
                           if(~isnan(curCoefIdx_loo(t2))&&curCoefIdx_loo(t2)>0)
                                strParts{end+1}=uCoefTerms{t2}{curCoefIdx_loo(t2)};
                           end
                       end
            
                       for r=1:i
                            isStr=true;
                           for s=1:length(strParts)
                                isStr=isStr&&contains(coefNames{r},strParts{s});
                           end
                           if(isStr)
                              break;
                           end
                       end
            
                       coefTermPartsIdx(i,j)=r;
                   end
            
               end
            end
            
            rootCoefIdx(rootCoefIdx==0)=nan;
            
            
            sigAnv=find(anv.pValue<pThreshold);
            
            if(isempty(sigAnv))
                contrastTable=table();
                return;
            else
               sigAnvNames=anv.Term(sigAnv);
            end
            
            sigCoef=mdl.Coefficients;
            sigCoefIdx=sigCoef.pValue<=pThreshold;
            
            cRows=[];
            cAnvGrp=[];
            cName={};
            nRows=[];
            
            for s=1:length(sigAnvNames)
               sIdx=sigAnv(s);
               basic_contrast_idx=find(coefTermAnv==sIdx);
               interaction_contrast_idx=coefTermPartsIdx(coefTermAnv==sIdx,:);
               for c=1:length(basic_contrast_idx)
                  if(numAnvTerms(sIdx)==1&&hasIntercept) %compare with intercept only
                      nRows(end+1)=1;
                      cRow=zeros(1,numCoef);
                      cRow(1)=-1;
                      cRow(basic_contrast_idx(c))=1;
                      cRows(end+1,:)=cRow;
                      if(contains(coefNames{basic_contrast_idx(c)},'(Intercept)'))
                        cName{end+1}='Intercept vs 0';
                      else
                        cName{end+1}=sprintf('%s vs %s',coefNames{basic_contrast_idx(c)},'Intercept');
                      end
                      cAnvGrp(end+1)=c;
                  elseif(numAnvTerms(sIdx)>1&&length(numAnvTerms)>1) %compare term and numterms-1 vs 0
            
                      nRows(end+1)=1;
                      cIdx=interaction_contrast_idx(c,:);
                      cIdx=cIdx(~isnan(cIdx));
                      [cIdx,uidx]=unique(cIdx);
                      cIdx=cIdx(uidx);
                      numContrasts=length(cIdx);
                      for c2=1:numContrasts % compare within matched groups
                          cRow=zeros(1,numCoef);
                          cRow(cIdx)=1;
                          cRow(cIdx(c2))=0;
                          cRow(basic_contrast_idx(c))=1;
                          cRows(end+1,:)=cRow;
                          cName{end+1}=sprintf('%s vs %s',coefNames{basic_contrast_idx(c)},coefNames{cIdx(c2)});
                            cAnvGrp(end+1)=c;
                      end


                  else %compare vs 0
                      if(sigCoefIdx(c))
                          nRows(end+1)=1;
                          cRow=zeros(1,numCoef);
                          %curCterms=coefTermIdx(basic_contrast_idx(c),:);
                          %curCterms=curCterms(curCterms>0);
                          cRow(basic_contrast_idx(c))=1;
                          cRows(end+1,:)=cRow;
                          cName{end+1}=sprintf('%s vs 0',coefNames{basic_contrast_idx(c)});
                          cAnvGrp(end+1)=c;
                      end
                  end
                  if(numAnvTerms(sIdx)>1)&&length(numAnvTerms)==1&&~hasIntercept %check for full model case (and multiple terms)
                      if(sigCoefIdx(c))
                          nRows(end+1)=1;
                          cIdx=rootCoefIdx(c,:); % now these are the model terms of all interactions
                          cmp_contrast_idx=rootCoefIdx;
                          cmp_contrast_idx(c,:)=nan;
                          %cIdx=cIdx(~isnan(cIdx));
                          numContrasts=length(cIdx);
                          for c2=1:numContrasts % compare with groups 1 above
                              cmp_contrast=cmp_contrast_idx(:,c2)==cIdx(c2);
                              cRow=zeros(1,numCoef);
                              cRow(c)=1;
                              cRow(cmp_contrast)=-1;
            
                              if(any(ismember(cRows,cRow,'rows'))||any(ismember(cRows,cRow*-1,'rows'))...
                                    ||sum(cmp_contrast)==1) % skips when the full interaction term is a better descripter
                                continue;
                              else
                                cRows(end+1,:)=cRow;
                                uc=uCoefTerms{c2};
                                cName{end+1}=sprintf('%s vs %s',uc{cIdx(c2)},coefNames{c});
                                cAnvGrp(end+1)=c;
                              end
                          end
            
                          for c2=1:numContrasts % repeat within similar groupss
                              cmp_contrast=cmp_contrast_idx(:,c2)==cIdx(c2);
            
                              cmp_contrast_vals=find(cmp_contrast==1);
                              for cmp=1:length(cmp_contrast_vals)
            
                                  cRow=zeros(1,numCoef);
                                  cRow(c)=1;
                                  cRow(cmp_contrast_vals(cmp))=-1;
            
                                  if(any(ismember(cRows,cRow,'rows'))||any(ismember(cRows,cRow*-1,'rows')))
                                    continue;
                                  else
                                    cRows(end+1,:)=cRow;
                                    uc=uCoefTerms{c2};
            
                                    cName{end+1}=sprintf('%s vs %s',coefNames{c},coefNames{cmp_contrast_vals(cmp)});
                                    cAnvGrp(end+1)=c;
                                  end
            
                              end
                          end
                      end
                   else
                        for c2=c+1:length(basic_contrast_idx) % compare within similar groups
                          nRows(end+1)=1;
                          cRow=zeros(1,numCoef);
                          cRow(basic_contrast_idx(c2))=-1;
                          cRow(basic_contrast_idx(c))=1;
            
                          cRows(end+1,:)=cRow;
                          cName{end+1}=sprintf('%s vs %s',coefNames{basic_contrast_idx(c)},coefNames{basic_contrast_idx(c2)});
                          cAnvGrp(end+1)=c;
                      end
                   end
               end
            end
            
            [~,~,mdlCoef]=fixedEffects(mdl,'DFMethod','satterthwaite');
            for c=1:size(cRows,1)
                curRow=cRows(c,:);
               [pVal(c),F(c),df(c),df2(c)]= coefTest(mdl,curRow,0,'DFMethod','satterthwaite');
            
               %df2(c)=mdlCoef.DF(c); %overwrite with satterwaite coefs
            
               mdlCoefAnv=mdlCoef(curRow==1,:);
               mdlCoefCompare=mdlCoef(curRow==-1,:);
               if(contains(cName{c},'Intercept'))
                    deltaE(c)=sum(mdlCoefAnv.Estimate);
               else
                    deltaE(c)=sum(mdlCoefAnv.Estimate)-sum(mdlCoefCompare.Estimate);
               end
            
               SD_anv_temp=mdlCoefAnv.SE;
               SD_cmp=mdlCoefCompare.SE;
            
               SD_p(c)=sqrt(sum((mdlCoefAnv.DF).*(SD_anv_temp.^2))+...
                   sum((mdlCoefCompare.DF).*(SD_cmp.^2)))...
                   /sqrt(sum(mdlCoefAnv.DF)+sum(mdlCoefCompare.DF));
               SD_anv(c)=sqrt(sum((mdlCoefAnv.DF).*(SD_anv_temp.^2)))...
                   /sqrt(sum(mdlCoefAnv.DF));
               %SE_p(c)=SD_p(c)/sqrt(mean(mdlCoefAnv.DF));
               HedgesG(c)=deltaE(c)/SD_p(c);
               GlassesDelta(c)=deltaE(c)/SD_anv(c);
            end
            
            [uAnvG,~,idxAnvG]=unique(cAnvGrp);
            uCount=histcounts(cAnvGrp);
            uCounts=uCount(idxAnvG);
            pVal_corr=pVal(:).*uCounts(:);
            pVal_corr(pVal_corr>1)=1;
            
            contrastTable=table(deltaE',SD_p',F',df',df2',pVal',pVal_corr,'VariableNames',{'deltaE','SD','F','df1','df2','pVal','pVal_corr'},'RowNames',cName');
        end
        
        function checkbox_code_nan_Callback(app, hObject, eventdata, handles)
            % hObject    handle to checkbox_code_nan (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_code_nan
            global ExFNIRS
            ExFNIRS.settings.code_missing=get(app.checkbox_code_nan,'Value');
        end
        
        function checkbox_shaded_err_Callback(app, hObject, eventdata, handles)
            % hObject    handle to checkbox_shaded_err (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_shaded_err
            
            global ExFNIRS
            ExFNIRS.settings.plot_error_shaded=get(app.checkbox_shaded_err,'Value');
        end
        
        function coef2coefIdx(app, coefNames, anvNames)
            
            mdlIdx
        end
        
        function displayLME(app, lme_mdl)
            %disp(lme_mdl.Forumla);
            fprintf(2,'\nUse These DFs\n');
            [~,~,stats]=fixedEffects(lme_mdl,'DFMethod','satterthwaite');
            disp(stats);
            %disp(lme_mdl.RandomEffects);
            % [~,~,stats]=randomEffects(lme_mdl,'DFMethod','satterthwaite');
            % disp(stats);
            disp(anova(lme_mdl,'DFMethod','satterthwaite'));
        end
        
        function exportLongTable(app, times)
            
            global ExFNIRS
            
            bioMList={'HbO','HbR','HbDiff','HbTotal','CBSI'};
            
            if(isempty(ExFNIRS.selectedTable))
                return;
            end
            
            [file, pathname] = uiputfile({'*.csv';'*.mat';'*.*'},'Export Segment Table');
            if(isempty(file)||~ischar(file)||(isnumeric(file)&&file==0))
                return
            end
            
            if(strcmp(lower(file(end-3:end)),'mat'))
                exportMAT=true;
            else
                exportMAT=false;
            end
            
            logFileName=sprintf('%s_long.log',file(1:end-4));
            
            
            exportTable=mergeGbyTablesLong(app, ExFNIRS.gby,bioMList,[],times,true,true);
            
            
            if(ExFNIRS.settings.export_replace_missing_9999)
                exportTable=nan_to_9999(app, exportTable);
            else
                exportTable=remove9999(app, exportTable);
            end
            
            
            numGroups=length(ExFNIRS.gby);
            
            if(exportMAT)
                grandAvgData=cell(1,numGroups);
                grandAvgBarData=cell(1,numGroups);
            end
            
            for g=1:numGroups
                curGby=ExFNIRS.gby(g);
            
                if(isempty(curGby))
                    continue;
                end
            
            
                if(exportMAT)
                    grandAvgData{g}=curGby.gbyGrand;
                    grandAvgBarData{g}=curBarGA;
                end
            
            end
            
            
            %if(ExFNIRS.settings.code_missing)
            %    disp('Not yet enabled');
            %end
            
            if(~exportMAT)
                writetable(exportTable, sprintf('%s/%s',pathname,file),'QuoteStrings',true);
            else
                save(sprintf('%s/%s',pathname,file),'exportTable','grandAvgData','grandAvgBarData');
            end
            
            writeLogFile(app, logFileName,pathname);
            
            fprintf('Data exported to %s\n',sprintf('%s%s',pathname,file));
        end
        
        function exportWideTable(app, times)
            
            global ExFNIRS
            
            bioMList={'HbO','HbR','HbDiff','HbTotal','CBSI'};
            
            if(isempty(ExFNIRS.selectedTable))
                return;
            end
            
            [file, pathname] = uiputfile({'*.csv';'*.mat';'*.*'},'Export Segment Table');
            if(isempty(file)||~ischar(file)||(isnumeric(file)&&file==0))
                return
            end
            
            if(strcmp(lower(file(end-3:end)),'mat'))
                exportMAT=true;
            else
                exportMAT=false;
            end
            
            logFileName=sprintf('%s_wide.log',file(1:end-4));
            
            exportTable=mergeGbyTablesWide(app, ExFNIRS.gby,bioMList,[],times,true,true);
            
            if(ExFNIRS.settings.export_replace_missing_9999)
                exportTable=nan_to_9999(app, exportTable);
            else
                exportTable=remove9999(app, exportTable);
            end
            
            numGroups=length(ExFNIRS.gby);
            
            if(exportMAT)
                grandAvgData=cell(1,numGroups);
                grandAvgBarData=cell(1,numGroups);
            end
            
            for g=1:numGroups
                curGby=ExFNIRS.gby(g);
            
                if(isempty(curGby))
                    continue;
                end
            
            
                if(exportMAT)
                    grandAvgData{g}=curGby.gbyGrand;
                    grandAvgBarData{g}=curBarGA;
                end
            
            end
            
            
            %if(ExFNIRS.settings.code_missing)
            %    disp('Not yet enabled');
            %end
            
            if(~exportMAT)
                writetable(exportTable, sprintf('%s/%s',pathname,file),'QuoteStrings',true);
            else
                save(sprintf('%s/%s',pathname,file),'exportTable','grandAvgData','grandAvgBarData');
            end
            
            writeLogFile(app, logFileName,pathname);
            
            fprintf('Data exported to %s\n',sprintf('%s/%s',pathname,file));
        end
        
        function flagForUpdate(app,UpdateNeeded)
            
            global ExFNIRS
            
            
            if(~isfield(ExFNIRS,'UpdateNeeded'))
               ExFNIRS.UpdateNeeded=3;
            end
            
            if(UpdateNeeded>0)
                if(ExFNIRS.UpdateNeeded<UpdateNeeded)
                    ExFNIRS.UpdateNeeded=UpdateNeeded; %2 indicates fNIRS data needs to be reprocessed as well
                                                       % 3 indicates fNIRS must be
                                                       % reprocessed entirely
                end
                set(app.pushbutton_process_selection,'BackgroundColor','Red');
                updateSelectedTable(app);
            else
                ExFNIRS.UpdateNeeded=false;
                set(app.pushbutton_process_selection,'BackgroundColor','Green');
            end
        end
        
        function outStr = getFormattedTrialString(app, fNIR)
            
            if(~isfield(fNIR,'info'))
                outStr='Missing Info';
                return;
            end
            
            outStr='';
            
            subStr=num2strOrNot(app, fNIR.info.SubjectID);
            groupStr=num2strOrNot(app, fNIR.info.Group);
            sessionStr=num2strOrNot(app, fNIR.info.Session);
            conditionStr=num2strOrNot(app, fNIR.info.Condition);
            trialStr=num2strOrNot(app, fNIR.info.Trial);
            blockStr=num2strOrNot(app, fNIR.info.Block);
            
            useID=true&&~isempty(subStr);
            useGroup=true&&~isempty(groupStr);
            useSession=true&&~isempty(sessionStr);
            useCondition=true&&~isempty(conditionStr);
            useTrial=true&&~isempty(trialStr);
            useBlock=true&&~isempty(blockStr);
            
            if(useID)
                outStr=sprintf('%sSubjectID:%s\n',outStr,subStr);
            end
            if(useGroup)
                outStr=sprintf('%sGroup:%s\n',outStr,groupStr);
            end
            if(useSession)
                outStr=sprintf('%sSession:%s\n',outStr,sessionStr);
            end
            if(useCondition)
                outStr=sprintf('%sCondition:%s\n',outStr,conditionStr);
            end
            if(useTrial)
                outStr=sprintf('%sTrial:%s\n',outStr,trialStr);
            end
            if(useBlock)
                outStr=sprintf('%sBlock:%s\n',outStr,blockStr);
            end
            
            outStr(end)='';
        end
        
        function initialize_gui(app, fig_handle, handles, isreset)
            % If the metricdata field is present and the reset flag is false, it means
            % we are we are just re-initializing a GUI by calling it from the cmd line
            % while it is up. So, bail out as we dont want to reset the data.
            
            
            % Update handles structure
            guidata(handles.figure1, handles);
        end
        
        function loadGUIcolors(app, csvPath)
            global ExFNIRS
            cRead=csvread(csvPath);
            
            if(size(cRead,1)<=10&&size(cRead,2)==3)
                cRead(cRead>1)=1;
                cRead(cRead<0)=0;
                ExFNIRS.settings.guiColor(1:size(cRead,1),:)=cRead;
            
                updateButtonListColors(app);
                set(app.listbox_color_order,'Items',cellstr(num2str([1:10]')));
                set(app.listbox_color_order,'Value',1);
            
            else
            
                error('Expected a (1-10)x3 RGB color matrix');
            end
        end
        
        function mergedTables = mergeGbyTablesLong(app, gbyTables, bioMarkers, channels, times, exportAux, exportROI)
            % hObject    handle to pushbutton_export_csv (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if(nargin<6)
                exportROI=false;
            end
            
            if(nargin<5)
                exportAux=false;
            end
            
            if(nargin<4)
                times=[];
            end
            
            if(nargin<3)
                channels=[];
            end
            
            if(isempty(channels))
               emptyChannelFlag=true;
            else
                emptyChannelFlag=false;
            end
            
            if((~exportROI&&~exportAux)) % export parameters by themself, or all channels
               exportFNIR=true;
            elseif(isempty(channels)) %used in mass export
               exportFNIR=true;
            else
                exportFNIR=false;
            end


            if(nargin<2)
                bioMarkers={'HbO','HbR','HbDiff','HbTotal','CBSI'};
            end
            
            mergedTables=table();%ExFNIRS.selectedTable;


            if(isempty(gbyTables))
                mergedTables=[];
                return;
            end


            for g=1:length(gbyTables)
                curGby=gbyTables(g);
            
                if(isempty(curGby))
                    continue;
                end
            
                if(iscell(curGby)&&length(curGby)==1)
                   curGby=curGby{1};
                end
            
                curBarGA=curGby.gbyGrandBarFlat;
            
                if(exportROI&&(emptyChannelFlag)&&pf2_base.isnestedfield(curBarGA,'ROI.HbO.data'))
                   numROI=size(curBarGA.ROI.HbO.data,2);
                   ROIs=1:numROI;
                elseif(exportROI&&~emptyChannelFlag&&pf2_base.isnestedfield(curBarGA,'ROI.HbO.data'))
                    numROI=length(channels);
                    ROIs=channels;
                else
                   numROI=0;
                end
            
                %numRows=size(tempTable,1);
                if(emptyChannelFlag&&exportFNIR)
                    numCh=size(curBarGA.HbO.data,2);
                    channels=1:numCh;
                elseif(exportFNIR)
                   numCh=length(channels);
                else
                   numCH=0;
                end
                if(isempty(times))
                    numTimes=length(curBarGA.time);
                    times=curBarGA.time;
                else
                    numTimes=length(times);
                end
            
                for tIdx=1:numTimes
                    t=times(tIdx);
                    tempTable=curGby.gbyTables;
                    tDataIdx=find(curBarGA.time==t,1);
                    if(isempty(tDataIdx))
                        continue;
                    end
                    t_end=curBarGA.segmentTimes(tDataIdx,3);
                    %tempTable.('BioM')(:,1)=string(curBioM);
                    tempTable.('Time')(:,1)=string(num2str(round(t),'%.0f'));
                    tempTable.('TimeStart')(:,1)=string(num2str(t,'%.2f'));
                    tempTable.('TimeEnd')(:,1)=string(num2str(t_end,'%.2f'));
            
                    if(exportFNIR&&~isempty(bioMarkers))
                        for b=1:length(bioMarkers)
                            curBioM=bioMarkers{b};
            
                            for c=1:numCh
                                chNum=channels(c);
                                chName=sprintf('Opt%i_%s',chNum,curBioM);
            
            
                                tempTable.(chName)(:,1)=nan;
                                tempTable.(chName)(tempTable{:,'missingFNIRS'}==0,1)=permute(curBarGA.(curBioM).data(tDataIdx,chNum,:),[3,1,2]);
                                tempTable.(chName)(tempTable{:,'missingFNIRS'}==1,1)=nan;
                            end
            
                        end
                    end
            
                    if(exportROI&&~isempty(bioMarkers)&&pf2_base.isnestedfield(curBarGA,'ROI.HbO.data'))
                        for b=1:length(bioMarkers)
                            curBioM=bioMarkers{b};
            
                            for c=1:numROI
                                chNum=ROIs(c);
                                chName=sprintf('ROI%i_%s',chNum,curBioM);
            
            
                                tempTable.(chName)(:,1)=nan;
                                tempTable.(chName)(tempTable{:,'missingFNIRS'}==0,1)=permute(curBarGA.ROI.(curBioM).data(tDataIdx,chNum,:),[3,1,2]);
                                tempTable.(chName)(tempTable{:,'missingFNIRS'}==1,1)=nan;
            
                            end
            
                        end
                    end
            
                    if(exportAux&&isfield(curBarGA,'Aux'))
                        curAuxFields=fields(curBarGA.Aux);
                        for aux=1:length(curAuxFields)
                           curAuxName=curAuxFields{aux};
                           curAux= curBarGA.Aux.(curAuxName);
                           numAuxCh=size(curAux.data,2);
                           if(numAuxCh==1)
                                newAuxName=sprintf('aux_%s',curAuxName);
                                tempTable.(newAuxName)(:,1)=nan;
                                tempTable.(newAuxName)(tempTable{:,'missingFNIRS'}==0,1)=permute(curAux.data(tDataIdx,1,:),[3,1,2]);
                                tempTable.(newAuxName)(tempTable{:,'missingFNIRS'}==1,1)=nan;
                           else
                               for ch=1:numAuxCh
                                   newAuxName=sprintf('aux_%s_%i',curAuxName,ch);
                                   tempTable.(newAuxName)(:,1)=nan;
                                   tempTable.(newAuxName)(tempTable{:,'missingFNIRS'}==0,1)=permute(curAux.data(tDataIdx,ch,:),[3,1,2]);
                                   tempTable.(newAuxName)(tempTable{:,'missingFNIRS'}==1,1)=nan;
                               end
                           end
            
                        end
                    end
            
                    mergedTables=mergeTables(app, mergedTables,tempTable);
                end
            
                %else % No fnirs info
                %    tempTable=curGby.gbyTables;
                %    mergedTables=mergeTables(mergedTables,tempTable);
                %end
            
            
            end
        end
        
        function mergedTables = mergeGbyTablesWide(app, gbyTables, bioMarkers, channels, times, exportAux, exportROI)
            % hObject    handle to pushbutton_export_csv (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if(nargin<6)
                exportROI=false;
            end
            
            if(nargin<5)
                exportAux=false;
            end
            
            if(nargin<4)
                times=[];
            end
            
            
            if(nargin<3)
                channels=[];
            end
            
            if(isempty(channels))
                emptyChannelFlag=true;
            else
               emptyChannelFlag=false;
            end
            
            
            if((~exportROI&&~exportAux)||emptyChannelFlag) %isempty used when exporting all data
                exportFNIR=true;
            else
                exportFNIR=false;
            end


            if(nargin<2)
                bioMarkers={'HbO','HbR','HbDiff','HbTotal','CBSI'};
            end
            
            mergedTables=table();%ExFNIRS.selectedTable;
            
            
            if(isempty(gbyTables))
                mergedTables=[];
                return;
            end


            for g=1:length(gbyTables)
                curGby=gbyTables(g);
            
                if(isempty(curGby))
                    continue;
                end
            
                if(iscell(curGby)&&length(curGby)==1)
                   curGby=curGby{1};
                end
            
                curBarGA=curGby.gbyGrandBarFlat;
                tempTable=curGby.gbyTables;
            
            
                %numRows=size(tempTable,1);
                if(emptyChannelFlag&&exportFNIR)
                    numCh=size(curBarGA.HbO.data,2);
                    channels=1:numCh;
                elseif(exportFNIR)
                   numCh=length(channels);
                else
                   numCh=0;
                end
            
                if(emptyChannelFlag&&exportROI&&pf2_base.isnestedfield(curBarGA,'ROI.HbO'))
                    numROI=size(curBarGA.ROI.HbO.data,2);
                    ROIs=1:numROI;
                elseif(exportFNIR&&pf2_base.isnestedfield(curBarGA,'ROI.HbO'))
                   numROI=size(curBarGA.ROI.HbO.data,2);
                   ROIs=1:numROI;
                else
                   numROI=0;
                end
            
                if(isempty(times))
                    numTimes=length(curBarGA.time);
                    times=curBarGA.time;
                else
                    numTimes=length(times);
                end
            
                numBarGATimes=length(curBarGA.time);
            
                if(exportFNIR)
                    for b=1:length(bioMarkers)
                        curBioM=bioMarkers{b};
                        for c=1:numCh
                            chNum=channels(c);
                            for t=1:numBarGATimes
                                if(ismember(curBarGA.time(t),times))
                               if(numTimes==1)
                                  varName=sprintf('%s_Opt%i',curBioM,chNum);
                               else
                                  varName=sprintf('%s_Opt%i_t%.0f',curBioM,chNum,curBarGA.time(t));
                               end
                               varName(varName=='-')='_';
                               tempTable.(varName)(tempTable{:,'missingFNIRS'}==0,1)=permute(curBarGA.(curBioM).data(t,chNum,:),[3,1,2]);
                                end
                            end
                        end
                    end
                end
            
                if(exportROI&&pf2_base.isnestedfield(curBarGA,'ROI.HbO'))
                    for b=1:length(bioMarkers)
                        curBioM=bioMarkers{b};
                        for c=1:numROI
                            chNum=ROIs(c);
                            for t=1:numBarGATimes
                                if(ismember(curBarGA.time(t),times))
                                   if(numTimes==1)
                                      varName=sprintf('%s_ROI%i',curBioM,chNum);
                                   else
                                      varName=sprintf('%s_ROI%i_t%.0f',curBioM,chNum,curBarGA.time(t));
                                   end
                                   varName(varName=='-')='_';
                                   tempTable.(varName)(tempTable{:,'missingFNIRS'}==0,1)=permute(curBarGA.ROI.(curBioM).data(t,chNum,:),[3,1,2]);
                                end
                            end
                        end
                    end
                end
            
                 if(exportAux&&isfield(curBarGA,'Aux'))
                     warning('To-do add AUX fields to export wide'); % trouble is syncing up timing between each
            %         curAuxFields=fields(curBarGA.Aux);
            %         for aux=1:length(curAuxFields)
            %            curAuxName=curAuxFields{aux};
            %            curAux= curBarGA.Aux.(curAuxName);
            %            numAuxCh=size(curAux.data,2);
            %            if(numAuxCh==1)
            %                 newAuxName=sprintf('aux_%s',curAuxName);
            %                 tempTable.(newAuxName)(:,1)=nan;
            %                 tempTable.(newAuxName)(tempTable{:,'missingFNIRS'}==0,1)=permute(curAux.data(tDataIdx,1,:),[3,1,2]);
            %            else
            %                for ch=1:numAuxCh
            %                    newAuxName=sprintf('aux_%s_%i',curAuxName,ch);
            %                    tempTable.(newAuxName)(:,1)=nan;
            %                    tempTable.(newAuxName)(tempTable{:,'missingFNIRS'}==0,1)=permute(curAux.data(tDataIdx,ch,:),[3,1,2]);
            %                end
            %            end
            %
            %         end
                 end
            
                mergedTables=mergeTables(app, mergedTables,tempTable);
            end
        end
        
        function mergedTables = mergeTables(app, table1, table2)
            
            if(isempty(table1))
                mergedTables=table2;
                return;
            elseif(isempty(table2))
                mergedTables=table1;
                return;
            end
            
            t1Vars=table1.Properties.VariableNames;
            t2Vars=table2.Properties.VariableNames;
            
            for i=1:length(t1Vars)
                curVar=t1Vars{i};
                if(~ismember(curVar,t2Vars))
                    if(ischar(curVar))
                        table2.(curVar)=strings(size(table2,1),1);
                    elseif(isnumeric(curVar))
                        table2.(curVar)=nan(size(table2,1),1);
                    end
                end
            end
            
            for i=1:length(t2Vars)
                curVar=t2Vars{i};
                if(~ismember(curVar,t1Vars))
                    if(ischar(curVar))
                        table1.(curVar)=strings(size(table1,1),1);
                    elseif(isnumeric(curVar))
                        table1.(curVar)=nan(size(table1,1),1);
                    end
                end
            end
            
            mergedTables=[table1;table2];
        end
        
        function txt = myDataTipUpdateFcn(app, pointDataTip, event_obj)
            
             hAxes=get(pointDataTip,'Parent');
             pos = event_obj.Position;
             selectedObjectTag=event_obj.Target.Tag;
            
             if(~isempty(selectedObjectTag))
                 txt={sprintf('%s\nt=%.2f, y=%.2f',selectedObjectTag,pos(1),pos(2))};
             else
                 txt = {sprintf('t=%.2f, y=%.2f',pos(1),pos(2))};
             end
        end
        
        function myTable = nan_to_9999(app, myTable)
            
            for c=1:size(myTable,2)
                if(isnumeric(myTable{:,c}))
                    myTable{isnan(myTable{:,c}),c}=-9999;
                elseif(iscell(myTable{:,c})&&(ischar(myTable{:,c}{1})||isstring(myTable{:,c}{1})))
                    myTable{strcmp('',myTable{:,c}),c}={'-9999'};
                end
            end
        end
        
        function possibleStr = num2strOrNot(app, possibleStr)
            if(iscell(possibleStr))
                for i=1:length(possibleStr)
                   if(~ischar(possibleStr{i})&&isnumeric(possibleStr{i}))
                        possibleStr{i}=num2str(possibleStr{i});
                   end
                end
            elseif(~ischar(possibleStr)&&isnumeric(possibleStr))
                possibleStr=num2str(possibleStr);
            end
        end
        
        function [qvalues, k, passed] = performFDR(app, pvalues, pThreshold)
            % Performs FDR correction per #CITATION HERE
            
            if(istable(pvalues))
                pvalues=table2array(pvalues);
            end
            
            if(nargin<2)
                pThreshold=0.05;
            end
            
            qvalues=nan(size(pvalues));
            
            kVals=nan(size(pvalues(:)));
            
            [pSorted,pIdx]=sort(pvalues(:));
            numP=length(pSorted);
            
            kPass=zeros(1,numP);
            m=numP;
            
            for i=1:numP
            
                qThreshold=pThreshold/m*i;
                k=numP-i+1;
                qvalues(pIdx(i))=pvalues(pIdx(i))*m/i;
            
                if(qvalues(pIdx(i))<=pThreshold&&pvalues(pIdx(i))<=0.05)
                    kPass(pIdx(i))=1;
                end
                kVals(pIdx(i))=i;
            end
            
            k_ind=find(kPass==1);
            
            if(isempty(k_ind))
                k=1;
            else
               k=1;
            end
            
            qvalues=pvalues*m/k;
            qvalues(qvalues>1)=1;
            passed=qvalues<=pThreshold;
            
            if(any(kPass(:)))
               k=max(kVals(kPass(:)==1));
               qvalues=pvalues*m/k;
               passed=qvalues<=pThreshold&pvalues<0.05;
            end
        end
        
        function [qvalues, k, passed] = performFDR_twostep(app, pvalues, pThreshold)
            % Performs FDR correction per #CITATION HERE
            
            if(istable(pvalues))
                pvalues=table2array(pvalues);
            end
            
            
            if(nargin<2)
                pThreshold=0.05;
            end
            
            
            m=length(pvalues(:));
            q_prime=pThreshold;%;/(1+pThreshold);
            [qvalues,k,passed]=performFDR(app, pvalues,q_prime);
            
            if(sum(passed(:))>0&&sum(~passed(:))>0)  %if some things passed (but not all)
                m=sum(~isnan(pvalues));
                numPassed=sum(passed);
                mo=m-numPassed;
                q_star=q_prime*m/mo; %later divided by m in regular fdr)
            
                [qvalues,k,passed]=performFDR(app, pvalues,q_star);
            
            
            end
            
            if(m/k<m)
            
                %qvalues=pvalues*m/k;
                qvalues(qvalues>1)=1;
                passed=passed&pvalues<=0.05;
            
            else
               qvalues=pvalues;
               qvalues(qvalues>1)=1;
               passed=qvalues<=pThreshold&pvalues<=0.05;
            end
        end
        

        function plot_barchart(app, showBarChart, showTopo)
            
            global ExFNIRS
            
            if(ExFNIRS.UpdateNeeded)
                updateSelectedTable(app);
            end
            
            multiPlot=false;
            
            if(~isfield(ExFNIRS,'gby'))
                warning('No groups match selection criteria');
                return;
            end
            
            curInfoGroup=ExFNIRS.settings.curInfoGroup;
            
            gbyVars=ExFNIRS.groupByVars;
            if(~isempty(curInfoGroup)&&~strcmp(curInfoGroup,'(Time)'))
                [ismem,idx]=ismember(curInfoGroup,gbyVars);
                if(ismem)
                    gbyVars(idx)=[];
                    useCurInfoGroup=true;
                else
                    useCurInfoGroup=false;
                end
            else
                useCurInfoGroup=false;
            end
            
            numGroups=length(ExFNIRS.gby);
            
            biomStrs=get(app.listbox_biomarker,'Items');
            selBioM=get(app.listbox_biomarker,'Value');
            selectedBioM=biomStrs(selBioM);
            numBioM=length(selBioM);
            
            optStrs=get(app.listbox_optode,'Items');
            selOpt=get(app.listbox_optode,'Value');
            selectedOptStr=optStrs(selOpt',:);
            numOpt=length(selOpt);
            
            if(numOpt==0||numGroups==0||numBioM==0)
                return;
            end
            
            
            if(ExFNIRS.settings.ylim_fixed)
                ExFNIRS.settings.ylim_fixed_min=inf;
                ExFNIRS.settings.ylim_fixed_max=-inf;
            end
            
            if(showBarChart)
                multiPlot=false;
                ExFNIRS.figHandles.main=figure(1000);
                clf(ExFNIRS.figHandles.main);
                cla(ExFNIRS.figHandles.main);
                addDebugAnnotation(app, ExFNIRS.figHandles.main);
                dcm_obj = datacursormode(ExFNIRS.figHandles.main);
                set(dcm_obj,'UpdateFcn',@app.myDataTipUpdateFcn);
                % end
            end
            
            gbyStrs=cell(numGroups,1);
            curInfoGby=cell(0);
            
            for g=1:numGroups
                gbyStrs{g}='';
               if(~isempty(ExFNIRS.gby(g).gbyTables))
                   for i=1:length(gbyVars)
                       gbyStrs{g}=sprintf('%s%s:%s,',gbyStrs{g},gbyVars{i},num2strOrNot(app, ExFNIRS.gby(g).gbyTables.(gbyVars{i})(1)));
                   end
                   if(useCurInfoGroup)
                       curInfoGby{g}=num2strOrNot(app, ExFNIRS.gby(g).gbyTables.(curInfoGroup)(1));
                   end
               end
               if(~isempty(gbyStrs{g}))
                    gbyStrs{g}(end)='';
               end
            end
            
            [numUgroups]=length(unique(cellstr(gbyStrs)));
            
            if(numUgroups==1&&~showTopo)
                num2Plot=numBioM;
                plotGroupByBioM=true;
                cCell=table2cell(pf2_base.getBioColors());
                for i=1:length(cCell)
                    cIndex(i,:)=cCell{i};
                end
                cIndex=cIndex(selBioM,:);
            else
                num2Plot=numGroups;
                plotGroupByBioM=false;
                if(ExFNIRS.settings.use_gui_color)
                    cIndex=ExFNIRS.settings.guiColor(1:numUgroups,:);
                else
                    cIndex=ExFNIRS.settings.cmap(numUgroups);%linspecer(num2Plot,'qualitative');
                end
            end


            barChartTimes=[];
            barChartEndTimes=[];
            for i=1:numGroups
                if(isempty(ExFNIRS.gby(i).gbyGrandBar))
                    curGrandTime=[];
                    curGrandEndTime=[];
                else
                    curGrandTime=ExFNIRS.gby(i).gbyGrandBar.segmentTimes(:,1);
                    curGrandEndTime=ExFNIRS.gby(i).gbyGrandBar.segmentTimes(:,3);
                end
                barChartTimes=[barChartTimes;curGrandTime];
                barChartEndTimes=[barChartEndTimes;curGrandEndTime];
            end
            barChartTimes=sort(unique(round(barChartTimes)));
            barChartEndTimes=sort(unique(round(barChartEndTimes)));
            
            [uCurInfoG,firstCurIdx,uCurIdx]=unique(cellstr(curInfoGby));
            numCurInfoG=max(uCurIdx);
            uCurGIdxCount=nan(size(uCurIdx));
            for i =1:numCurInfoG
                uCurGIdxCount(uCurIdx==i)=1:sum(uCurIdx==i);
            end
            
            if(~useCurInfoGroup||isnan(numCurInfoG))
               useCurInfoGroup=false;
               numCurInfoG=1;
               uCurInfoG='';
            end
            
            validBarChartTimesIdx=barChartEndTimes<=ExFNIRS.settings.plot_end&barChartTimes>=ExFNIRS.settings.plot_start;
            barChartTimes=barChartTimes(validBarChartTimesIdx);
            barChartEndTimes=barChartEndTimes(validBarChartTimesIdx);
            numChartTimes=length(barChartTimes);
            
            for i=1:length(barChartTimes)
               barChartTimeStrings{i}=sprintf('%i-%i',barChartTimes(i),barChartEndTimes(i));
            end
            
            errorFeature=ExFNIRS.settings.plot_bar_err_feature;
            plotFeature=ExFNIRS.settings.plot_bar_feature;
            
            if(strcmp(plotFeature,'Count')&&ExFNIRS.settings.plot_bar_ga)
              plotFeature='N';
              plotCount=true;
            else
                plotCount=false;
            end
            
            if(plotGroupByBioM)
                subplotHandles=cell(numOpt,1);
            else
                subplotHandles=cell(numOpt*numBioM,1);
            end
            
            subplotGby=subplotHandles;
            numCharts=length(subplotHandles);
            
            
            for chIdx=1:numOpt
                ch=selOpt(chIdx);
                %legendGFXhandles{1}=[];
                %legendGFXstrs{1}=cell(0);
                if(plotGroupByBioM)
                    barChartData{1}=nan(numCurInfoG,numBioM,3);
                    num2Plot=numBioM;
                elseif(numUgroups>1)
                    for i=1:numBioM
                        barChartData{i}=nan(numChartTimes,numUgroups,3);
                    end
                    num2Plot=numUgroups;
                else
                    barChartData{1}=nan(numCurInfoG,1,3);
            
                end
            
                chartGby=cell(size(subplotHandles));
            
                %barChartTimes=times;
            
                gAStrs=cell(num2Plot,1);
                gAerrStrs=cell(num2Plot,1);
            
                for b=1:numBioM
                    bioM=selectedBioM(b);
                    if(iscell(bioM))
                        bioM=bioM{1};
                    end
            
            
                    if(numUgroups>1)
                        curChart=b;
                    else
                        curChart=1;
                    end
            
                    for g=1:numGroups
            
                        curFNIRS=ExFNIRS.gby(g).gbyFNIRS_blk;
                        curGrand=ExFNIRS.gby(g).gbyGrandBar;
            
            
                        if(isfield(chartGby{curChart},'gby'))
                            chartGby{curChart}.gby(end+1)=ExFNIRS.gby(g);
                        else
                            chartGby{curChart}.gby(1)=ExFNIRS.gby(g);
                            chartGby{curChart}.curCh=ch;
                            if(plotGroupByBioM)
                                chartGby{curChart}.curBioM=selectedBioM;
                            else
                                chartGby{curChart}.curBioM=selectedBioM(b);
                            end
                        end
            
            
                        if(useCurInfoGroup)
                            curGroupInfoIdx=uCurIdx(g);
                            curGroupIdxOffset=(curGroupInfoIdx-1)*numChartTimes;
                            curUgroupIdx=uCurGIdxCount(g);
                        else
                            curGroupInfoIdx=g;
                            curGroupIdxOffset=0;
                            curUgroupIdx=g;
                        end
            
                        if(isempty(curGrand))
                            if(plotGroupByBioM)
                                barChartData{curChart}(:,b,1:3)=nan;
            
                            else
                                barChartData{curChart}(:,curUgroupIdx,1:3)=nan;
                            end
            
                           if(numUgroups>1||numBioM==1)
                                gAStrs{curUgroupIdx,curChart}=sprintf('%s',gbyStrs{g});
                           elseif(numBioM>1&&~multiPlot)
                                gAStrs{b,curChart}=sprintf('%s',selectedBioM{b});
                           end
            
                           continue;
                        end
            
                        if(ExFNIRS.settings.plot_bar_ga)
                              [timeIdx,timeIdxRev]=ismember(round(curGrand.time),barChartTimes);
                              timeIdxRev=timeIdxRev(timeIdxRev>0);
            
                              switch(ExFNIRS.settings.ChannelMode)
                                  case 'fNIR'
                                      data2plot=curGrand.(bioM);
                                  case 'ROI'
                                      if(~pf2_base.isnestedfield(curGrand,'ROI.HbO.data'))
                                          error('ROI data must be calculated using a build ROI step');
                                      end
            
                                      data2plot=curGrand.ROI.(bioM);
                                  case 'Aux'
                                      data2plot=curGrand.Aux.(bioM);
                              end
            
                              if(plotGroupByBioM)
                                  barChartData{curChart}(timeIdxRev+curGroupIdxOffset,b,1)=data2plot.(plotFeature)(timeIdx,ch);
                              else
                                  barChartData{curChart}(timeIdxRev+curGroupIdxOffset,curUgroupIdx,1)=data2plot.(plotFeature)(timeIdx,ch);
                              end
                              if(numUgroups>1||numBioM==1)
                                   gAStrs{curUgroupIdx,curChart}=sprintf('%s',gbyStrs{g});
                              elseif(numBioM>1)
                                   gAStrs{b,curChart}=sprintf('%s',selectedBioM{b});
                              end
                        end
            
                        if(ExFNIRS.settings.plot_bar_err&&~plotCount)
            
                              errMulitply=ExFNIRS.settings.plot_bar_err_mult;
                              [timeIdx,timeIdxRev]=ismember(round(curGrand.time),barChartTimes);
                              timeIdxRev=timeIdxRev(timeIdxRev>0);
                              ga2plot=data2plot;%curGrand.(bioM);
                              if(strcmp(errorFeature,'MaxMin'))
                                  if(plotGroupByBioM)
                                      barChartData{curChart}(timeIdxRev+curGroupIdxOffset,b,2)=ga2plot.Max(timeIdx,ch);
                                      barChartData{curChart}(timeIdxRev+curGroupIdxOffset,b,3)=ga2plot.Min(timeIdx,ch);
                                  else
                                      barChartData{curChart}(timeIdxRev+curGroupIdxOffset,curUgroupIdx,2)=ga2plot.Max(timeIdx,ch);
                                      barChartData{curChart}(timeIdxRev+curGroupIdxOffset,curUgroupIdx,3)=ga2plot.Min(timeIdx,ch);
                                  end
                              else
                                  if(plotGroupByBioM)
                                      barChartData{curChart}(timeIdxRev+curGroupIdxOffset,b,2)=ga2plot.(errorFeature)(timeIdx,ch)*errMulitply;
                                  else
                                      barChartData{curChart}(timeIdxRev+curGroupIdxOffset,curUgroupIdx,2)=ga2plot.(errorFeature)(timeIdx,ch)*errMulitply;
                                  end
                              end
                              if(numUgroups>1||numBioM==1)
                                   gAErrStrs{curGroupInfoIdx,curChart}=sprintf('%s',gbyStrs{curGroupInfoIdx});
                              elseif(numBioM>1&&~multiPlot)
                                   gAErrStrs{b,curChart}=sprintf('%s',selectedBioM{b});
                              end
                        else
                                barChartData{curChart}(timeIdxRev+curGroupIdxOffset,b,2)=0;
                                barChartData{curChart}(timeIdxRev+curGroupIdxOffset,b,3)=0;
                              if(numUgroups>1||numBioM==1)
                                   gAErrStrs{curGroupInfoIdx,curChart}='';
                              elseif(numBioM>1&&~multiPlot)
                                   gAErrStrs{b,curChart}='';
                              end
                        end
                      end
            
                end


                for curChart=1:length(barChartData)
                    if(isempty(barChartData{curChart}))
                        warning('All data is missing');
                        continue;
                    elseif(sum(~isnan(barChartData{curChart}(:,:,1)))==0)
                        warning('All data is missing');
                        continue;
                    end
                    if(~plotGroupByBioM)
                            lastPlotNum=numBioM*numOpt;
                            if(numOpt>1)
                                curSubplotIdx=chIdx+(numOpt*(curChart-1));
            
                                numX=numBioM;
                                numY=numOpt;
                                subplotGby{curSubplotIdx}=chartGby{curChart};
                            else
                                curSubplotIdx=chIdx+(numOpt*(curChart-1));
                                numX=1;
                                numY=numBioM;
                                subplotGby{curSubplotIdx}=chartGby{curChart};
                            end
                    else
                            lastPlotNum=numOpt;
                            curSubplotIdx=chIdx;
                            numX=1;
                            numY=numOpt;
                            subplotGby{curSubplotIdx}=chartGby{curChart};
                    end
                    if(~showBarChart)
                        continue;
                    end
                    subplotHandles{curSubplotIdx}=subplot(numX,numY,curSubplotIdx);
            
                    if(useCurInfoGroup&&numChartTimes>1)
                        xBarLabels=cell(numChartTimes*numCurInfoG,1);
                        timeStrs=num2str(round(barChartTimes));
                        for g=1:numCurInfoG
                            for t=1:numChartTimes
                                xBarLabels{(g-1)*numChartTimes+t}=sprintf('%s-%ss',curInfoGby{firstCurIdx(g)},timeStrs(t,:));
                            end
                        end
                    elseif(useCurInfoGroup&&numChartTimes==1)
                        xBarLabels=cell(numChartTimes*numCurInfoG,1);
                        for g=1:numCurInfoG
                                xBarLabels{g}=curInfoGby{firstCurIdx(g)};
                        end
                    else
                        xBarLabels=barChartTimeStrings;
                    end
            
                    if(ExFNIRS.settings.plot_bar_err)
                        pf2_base.external.barweb(barChartData{curChart}(:,:,1),barChartData{curChart}(:,:,2),1,xBarLabels, [], [], [], cIndex,[],gAStrs,[],'hide');
                        ylimLower=min(min(barChartData{curChart}(:,:,1)))-max(max(barChartData{curChart}(:,:,2)));
                        ylimUpper=max(max(barChartData{curChart}(:,:,1)))+max(max(barChartData{curChart}(:,:,2)));
                        yrange=ylimUpper-ylimLower;
                        ylim([min(ylimLower-0.1*yrange,0),max(ylimUpper+0.1*yrange,0)]);
            
                    else
                        pf2_base.external.barweb(barChartData{curChart}(:,:,1),[],1,xBarLabels, [], [], [], cIndex,[],gAStrs,[],'hide');
                        ylimLower=min(min(barChartData{curChart}(:,:,1)));
                        ylimUpper=max(max(barChartData{curChart}(:,:,1)));
                        yrange=ylimUpper-ylimLower;
            
                    end
            
                    if(ExFNIRS.settings.ylim_fixed)
                        ylim([min(ylimLower-0.05*yrange,0),max(ylimUpper+0.05*yrange,0)]);
                        cylim=ylim;
                        ExFNIRS.settings.ylim_fixed_min=min(cylim(1),ExFNIRS.settings.ylim_fixed_min);
                        ExFNIRS.settings.ylim_fixed_max=max(cylim(2),ExFNIRS.settings.ylim_fixed_max);
                    elseif(ExFNIRS.settings.ylim_manual&&~plotCount)
                        ylim([ExFNIRS.settings.ylim_manual_min,ExFNIRS.settings.ylim_manual_max]);
                    else
                        ylim([min(ylimLower-0.1*yrange,0),max(ylimUpper+0.1*yrange,0)]);
                    end
            
                    if(numBioM==1||numUgroups>1)
                        if(numBioM==1)
                            bioM=selectedBioM(curChart);
                            if(iscell(bioM))
                                bioM=bioM{1};
                            end
                            if(plotCount)
                                ylabel(sprintf('%s \\Delta[%s] (\\muM)',plotFeature,bioM));
                            else
                                ylabel(sprintf('%s \\Delta[%s] (\\muM)  +/- (%s)',plotFeature,bioM,errorFeature));
                            end
                        else
                            bioM=selectedBioM(curChart);
                             if(iscell(bioM))
                                bioM=bioM{1};
                            end
                            if(plotCount)
                                ylabel(sprintf('%s \\Delta[%s] (\\muM)',plotFeature,bioM));
                            else
                                ylabel(sprintf('%s \\Delta[%s] (\\muM)  +/- (%s)',plotFeature,bioM,errorFeature));
                            end
            
                        end
                    elseif(numBioM==b)
                        if(plotCount)
                            ylabel(sprintf('%s \\Delta[%s] (\\muM)',plotFeature,'X'));
                        else
                            ylabel(sprintf('%s \\Delta[%s] (\\muM)  +/- (%s)',plotFeature,'X',errorFeature));
                        end
                    end
            
            
                    switch ExFNIRS.settings.ChannelMode
                        case 'fNIR'
                            title(sprintf('Optode %i',ch));
                        case 'ROI'
                            title(sprintf('ROI: %s',optStrs{ch}));
                        case 'Aux'
                            title(sprintf('Aux: %s', bioM));
                    end
            
                    if(useCurInfoGroup&&numChartTimes==1)
                        xlabel(sprintf('%s (t=%s)',curInfoGroup,barChartTimeStrings{1}));
                    elseif(useCurInfoGroup)
                        xlabel(sprintf('Time (s) x %s',curInfoGroup));
                    else
                        xlabel('Time (s)');
                    end
            
                    if((numBioM>1||numUgroups>1)&&(ExFNIRS.settings.plot_legend_mode==3||(ExFNIRS.settings.plot_legend_mode==2&&curSubplotIdx==lastPlotNum)))
                        for i=1:size(gAStrs,1)
                           if(isnumeric(gAStrs{i,curChart}))
                               gAStrs{i,curChart}='';
                           end
                        end
                        legend(gAStrs(:,curChart),'Location', 'Best');
                        legend boxoff;
                    end
                    hold off;
                end
            end
            
            if(plotCount)
               ExFNIRS.settings.ylim_fixed_min=0;
            end
            
            ExFNIRS.curChartModels=cell(0);
            ExFNIRS.curChartModelsAIC=[];
            ExFNIRS.curChartModelsCoefficents=cell(0);
            ExFNIRS.curChartModelsCoefficents_pval=table();
            ExFNIRS.curChartModelsCoefficents_tstat=table();
            ExFNIRS.curChartModelsCoefficents_df=table();
            ExFNIRS.curChartModelsANOVA=cell(0);
            ExFNIRS.curChartModelsANOVACoefficents_pval=table();
            ExFNIRS.curChartModelsANOVACoefficents_Fstat=table();
            ExFNIRS.curChartModelsANOVACoefficents_df1=table();
            ExFNIRS.curChartModelsANOVACoefficents_df2=table();
            ExFNIRS.curMdlFits=table();
            
            if(ExFNIRS.settings.LME_enable)
                fprintf('Generating Models...\nAccessed at ExFNIRS.curChartModels\n')
            
            end
            
            
             LME_topo_mode='anova';
            
             lmeString='None~';
            
            
            for sH=1:length(subplotHandles)
            
                if(ExFNIRS.settings.LME_enable&&isfield(subplotGby{sH},'gby'))
                    switch (ExFNIRS.settings.ChannelMode)
                        case 'fNIR'
                            mergedTables{sH}=mergeGbyTablesLong(app, subplotGby{sH}.gby,subplotGby{sH}.curBioM,subplotGby{sH}.curCh,barChartTimes,false,false);
                            varNameStart='Opt';
            
                        case 'ROI'
                            mergedTables{sH}=mergeGbyTablesLong(app, subplotGby{sH}.gby,subplotGby{sH}.curBioM,subplotGby{sH}.curCh,barChartTimes,false,true);
                            varNameStart='ROI';
            
                        case 'Aux'
                            mergedTables{sH}=mergeGbyTablesLong(app, subplotGby{sH}.gby,subplotGby{sH}.curBioM,subplotGby{sH}.curCh,barChartTimes,true,false);
                            varNameStart='aux';
                    end
                    x=ExFNIRS.groupByVars;
                    curLMEGbyString='';
                    mdlPrtString='';
            
                    useAllInteractions=ExFNIRS.settings.LME_all_interactions;
            
                    basicMdlStrings=cell(0);
            
            
                    if(ExFNIRS.settings.LME_info_covariate)
                        basicMdlStrings{length(basicMdlStrings)+1}=ExFNIRS.settings.curInfoStr;
                    end
            %         if(plotGroupByBioM&&numBioM>1)
            %             %basicMdlStrings{length(basicMdlStrings)+1}='BioM';
            %             warning('GroupBy Biomarker Plots not supported yet\n Only using first biomarker');
            %         end
            
                    for z=1:length(basicMdlStrings)
                        if(z==1)
                            mdlPrtString=basicMdlStrings{z};
                        else
                            mdlPrtString=sprintf('%s*%s',mdlPrtString,basicMdlStrings{z});
                        end
                    end
            
                    if(isempty(mdlPrtString))
                        mdlPrtString='1';
                    end
            
            
                    if(useAllInteractions)
                        curLMEGbyString=mdlPrtString;
                        for i=1:length(x)
                            curLMEGbyString=sprintf('%s*%s',curLMEGbyString,x{i});
                        end
                    else
                        for i=1:length(x)
                            curLMEGbyString=sprintf('%s+%s*%s',curLMEGbyString,mdlPrtString,x{i});
                        end
                        if(~isempty(curLMEGbyString))
                            curLMEGbyString(1)=[];
                        end
            
            
                    end
            
                    if(numChartTimes>1)
                        curLMEGbyString=sprintf('%s+Time',curLMEGbyString);
                    end
            
                    dummyCodeStr='reference';
            
                    if(strcmp(ExFNIRS.settings.ChannelMode,'Aux'))
                        varName=sprintf('%s_%s',varNameStart,subplotGby{sH}.curBioM{1});
                    else
                        varName=sprintf('%s%i_%s',varNameStart,subplotGby{sH}.curCh,subplotGby{sH}.curBioM{1});
                    end
            
                    if(ExFNIRS.settings.LME_use_customStr&&~isempty(ExFNIRS.settings.LME_customStr))
                        lmeString=sprintf('%s~%s',varName,ExFNIRS.settings.LME_customStr);
                        if(contains(lmeString,'-1+')||contains(lmeString,'~-1'))
                           dummyCodeStr='full';
                           lmeString(lmeString=='*')=':';
                        end
                    elseif(ExFNIRS.settings.LME_use_intercept)
                        lmeString=sprintf('%s~%s+(%s)',varName,curLMEGbyString,ExFNIRS.settings.LME_randomFxStr);
                        if(isempty(curLMEGbyString))
                            lmeString=sprintf('%s~1+(%s)',varName,ExFNIRS.settings.LME_randomFxStr);
                        end
            
                    else
                        lmeString=sprintf('%s~-1+%s+(%s)',varName,curLMEGbyString,ExFNIRS.settings.LME_randomFxStr);
                        dummyCodeStr='full';
                        lmeString(lmeString=='*')=':';
                    end
            
                    try
                        if((~ExFNIRS.settings.LME_use_discreteTime||strcmp(LME_topo_mode,'anova'))&&numChartTimes>1)
                            mergedTables{sH}.Time=str2double(mergedTables{sH}.Time);
                        end
            
            
                        rng(2019);
                        curChartLME{sH}=fitlme(mergedTables{sH},lmeString,'FitMethod','REML','CheckHessian',true,'DummyVarCoding',dummyCodeStr);
                      %   curChartLME_emm{sH}= pf2_base.external.emmeans(curChartLME{sH}, {'orig'}, 'effects');
            %             h = emmip(curChartLME_emm{sH},'orig');
                        nullMdlstring=sprintf('%s~1+(1|SubjectID)',varName);
                        curChartLME_ML=fitlme(mergedTables{sH},lmeString,'FitMethod','ML','CheckHessian',true,'DummyVarCoding',dummyCodeStr);
                        nullChartLME=fitlme(mergedTables{sH},nullMdlstring,'FitMethod','ML','CheckHessian',true,'DummyVarCoding',dummyCodeStr);
                        nullCompare{sH}=compare(curChartLME_ML,nullChartLME);
                        pVal=nullCompare{sH}.pValue(end);
                        if(pVal>0.05)
                            nullCompareStr{sH}='Model is marginally worse than naive model';
                        elseif(~isnan(pVal))
                            nullCompareStr{sH}='Model is significantly worse than naive model';
                        else
                            nullCompare{sH}=compare(nullChartLME,curChartLME_ML);
                            pVal=nullCompare{sH}.pValue(end);
                            if(pVal>0.05)
                                nullCompareStr{sH}='Model is marginally better than naive model';
                            else
                                nullCompareStr{sH}='Model is significantly better than naive model';
                            end
                        end
            
                        switch (ExFNIRS.settings.ChannelMode)
                            case 'fNIR'
                                     chName=sprintf('Opt%i',subplotGby{sH}.curCh);
                                     mdlChName=chName;
                            case 'ROI'
                                     chName=sprintf('ROI%i_%s',subplotGby{sH}.curCh,optStrs{subplotGby{sH}.curCh});
                                     mdlChName=sprintf('ROI%i',subplotGby{sH}.curCh);
                            case 'Aux'
                                    chName=sprintf('%s',subplotGby{sH}.curBioM{1});
                                     mdlChName=chName;
                        end
            
            
                        fprintf('Chart %i LME model: %s',sH,chName);
                        if(~plotGroupByBioM)
                            fprintf(' [%s]',subplotGby{sH}.curBioM{1});
                        end
                        if(useAllInteractions)
                            fprintf(' - All Interactions\n');
                        else
                            fprintf(' - No Interactions\n');
                        end
                        ExFNIRS.curChartModels{sH}=curChartLME{sH};
                        ExFNIRS.curChartModelsAIC(sH)=curChartLME{sH}.ModelCriterion.AIC;
                        [~,~,ExFNIRS.curChartModelsCoefficents{sH}]=randomEffects(curChartLME{sH},'DFMethod','satterthwaite');
                        ExFNIRS.curChartModelsANOVA{sH}=anova(curChartLME{sH},'DFMethod','satterthwaite');
            
                        anovaNames=curChartLME{sH}.anova.Term;
            
                        for a=1:length(anovaNames)
                           str=anovaNames{a};
                           str(str=='('|str==')')=''; % replace shitty characters
                           str(str==':'|str=='_')=''; % replace shitty characters
                           str(str==' '|str=='-')=''; % replace shitty characters
                           anovaNames{a}=str;
                        end
            
                        varNames=ExFNIRS.curChartModelsCoefficents{sH}.Name;
                        for v=1:length(varNames)
                           str=varNames{v};
                           str(str=='('|str==')')=''; % replace shitty characters
                           str(str==':'|str=='_')=''; % replace shitty characters
                           str(str==' '|str=='-')=''; % replace shitty characters
                           varNames{v}=str;
                        end
            
                        if(true)%~plotGroupByBioM)
                            curBioM=subplotGby{sH}.curBioM{1};
                            curRowName=sprintf('%s_%s',chName,curBioM);


                            ExFNIRS.curChartModelsANOVACoefficents_pval{curRowName,anovaNames}= ExFNIRS.curChartModelsANOVA{sH}.pValue';
                            ExFNIRS.curChartModelsANOVACoefficents_Fstat{curRowName,anovaNames}=ExFNIRS.curChartModelsANOVA{sH}.FStat';
                            if(ismember('DF2',properties(ExFNIRS.curChartModelsANOVA{sH})))
                                ExFNIRS.curChartModelsANOVACoefficents_df2{curRowName,anovaNames}=ExFNIRS.curChartModelsANOVA{sH}.DF2';
                                ExFNIRS.curChartModelsANOVACoefficents_df1{curRowName,anovaNames}=ExFNIRS.curChartModelsANOVA{sH}.DF1';
                            else
                                ExFNIRS.curChartModelsANOVACoefficents_df1{curRowName,anovaNames}=ExFNIRS.curChartModelsANOVA{sH}.DF';
                                ExFNIRS.curChartModelsANOVACoefficents_df2{curRowName,anovaNames}=zeros(size(ExFNIRS.curChartModelsANOVA{sH}.DF'));
                            end


                            ExFNIRS.curChartModelsCoefficents_pval{curRowName,varNames}=ExFNIRS.curChartModelsCoefficents{sH}.pValue';
                            ExFNIRS.curChartModelsCoefficents_tstat{curRowName,varNames}=ExFNIRS.curChartModelsCoefficents{sH}.tStat';
                            ExFNIRS.curChartModelsCoefficents_df{curRowName,varNames}=ExFNIRS.curChartModelsCoefficents{sH}.DF';
                            ExFNIRS.curChartModels_ch(sH)=subplotGby{sH}.curCh;
                        else
                            curBioM=subplotGby{sH}.curBioM{1};
                            curRowName=sprintf('%s_%s',chName,curBioM);
                            ExFNIRS.curChartModelsCoefficents_pval{curRowName,varNames}=ExFNIRS.curChartModelsCoefficents{sH}.pValue';
                            ExFNIRS.curChartModelsCoefficents_tstat{curRowName,varNames}=ExFNIRS.curChartModelsCoefficents{sH}.tStat';
                            ExFNIRS.curChartModelsCoefficents_df{curRowName,varNames}=ExFNIRS.curChartModelsCoefficents{sH}.DF';
            
            
                            ExFNIRS.curChartModelsANOVACoefficents_pval{curRowName,anovaNames}=ExFNIRS.curChartModelsANOVA{sH}.pValue';
                            ExFNIRS.curChartModelsANOVACoefficents_Fstat{curRowName,anovaNames}=ExFNIRS.curChartModelsANOVA{sH}.FStat';
                            if(ismember('DF2',properties(ExFNIRS.curChartModelsANOVA{sH})))
                                ExFNIRS.curChartModelsANOVACoefficents_df2{curRowName,anovaNames}=ExFNIRS.curChartModelsANOVA{sH}.DF2';
                                ExFNIRS.curChartModelsANOVACoefficents_df1{curRowName,anovaNames}=ExFNIRS.curChartModelsANOVA{sH}.DF1';
                            else
                                ExFNIRS.curChartModelsANOVACoefficents_df1{curRowName,anovaNames}=ExFNIRS.curChartModelsANOVA{sH}.DF';
                                ExFNIRS.curChartModelsANOVACoefficents_df2{curRowName,anovaNames}=zeros(size(ExFNIRS.curChartModelsANOVA{sH}.DF'));
                            end
            
                            ExFNIRS.curChartModels_ch(sH)=subplotGby{sH}.curCh;
                        end
                        disp(curChartLME{sH});
                        displayLME(app, curChartLME{sH});
                        fprintf(2,'\n%s\n',nullCompareStr{sH});
                        disp(nullCompare{sH});
            
                        mdlTest=eye(length(curChartLME{sH}.Coefficients.Name));
                        if(ExFNIRS.settings.LME_use_intercept)
                            mdlTest=mdlTest(2:end,:);
                        end
                        [curMdlFit{1:4}]=coefTest(curChartLME{sH},mdlTest,zeros(size(mdlTest,1),1),'DFMethod','satterthwaite');
                        fprintf('\nModel Fit (H0: All F=0): p=%.5f\tF=%.2f\tdf1=%i\tdf2=%i\n\n',curMdlFit{1},curMdlFit{2},curMdlFit{3},curMdlFit{4});
                        if(~showTopo)
                            curChartContrast=autoContrast(app, curChartLME{sH});
                            if(~isempty(curChartContrast))
                                disp(curChartContrast);
                            end
                        end
                        ExFNIRS.curMdlFits{curBioM,mdlChName}=curMdlFit{1};
                        %curChartLME{sH}.contrastTable=autoContrast(curChartLME{sH},0.5);
                    catch ME
                        fprintf(2,'Could not generate model for figure %i\n',sH);
                        fprintf(2,'\nLME: %s\n',lmeString);
                        fprintf(2,ME.message);
                        fprintf(2,'\n');
                    end
                end
            
            
                if(showBarChart&&ExFNIRS.settings.ylim_fixed)
                    set(subplotHandles{sH},'YLim',[ExFNIRS.settings.ylim_fixed_min, ExFNIRS.settings.ylim_fixed_max]);
                end
            end
            
            doublePlotWithFDR=false;
            FDRfound=false;
            
            if(showTopo)
                if(~ExFNIRS.settings.LME_enable)
                    warning('LME must be enabled');
                else
            
                    topoH=figure(2000);
                    clf(topoH);
                    lmeString=strsplit(lmeString,'~');
                    lmeString=sprintf('[X]~%s',lmeString{2});
                    addDebugAnnotation(app, topoH,lmeString);
            
                    chNames=ExFNIRS.curChartModelsCoefficents_tstat.Properties.RowNames;
                    coefNames=ExFNIRS.curChartModelsCoefficents_tstat.Properties.VariableNames;
                    numCoeff=size(ExFNIRS.curChartModelsCoefficents_tstat,2);
            
                    numANOVA=size(ExFNIRS.curChartModelsANOVACoefficents_Fstat,2);
                    anovaNames=ExFNIRS.curChartModelsANOVACoefficents_Fstat.Properties.VariableNames;
            
                     for z=1:length(chNames)
                        temp=strsplit(chNames{z},'_');
                         switch (ExFNIRS.settings.ChannelMode)
                            case 'fNIR'
                                     chArr(z)=sscanf(temp{1},'Opt%i');
                            case 'ROI'
                                     chArr(z)=sscanf(temp{1},'ROI%i');
                            case 'Aux'
                        end
            
                        bioMarr(z)=temp(end);
                     end
                     bioMLabel=cell(0,0);


                    if(true&&~isempty(chNames))%~plotGroupByBioM)
                        for b=1:numBioM
                            bioM=selectedBioM(b);
                           curMdlP=ExFNIRS.curMdlFits(bioM,:);
                          fprintf('\n <strong>Significant Models [%s]: </strong>',bioM{1});
                          [curMdlQ,curMdlK]=performFDR(app, curMdlP,ExFNIRS.settings.topoSigThrehold{2});
                          [curMdlQ_rev,curMdlK_rev]=performFDR_twostep(app, curMdlP,ExFNIRS.settings.topoSigThrehold{2});
            
                          for i=1:size(curMdlP,2)
                              varName=curMdlP.Properties.VariableNames{i};
                              if((curMdlP{1,i}<ExFNIRS.settings.topoSigThrehold{2}&&strcmp(ExFNIRS.settings.topoSigThrehold{1},'p'))||...
                                   (curMdlP{1,i}<0.05&&~strcmp(ExFNIRS.settings.topoSigThrehold{1},'p')))
                                  fprintf('\n%s_%s p=%.4f',varName,bioM{1},curMdlP{1,i});
                                  if(curMdlP{1,i}<ExFNIRS.settings.topoSigThrehold{2}&&strcmp(ExFNIRS.settings.topoSigThrehold{1},'p'))
                                      fprintf('<strong>* </strong>');
                                  end
            
                                  if(strcmp(ExFNIRS.settings.topoSigThrehold{1},'q'))
                                      fprintf(', q=%.4f',curMdlQ(i));
                                  end
                                  if(curMdlQ(i)<ExFNIRS.settings.topoSigThrehold{2}&&strcmp(ExFNIRS.settings.topoSigThrehold{1},'q'))
                                      fprintf('<strong>* </strong>');
                                  end
                                  if(strcmp(ExFNIRS.settings.topoSigThrehold{1},'q-twostep'))
                                      fprintf(', q adaptive=%.4f',curMdlQ_rev(i));
                                  end
                                  if(curMdlQ_rev(i)<ExFNIRS.settings.topoSigThrehold{2}&&strcmp(ExFNIRS.settings.topoSigThrehold{1},'q-twostep'))
                                      fprintf('<strong>* </strong>');
                                  end
                              end
            
                          end
            
                          fprintf('\n');
            
                          switch(LME_topo_mode)
                                case 'coef'
                                    for c=1:numCoeff
                                        fNIR_t{b,c}=nan(1,max(chArr));
                                        fNIR_p{b,c}=nan(1,max(chArr));
                                        fNIR_df{b,c}=nan(1,max(chArr));
                                    end
                              case 'anova'
                                    for a=1:numANOVA
                                        fNIR_f{b,a}=nan(1,max(chArr));
                                        fNIR_p{b,a}=nan(1,max(chArr));
                                        fNIR_df{b,a}=nan(1,max(chArr));
                                        fNIR_df2{b,a}=nan(1,max(chArr));
                                    end
            
                          end
                        end


                        for coefIdx=1:size(ExFNIRS.curChartModelsCoefficents_tstat,1)
            
                           curCh= chArr(coefIdx);
            
                           curChName=chNames(coefIdx);
            
                           b_idx=strcmp(bioMarr{coefIdx},selectedBioM);
                           bioMLabel(b)=selectedBioM(b_idx);
                           switch(LME_topo_mode)
                                case 'coef'
            
                                   for c=1:numCoeff
            
                                       fNIR_t{b_idx,c}(curCh)=ExFNIRS.curChartModelsCoefficents_tstat{curChName,coefNames(c)};
                                       fNIR_p{b_idx,c}(curCh)=ExFNIRS.curChartModelsCoefficents_pval{curChName,coefNames(c)};
                                       fNIR_df{b_idx,c}(curCh)=ExFNIRS.curChartModelsCoefficents_df{curChName,coefNames(c)};
                                   end
                               case 'anova'
                                   for a=1:numANOVA
            
                                       fNIR_f{b_idx,a}(curCh)=ExFNIRS.curChartModelsANOVACoefficents_Fstat{curChName,anovaNames(a)};
                                       fNIR_p{b_idx,a}(curCh)=ExFNIRS.curChartModelsANOVACoefficents_pval{curChName,anovaNames(a)};
                                       fNIR_df{b_idx,a}(curCh)=ExFNIRS.curChartModelsANOVACoefficents_df1{curChName,anovaNames(a)};
                                       fNIR_df2{b_idx,a}(curCh)=ExFNIRS.curChartModelsANOVACoefficents_df2{curChName,anovaNames(a)};
                                   end
                           end
                        end
            
                        for b=1:numBioM
                            if(b==1)
                               sigStr=sprintf('Thresholded at %s=%.2f',ExFNIRS.settings.topoSigThrehold{1},ExFNIRS.settings.topoSigThrehold{2});
                               th=annotation(topoH,'textbox',[0,1,0,0],'Items',sigStr,'FitBoxToText','on');
                            end
                           switch(LME_topo_mode)
                                case 'coef'
                                    for c=1:numCoeff
                                        subplot(numBioM,numCoeff,c+(b-1)*numCoeff)
                                        curT=fNIR_t{b,c};
                                        curP=fNIR_p{b,c};
                                        curDf=fNIR_df{b,c};


                                        curQ=performFDR(app, curP);
            
                                        if(any(curQ<0.05))
                                            FDRfound=true;
                                            %FDR RESULTS FOUND
                                        end
            
                                        global setF
            
                                        switch(setF.device.Info.CfgName)
                                            case 'fNIR_Devices_fNIR1000'
                                                curT=nan(2,8);
                                                curP=nan(2,8);
                                                curDf=nan(2,8);
            
                                                curT(:)=fNIR_t{b,c};
                                                curP(:)=fNIR_p{b,c};
                                                curDf(:)=fNIR_df{b,c};
            
            
                                                switch(ExFNIRS.settings.ChannelMode)
                                                    case 'fNIR'
                                                        interpolateNIR(curT,'Mode','tstat','fontSize',12,'transparent',true,'pValueMask',curP,'TitleText',coefNames{c},'ChannelLabels',true)%,7,11,2,1,false,'[Hb-Oxy] Natural High Vs. Low',12,'hot',true)
                                                    case 'ROI'
                                                        roiInfo=ExFNIRS.currentROI;
                                                        interpolateNIR(mapROIvaluesToCh(roiInfo,curT),'Mode','tstat','fontSize',12,'transparent',true,'pValueMask',mapROIvaluesToCh(roiInfo,curP),'TitleText',coefNames{c},'ChannelLabels',true)%,7,11,2,1,false,'[Hb-Oxy] Natural High Vs. Low',12,'hot',true)
                                                end
                                            otherwise
                                                passingVals=curT(curP<0.05);
                                                if(~isempty(passingVals))
                                                    minVal(1)=min(abs(passingVals));
                                                    minVal(2)=-1*min(abs(passingVals));
                                                    switch(ExFNIRS.settings.ChannelMode)
                                                        case 'fNIR'
                                                            processFNIRS2.Data.Plot.ImageValues([],curT,minVal,[],coefNames{c},'t-Stat');%,7,11,2,1,false,'[Hb-Oxy] Natural High Vs. Low',12,'hot',true)
                                                        case 'ROI'
                                                            roiInfo=ExFNIRS.currentROI;
                                                            processFNIRS2.Data.Plot.ImageValues([],mapROIvaluesToCh(roiInfo,curT),minVal,[],coefNames{c},'t-Stat');%,7,11,2,1,false,'[Hb-Oxy] Natural High Vs. Low',12,'hot',true)
                                                    end
                                                else
                                                    plot(0,0);
                                                    curAxes=gca;
                                                    axesPos=curAxes.OuterPosition;
                                                    axis off
                                                    title(sprintf('%s_N_S',coefNames{c}));
                                                    if(a==1) % first column
                                                        th=annotation(gcf,'textbox',[0,axesPos(2),axesPos(3),axesPos(4)/2],'Items',selectedBioM(b),'FitBoxToText','on');
                                                    end
                                                end
                                        end
                                        if(c==1) % first column
                                            ylabel(selectedBioM(b));
                                        end
                                    end
                               case 'anova'
                                   for a=1:numANOVA
                                        subplot(numBioM,numANOVA,a+(b-1)*numANOVA)
                                        curF=fNIR_f{b,a};
                                        curP=fNIR_p{b,a};
                                        curDf1=fNIR_df{b,a};
                                        curDf2=fNIR_df2{b,a};
                                        m=length(curP(:));
                                        [curQ,curK]=performFDR(app, curP,ExFNIRS.settings.topoSigThrehold{2});
                                        [curQ_rev,curK_rev]=performFDR_twostep(app, curP,ExFNIRS.settings.topoSigThrehold{2});
            
                                        estimateFPval=finv(ones(size(curF(:)))*(1-ExFNIRS.settings.topoSigThrehold{2}), curDf1(:), curDf2(:));
                                        estimateFPval_q=finv(ones(size(curF(:)))*(1-ExFNIRS.settings.topoSigThrehold{2}*curK/m), curDf1(:), curDf2(:));
                                        estimateFPval_qrev=finv(ones(size(curF(:)))*(1-ExFNIRS.settings.topoSigThrehold{2}*curK_rev/m), curDf1(:), curDf2(:));
            
                                        switch(ExFNIRS.settings.topoSigThrehold{1})
                                            case 'p'
            
                                            case 'q'
                                                estimateFPval=estimateFPval_q;
                                            case 'q-twostep'
                                                estimateFPval=estimateFPval_qrev;
                                        end
            
                                        estimatedPval_min=nanmin(estimateFPval);
            
                                        if(any(curF(:)>=estimatedPval_min))
            
                                            titleSTR=anovaNames{a};
            
                                            if(any(curQ<0.05))
                                                FDRfound=true;
                                                titleSTR=sprintf('%s*',anovaNames{a});
                                                %FDR RESULTS FOUND
                                            end
            
                                            global setF
            
                                            switch(setF.device.Info.CfgName)
                                                case 'fNIR_Devices_fNIR1000'
                                                    curF=nan(2,8);
                                                    curP=nan(2,8);
                                                    curDf1=nan(2,8);
                                                    curDf2=nan(2,8);
            
                                                    len=length(fNIR_f{b,a});
                                                    curF(1:len)=fNIR_f{b,a};
                                                    curP(1:len)=fNIR_p{b,a};
                                                    curDf1(1:len)=fNIR_df{b,a};
                                                    curDf2(1:len)=fNIR_df2{b,a};


                                                    switch(ExFNIRS.settings.ChannelMode)
                                                        case 'fNIR'
                                                            interpolateNIR(curF,'Mode','fstat','fontSize',12,'transparent',true,'lowerThreshold',estimatedPval_min,'TitleText',titleSTR,'ChannelLabels',true)%,7,11,2,1,false,'[Hb-Oxy] Natural High Vs. Low',12,'hot',true)
                                                        case 'ROI'
                                                            roiInfo=ExFNIRS.currentROI;
                                                            interpolateNIR(mapROIvaluesToCh(roiInfo,curF),'Mode','fstat','fontSize',12,'transparent',true,'lowerThreshold',estimatedPval_min,'TitleText',titleSTR,'ChannelLabels',true)%,7,11,2,1,false,'[Hb-Oxy] Natural High Vs. Low',12,'hot',true)
                                                    end
                                                otherwise
            
                                                    switch(ExFNIRS.settings.ChannelMode)
                                                        case 'fNIR'
                                                            processFNIRS2.Data.Plot.InterpolateValues([],curF,estimatedPval_min,[],1,titleSTR,'F-val');%InterpolateValues(fNIR,data2plot,minVal,maxVal,bufferMult,titleString,clrBarTitle)
                                                        case 'ROI'
                                                            roiInfo=ExFNIRS.currentROI;
                                                            processFNIRS2.Data.Plot.InterpolateValues([],mapROIvaluesToCh(roiInfo,curF),estimatedPval_min,[],1,titleSTR,'F-val');%,7,11,2,1,false,'[Hb-Oxy] Natural High Vs. Low',12,'hot',true)
                                                    end
                                            end
            
            
                                            if(a==1) % first column
                                                curAxes=gca;
                                                axesPos=curAxes.OuterPosition;
                                                th=annotation(gcf,'textbox',[0,axesPos(2),axesPos(3),axesPos(4)/2],'Items',selectedBioM(b),'FitBoxToText','on');
                                            end
                                        else
                                            plot(0,0);
                                            curAxes=gca;
                                            axesPos=curAxes.OuterPosition;
                                            axis off
                                            title(sprintf('%s_N_S',anovaNames{a}));
                                            if(a==1) % first column
                                                th=annotation(gcf,'textbox',[0,axesPos(2),axesPos(3),axesPos(4)/2],'Items',selectedBioM(b),'FitBoxToText','on');
                                            end
                                        end
                                    end
            
                           end
                        end
                    end
            
                    if(doublePlotWithFDR&&FDRfound)
                        topoHfdr=figure(2001);
                        clf(topoHfdr);
                        addDebugAnnotation(app, topoHfdr);
            
            
                            for b=1:numBioM
                                switch(LME_topo_mode)
                                 case 'coef'
                                    for c=1:numCoeff
                                        subplot(numBioM,numCoeff,c+(b-1)*numCoeff)
                                        curT=fNIR_t{b,c};
                                        curP=fNIR_p{b,c};
                                        curQ=performFDR(app, curP);
            
                                        switch(ExFNIRS.settings.ChannelMode)
                                            case 'fNIR'
                                                interpolateNIR(curT,'Mode','tstat','fontSize',12,'transparent',true,'pValueMask',curQ,'TitleText',coefNames{c},'ChannelLabels',true)%,7,11,2,1,false,'[Hb-Oxy] Natural High Vs. Low',12,'hot',true)
                                            case 'ROI'
                                                roiInfo=ExFNIRS.currentROI;
                                                interpolateNIR(mapROIvaluesToCh(roiInfo,curT),'Mode','tstat','fontSize',12,'transparent',true,'pValueMask',mapROIvaluesToCh(roiInfo,curQ),'TitleText',coefNames{c},'ChannelLabels',true)%,7,11,2,1,false,'[Hb-Oxy] Natural High Vs. Low',12,'hot',true)
                    %                             maxVal=nanmax([nanmax(abs(curT(:))),1]);
                    %                             minVal=nanmin([maxVal+0.1;abs(curT(curP<0.05))]);
                    %                             if(maxVal<=minVal)
                    %                                 minVal=maxVal;
                    %                                 maxVal=maxVal+0.05;
                    %                             end
                    %
                    %                             numROI=size(ExFNIRS.currentROI,1);
                    %                             vals=abs(curT(1:numROI));
                    %                             processFNIRS2.Data.Plot.InterpolateROIvalues(roiInfo,vals,minVal,maxVal,1,coefNames{c},'tstat');%,7,11,2,1,false,'[Hb-Oxy] Natural High Vs. Low',12,'hot',true)
                                        end
                                        if(c==1) % first column
                                            ylabel(selectedBioM(b));
                                        end
                                    end
                                    case 'anova'
                                       for a=1:numANOVA
                                        subplot(numBioM,numANOVA,a+(b-1)*numANOVA)
                                        curT=fNIR_f{b,a};
                                        curP=fNIR_p{b,a};
                                        curQ=performFDR(app, curP);
            
                                        switch(ExFNIRS.settings.ChannelMode)
                                            case 'fNIR'
                                                interpolateNIR(curT,'Mode','tstat','fontSize',12,'transparent',true,'pValueMask',curQ,'TitleText',numANOVA{a},'ChannelLabels',true)%,7,11,2,1,false,'[Hb-Oxy] Natural High Vs. Low',12,'hot',true)
                                            case 'ROI'
                                                roiInfo=ExFNIRS.currentROI;
                                                interpolateNIR(mapROIvaluesToCh(roiInfo,curT),'Mode','tstat','fontSize',12,'transparent',true,'pValueMask',mapROIvaluesToCh(roiInfo,curQ),'TitleText',numANOVA{a},'ChannelLabels',true)%,7,11,2,1,false,'[Hb-Oxy] Natural High Vs. Low',12,'hot',true)
                    %                             maxVal=nanmax([nanmax(abs(curT(:))),1]);
                    %                             minVal=nanmin([maxVal+0.1;abs(curT(curP<0.05))]);
                    %                             if(maxVal<=minVal)
                    %                                 minVal=maxVal;
                    %                                 maxVal=maxVal+0.05;
                    %                             end
                    %
                    %                             numROI=size(ExFNIRS.currentROI,1);
                    %                             vals=abs(curT(1:numROI));
                    %                             processFNIRS2.Data.Plot.InterpolateROIvalues(roiInfo,vals,minVal,maxVal,1,coefNames{c},'tstat');%,7,11,2,1,false,'[Hb-Oxy] Natural High Vs. Low',12,'hot',true)
                                        end
                                        if(a==1) % first column
                                            ylabel(selectedBioM(b));
                                        end
                                    end
            
                                end
                            end
                        suptitle('FDR Edition');
                    end
            
                end
            end
        end
        
        function popupmenu_feature_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu_feature (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function preprocessFNIRSData(app)
            global ExFNIRS
            if(ExFNIRS.UpdateNeeded==2||~isfield(ExFNIRS,'curPreprocessedFNIR'))
            
                pf2_base.closeProgressHandles();
            
                 numSegs2Process=size(ExFNIRS.curProcessedData,1);
            
                global ProgressHandles
                ProgressHandles.h.hF=waitbar(0,sprintf('ExploreFNIRS\nResampling and baselining fNIRS %i of %i',1,numSegs2Process));
                hF=ProgressHandles.h.hF;
            
            
                ExFNIRS.curPreprocessedFNIR=[];
                ExFNIRS.curPreprocessedFNIR.fNIR=ExFNIRS.curProcessedData;
                ExFNIRS.curPreprocessedFNIR.baseline=cell(size(ExFNIRS.curProcessedData));
                ExFNIRS.curPreprocessedFNIR.gbyFNIRS=ExFNIRS.curProcessedData;
                ExFNIRS.curPreprocessedFNIR.gbyFNIRS_blk=cell(size(ExFNIRS.curProcessedData));
            
                for i=1:numSegs2Process
            
                   try
                        waitbar(i/numSegs2Process,hF,sprintf('ExploreFNIRS\nResampling and baselining fNIRS %i of %i',i,numSegs2Process));
                   catch
            
                   end
                   if(ExFNIRS.settings.use_baseline)
                        ExFNIRS.curPreprocessedFNIR.baseline{i}=processFNIRS2.Data.Split(ExFNIRS.curPreprocessedFNIR.fNIR{i},ExFNIRS.settings.baseline_start,ExFNIRS.settings.baseline_end); %baselineining is handled in processing section
                        ExFNIRS.curPreprocessedFNIR.gbyFNIRS{i}.time=ExFNIRS.curPreprocessedFNIR.gbyFNIRS{i}.time-ExFNIRS.settings.block_start; %change time so that 0 is start of block
            
                        ExFNIRS.curPreprocessedFNIR.gbyFNIRS_blk{i}=processFNIRS2.Data.Resample(ExFNIRS.curPreprocessedFNIR.gbyFNIRS{i}, ExFNIRS.settings.barchart_resample_size,'centerOnT0',true,'timeOutMode','start','blfNIR',ExFNIRS.curPreprocessedFNIR.baseline{i},'averageAux',true);
                        ExFNIRS.curPreprocessedFNIR.gbyFNIRS_blk{i}.time=ExFNIRS.curPreprocessedFNIR.gbyFNIRS_blk{i}.time+ExFNIRS.settings.block_start; %change time so that 0 is start of block
                        if(isfield(ExFNIRS.curPreprocessedFNIR.gbyFNIRS_blk{i},'segmentTimes'))
                            ExFNIRS.curPreprocessedFNIR.gbyFNIRS_blk{i}.segmentTimes=ExFNIRS.curPreprocessedFNIR.gbyFNIRS_blk{i}.segmentTimes+ExFNIRS.settings.block_start;
                        else
                           z=1;
                        end
            
                        if(isnan(ExFNIRS.curPreprocessedFNIR.gbyFNIRS_blk{i}.time))
                           z=1;
                        end
                        ExFNIRS.curPreprocessedFNIR.gbyFNIRS{i}=processFNIRS2.Data.Resample(ExFNIRS.curPreprocessedFNIR.gbyFNIRS{i}, ExFNIRS.settings.grandavg_resample_size,'centerOnT0',true,'timeOutMode','start','blfNIR',ExFNIRS.curPreprocessedFNIR.baseline{i},'averageAux',true);
                        ExFNIRS.curPreprocessedFNIR.gbyFNIRS{i}.time=ExFNIRS.curPreprocessedFNIR.gbyFNIRS{i}.time+ExFNIRS.settings.block_start; %change time so that 0 is start of block
                        if(isfield(ExFNIRS.curPreprocessedFNIR.gbyFNIRS{i},'segmentTimes'))
                            ExFNIRS.curPreprocessedFNIR.gbyFNIRS{i}.segmentTimes=ExFNIRS.curPreprocessedFNIR.gbyFNIRS{i}.segmentTimes+ExFNIRS.settings.block_start;
                        else
                           z=1;
                        end
                   else
                        ExFNIRS.curPreprocessedFNIR.baseline{i}=[];
                        ExFNIRS.curPreprocessedFNIR.gbyFNIRS{i}.time=ExFNIRS.curPreprocessedFNIR.gbyFNIRS{i}.time-ExFNIRS.settings.block_start; %change time so that 0 is start of block
                        ExFNIRS.curPreprocessedFNIR.gbyFNIRS_blk{i}=processFNIRS2.Data.Resample(ExFNIRS.curPreprocessedFNIR.gbyFNIRS{i}, ExFNIRS.settings.barchart_resample_size,'centerOnT0',true,'timeOutMode','start','averageAux',true);
                        ExFNIRS.curPreprocessedFNIR.gbyFNIRS_blk{i}.time=ExFNIRS.curPreprocessedFNIR.gbyFNIRS_blk{i}.time+ExFNIRS.settings.block_start; %change time so that 0 is start of block
                        ExFNIRS.curPreprocessedFNIR.gbyFNIRS_blk{i}.segmentTimes=ExFNIRS.curPreprocessedFNIR.gbyFNIRS_blk{i}.segmentTimes+ExFNIRS.settings.block_start;
            
                        ExFNIRS.curPreprocessedFNIR.gbyFNIRS{i}=processFNIRS2.Data.Resample(ExFNIRS.curPreprocessedFNIR.gbyFNIRS{i}, ExFNIRS.settings.grandavg_resample_size,'centerOnT0',true,'timeOutMode','start','averageAux',true);
                        ExFNIRS.curPreprocessedFNIR.gbyFNIRS{i}.time=ExFNIRS.curPreprocessedFNIR.gbyFNIRS{i}.time+ExFNIRS.settings.block_start; %change time so that 0 is start of block
                        ExFNIRS.curPreprocessedFNIR.gbyFNIRS{i}.segmentTimes=ExFNIRS.curPreprocessedFNIR.gbyFNIRS{i}.segmentTimes+ExFNIRS.settings.block_start;
            
                   end
                end
            
                try
                    close(hF);
                catch
            
            end
            else
            
                ExFNIRS.UpdateNeeded=true; % mark that data was preprocesed
            end
        end
        
        function processCurrentFunction(app)
            
            global ExFNIRS
            
            if(ExFNIRS.UpdateNeeded==4)
                error('Dataset has been updated, please close and repoen ExFNIRS');
            end
            
            if(ExFNIRS.UpdateNeeded==3)
            
            strsRaw=get(app.listbox_raw_methods,'Items');
            selectedStrs=get(app.listbox_raw_methods,'Value');
            cur_raw_method=strsRaw(selectedStrs,:);
            
            strsOxy=get(app.listbox_oxy_methods,'Items');
            selectedStrs=get(app.listbox_oxy_methods,'Value');
            cur_oxy_method=strsOxy(selectedStrs,:);
            
            if(~isfield(ExFNIRS,'processedData'))
                ExFNIRS.processedData=cell(length(strsOxy)*length(strsRaw),3);
                ExFNIRS.numProcessed=0;
            end
            
            if(ExFNIRS.settings.processRaw)
                exploreFNIRS.processMethods(cur_raw_method,cur_oxy_method);
            else
                exploreFNIRS.processMethods([],cur_oxy_method);
            end
            
            [strsOxy,iOxy]=processFNIRS2.Methods.Oxy();
            [strsRaw,iRaw]=processFNIRS2.Methods.Raw();
            
            set(app.listbox_oxy_methods,'Items',strsOxy);
            set(app.listbox_oxy_methods,'Value',find(iOxy));
            set(app.listbox_raw_methods,'Items',strsRaw);
            set(app.listbox_raw_methods,'Value',find(iRaw));
            
            ExFNIRS.UpdateNeeded=2;
            
            end
        end
        
        function processSelectedTable(app, sellFullIdx, gbyIdx)
            global ExFNIRS
            pf2_base.closeProgressHandles();
            
            
            global ProgressHandles
            ProgressHandles.h.hF=waitbar(0,sprintf('ExploreFNIRS\nProcessing Group %i of %i',1,max(gbyIdx)));
            hF=ProgressHandles.h.hF;
            
            numSegs2Process=size(ExFNIRS.selectedTable,1);
            
            ExFNIRS.gbyFlat=[];
            ExFNIRS.gbyFlat.fNIR=ExFNIRS.curPreprocessedFNIR.fNIR(sellFullIdx,:);
            ExFNIRS.gbyFlat.baseline=ExFNIRS.curPreprocessedFNIR.baseline(sellFullIdx,:);
            ExFNIRS.gbyFlat.gbyFNIRS=ExFNIRS.curPreprocessedFNIR.gbyFNIRS(sellFullIdx,:);
            ExFNIRS.gbyFlat.gbyFNIRS_blk=ExFNIRS.curPreprocessedFNIR.gbyFNIRS_blk(sellFullIdx,:);
            ExFNIRS.gbyFlat.gbyIndex=gbyIdx;
            
            
            for i=1:max(gbyIdx)
                try
                    waitbar(i/max(gbyIdx),hF,sprintf('ExploreFNIRS\nProcessing Group %i of %i',i,max(gbyIdx)));
                catch
            
                end
                ExFNIRS.gby(i).gbyTables=ExFNIRS.selectedTable(gbyIdx==i,:);
                ExFNIRS.gby(i).gbyFNIRS=ExFNIRS.gbyFlat.gbyFNIRS(gbyIdx==i,:);
                ExFNIRS.gby(i).gbyFNIRS_blk=ExFNIRS.gbyFlat.gbyFNIRS_blk(gbyIdx==i,:);
            
            
                if(ExFNIRS.settings.within_sub_avg_mode==1)
                   hArg=[];
                   ExFNIRS.settings.within_sub_avg_mode_label='None';
                elseif(ExFNIRS.settings.within_sub_avg_mode==2)
                    hArg=ExFNIRS.gby(i).gbyTables(:,'SubjectID');
                    ExFNIRS.settings.within_sub_avg_mode_label='Flat';
                elseif(ExFNIRS.settings.within_sub_avg_mode==3)
                    hArg=ExFNIRS.gby(i).gbyTables(:,ExFNIRS.dataHierarchy);
                    ExFNIRS.settings.within_sub_avg_mode_label='Hierarchy';
                end
                ExFNIRS.gby(i).gbyGrand=grandAvgFNIRS(ExFNIRS.gby(i).gbyFNIRS,false,[],false,hArg,false,true);
                ExFNIRS.gby(i).gbyGrandBar=grandAvgFNIRS(ExFNIRS.gby(i).gbyFNIRS_blk,false, ExFNIRS.settings.barchart_resample_size,false,hArg,false,true);
                ExFNIRS.gby(i).gbyGrandBarFlat=grandAvgFNIRS(ExFNIRS.gby(i).gbyFNIRS_blk,false, ExFNIRS.settings.barchart_resample_size,false,ExFNIRS.gby(i).gbyTables(:,'SubjectID'),false,true);
                try
                    close(eHf);
                catch
                end
            end
            
            set(app.text_status,'Text',sprintf('%i Segments in\n%i Group(s)',numSegs2Process,max(gbyIdx)));
            try
                close(hF);
            catch
            
            end
            
            
            flagForUpdate(app,false);
        end
        
        function myTable = remove9999(app, myTable)
            
            for c=1:size(myTable,2)
                if(isnumeric(myTable{:,c}))
                    myTable{myTable{:,c}==-9999,c}=nan;
                elseif(iscell(myTable{:,c})&&(ischar(myTable{:,c}{1})||isstring(myTable{:,c}{1})))
                    myTable{strcmp('-9999',myTable{:,c}),c}={''};
                end
            end
        end
        
        function setExChannelMode(app, modeStr, initROI)
            % sets the channel mode and changes GUI to match
            
            if(nargin<3)
                initROI=false;
            end
            
            global ExFNIRS
            ExFNIRS.settings.ChannelMode=modeStr;
            
            switch (ExFNIRS.settings.ChannelMode)
                case 'fNIR'
                    uOpt=[];
                    for i=1:length(ExFNIRS.data)
                        if(isfield(ExFNIRS.data{i},'channels'))
                            uOpt=[uOpt,ExFNIRS.data{i}.channels];
                        end
                    end
                    uOpt=sort(unique(uOpt));
            
                    set(app.text_optode_label,'Text','Optode');
                    set(app.text_biomarker_label,'Text','BioMarker');
                    set(app.listbox_biomarker,'Enable','on');
                    set(app.pushbutton_biomarker_select_all,'Enable','on');
                    set(app.pushbutton_biomarker_select_none,'Enable','on');
                    set(app.listbox_optode,'Enable','on');
                        set(app.pushbutton_optodes_select_all,'Enable','on');
                        set(app.pushbutton_optodes_select_none,'Enable','on');
            
                    set(app.listbox_optode,'Items',uOpt);
                    set(app.listbox_optode,'Value',1);
                    set(app.listbox_biomarker,'Items',{'HbO','HbR','HbDiff','HbTotal','CBSI'});
            
                    set(app.pushbutton_lme_plot_topo,'Enable','on');
                case 'ROI'
                    uAux={};
                    auxNames={};
                    for i=1:length(ExFNIRS.data)
                        if(pf2_base.isnestedfield(ExFNIRS.data{i},'ROI.info'))
                            curAuxNames=ExFNIRS.data{i}.ROI.info.Properties.RowNames;
                            if(any(~ismember(curAuxNames,auxNames)))
                                for auxnum=1:size(ExFNIRS.data{i}.ROI.info,1)
                                    if(~ismember(curAuxNames{auxnum},auxNames))
                                        if(isempty(ExFNIRS.data{i}.ROI.info.Properties.RowNames{auxnum}))
                                            newRoiName=sprintf('ROI%i',auxnum+length(rowNames));
                                            auxNames=[auxNames,{newRoiName}];
                                            ExFNIRS.data{i}.ROI.info.Properties.RowNames{auxnum}=newRoiName;
                                        else
                                            auxNames=[auxNames,ExFNIRS.data{i}.ROI.info.Properties.RowNames(auxnum)];
                                        end
                                        ExFNIRS.data{i}.ROI.info.DeviceCfg(:)={ExFNIRS.data{i}.info.probename};
                                        uAux=[uAux;ExFNIRS.data{i}.ROI.info(auxnum,:)];
                                    end
                                end
                            end


                        end
                    end
                    if(isempty(uAux))
                        warning('No ROIs present in data');
                        set(app.popupmenu_ChannelMode,'Value',1);
                    else
                        %uROI=unique(uROI{:},'rows');
            
                        [uROInames,b,c]=unique(auxNames);
                        uROInames=auxNames(b);
                        uAux=uAux(b,:);


                        if(initROI) % standaradize all ROIs on first load
                            fprintf(2,'************\nStandardizing all ROI fields..\n********\n');
                            for i=1:length(ExFNIRS.data)
                                if(pf2_base.isnestedfield(ExFNIRS.data{i},'raw')&&~isempty(ExFNIRS.data{i}))
                                   ExFNIRS.data{i}.ROI.info=uAux;
                                end
                            end
                        end
            
                        ExFNIRS.currentROI=uAux;
            
            
                        set(app.text_optode_label,'Text','ROI');
                        set(app.text_biomarker_label,'Text','BioMarker');
                        set(app.listbox_biomarker,'Enable','on');
                        set(app.pushbutton_biomarker_select_all,'Enable','on');
                        set(app.pushbutton_biomarker_select_none,'Enable','on');
            
                        set(app.listbox_optode,'Enable','on');
                        set(app.pushbutton_optodes_select_all,'Enable','on');
                        set(app.pushbutton_optodes_select_none,'Enable','on');
            
                        set(app.listbox_optode,'Items',uROInames);
                        set(app.listbox_optode,'Value',1);
                        set(app.listbox_biomarker,'Items',{'HbO','HbR','HbDiff','HbTotal','CBSI'});
                        set(app.pushbutton_lme_plot_topo,'Enable','on');
                    end
                case 'Aux'
            
                    auxNames={};
                    for i=1:length(ExFNIRS.data)
                        if(pf2_base.isnestedfield(ExFNIRS.data{i},'Aux'))
                            curAuxNames=fields(ExFNIRS.data{i}.Aux);
                            if(any(~ismember(curAuxNames,auxNames)))
                                for auxnum=1:size(curAuxNames,1)
                                    if(~ismember(curAuxNames{auxnum},auxNames))
                                        auxNames=[auxNames,curAuxNames{auxnum}];
                                    end
                                end
                            end
                        end
                    end
            
            
                    if(isempty(auxNames))
                        warning('No Auxillary channels present in data');
                        set(app.popupmenu_ChannelMode,'Value',1);
                    else
                        %uROI=unique(uROI{:},'rows');
            
                        [uAuxNames,b,c]=unique(auxNames);
                        uAuxNames=auxNames(b);


            %             if(initROI) % standaradize all ROIs on first load
            %                 fprintf(2,'************\nStandardizing all ROI fields..\n********\n');
            %                 for i=1:length(ExFNIRS.data)
            %                     if(pf2_base.isnestedfield(ExFNIRS.data{i},'raw')&&~isempty(ExFNIRS.data{i}))
            %                        ExFNIRS.data{i}.ROI.info=uAux;
            %                     end
            %                 end
            %             end
            
                      %  ExFNIRS.currentROI=uAux;
            
                        set(app.text_optode_label,'Text','Aux');
                        set(app.text_biomarker_label,'Text','Aux Signal');
                        set(app.listbox_optode,'Enable','off');
                        set(app.pushbutton_optodes_select_all,'Enable','off');
                        set(app.pushbutton_optodes_select_none,'Enable','off');
                        set(app.listbox_biomarker,'Enable','on');
                        set(app.pushbutton_biomarker_select_all,'Enable','on');
                        set(app.pushbutton_biomarker_select_none,'Enable','on');
                        set(app.listbox_optode,'Items',{'Aux'});
                        set(app.listbox_optode,'Value',1);
                        set(app.listbox_biomarker,'Value',1);
                        set(app.listbox_biomarker,'Items',uAuxNames);
                        set(app.pushbutton_lme_plot_topo,'Enable','off');
                    end
            end
        end
        
        function updateButtonListColors(app)
            
            global ExFNIRS
            
            set(app.pushbutton_gui_color_1,'ForegroundColor',ExFNIRS.settings.guiColor(1,:));
            set(app.pushbutton_gui_color_2,'ForegroundColor',ExFNIRS.settings.guiColor(2,:));
            set(app.pushbutton_gui_color_3,'ForegroundColor',ExFNIRS.settings.guiColor(3,:));
            set(app.pushbutton_gui_color_4,'ForegroundColor',ExFNIRS.settings.guiColor(4,:));
            set(app.pushbutton_gui_color_5,'ForegroundColor',ExFNIRS.settings.guiColor(5,:));
            set(app.pushbutton_gui_color_6,'ForegroundColor',ExFNIRS.settings.guiColor(6,:));
            set(app.pushbutton_gui_color_7,'ForegroundColor',ExFNIRS.settings.guiColor(7,:));
            set(app.pushbutton_gui_color_8,'ForegroundColor',ExFNIRS.settings.guiColor(8,:));
            set(app.pushbutton_gui_color_9,'ForegroundColor',ExFNIRS.settings.guiColor(9,:));
            set(app.pushbutton_gui_color_10,'ForegroundColor',ExFNIRS.settings.guiColor(10,:));
            
            colorStr=get(app.listbox_color_order,'Items');
        end
        
        function updateInfoGroupByVars(app)
            global ExFNIRS
            
            plotInfoVars=cell(length(ExFNIRS.groupByVars)+1,1);
            plotInfoVars{1}='';
            plotInfoVars(2:end,1)=ExFNIRS.groupByVars;
            set(app.popupmenu_info_group,'Items',plotInfoVars);
            
            
            [a,b]=ismember(ExFNIRS.settings.curInfoGroup,plotInfoVars);
            
            if(a==1)
               set(app.popupmenu_info_group,'Value',b);
            else
                set(app.popupmenu_info_group,'Value',1);
            end
        end
        
        function updateSelectedTable(app, processDataNow)
            
            if(nargin<2)
                processDataNow=true;
            else
                processDataNow=false;
            end
            
            global ExFNIRS
            
            pf2_base.closeProgressHandles();
            
            ExFNIRS.selectedTable=table();
            ExFNIRS.selectedFNIR=cell(0);
            ExFNIRS.selectedIdx=[];
            
            strs=get(app.listbox_subjectID,'Items');
            selectedStrs=get(app.listbox_subjectID,'Value');
            selectedSubs=strs(selectedStrs,:);
            if(isnumeric(ExFNIRS.dataTable.('SubjectID')))
                selectedSubs=str2num(selectedSubs);
            else
               if(~iscell(selectedSubs))
                   selectedSubs={selectedSubs};
               end
            
               missingIdx=strcmp('Missing',selectedSubs);
               %selectedSubs(missingIdx)={''};
            end
            selSubIdx=ismember(ExFNIRS.dataTable.('SubjectID'),selectedSubs);
            
            strs=get(app.listbox_condition,'Items');
            selectedStrs=get(app.listbox_condition,'Value');
            selectedCondition=strs(selectedStrs,:);
            if(isnumeric(ExFNIRS.dataTable.('Condition')))
                selectedCondition=str2num(selectedCondition);
            else
               if(~iscell(selectedCondition))
                   selectedCondition={selectedCondition};
               end
            
               missingIdx=strcmp('Missing',selectedCondition);
               %selectedCondition(missingIdx)={''};
            end
            selConditionIdx=ismember(ExFNIRS.dataTable.('Condition'),selectedCondition);
            
            strs=get(app.listbox_session,'Items');
            selectedStrs=get(app.listbox_session,'Value');
            selectedSession=strs(selectedStrs,:);
            if(isnumeric(ExFNIRS.dataTable.('Session')))
                selectedSession=str2num(selectedSession);
            else
               if(~iscell(selectedSession))
                   selectedSession={selectedSession};
               end
            
               missingIdx=strcmp('Missing',selectedSession);
               %selectedSession(missingIdx)={''};
            end
            selSessionIdx=ismember(ExFNIRS.dataTable.('Session'),selectedSession);
            
            strs=get(app.listbox_block,'Items');
            selectedStrs=get(app.listbox_block,'Value');
            selectedBlock=strs(selectedStrs,:);
            if(isnumeric(ExFNIRS.dataTable.('Block')))
                selectedBlock=str2num(selectedBlock);
            else
               if(~iscell(selectedBlock))
                   selectedBlock={selectedBlock};
               end
            
               missingIdx=strcmp('Missing',selectedBlock);
               %selectedBlock(missingIdx)={''};
            end
            selBlockIdx=ismember(ExFNIRS.dataTable.('Block'),selectedBlock);
            
            if(ExFNIRS.settings.use_group)
                strs=get(app.listbox_group,'Items');
                selectedStrs=get(app.listbox_group,'Value');
                selectedGroup=strs(selectedStrs,:);
                if(isnumeric(ExFNIRS.dataTable.('Group')))
                    selectedGroup=str2num(selectedGroup);
                else
                   if(~isempty(selectedGroup)&&~iscell(selectedGroup))
                       selectedGroup={selectedGroup};
                   end
            
                   missingIdx=strcmp('Missing',selectedGroup);
                   %selectedGroup(missingIdx)={''};
                end
                selGroupIdx=ismember(ExFNIRS.dataTable.('Group'),selectedGroup);
            else
                strs=get(app.listbox_subgroup,'Items');
                selectedStrs=get(app.listbox_subgroup,'Value');
                selectedGroup=strs(selectedStrs,:);
                if(isnumeric(ExFNIRS.dataTable.('Subgroup')))
                    selectedGroup=str2num(selectedGroup);
                else
                   if(~isempty(selectedGroup)&&~iscell(selectedGroup))
                       selectedGroup={selectedGroup};
                   end
            
                   missingIdx=strcmp('Missing',selectedGroup);
                   %selectedGroup(missingIdx)={''};
                end
                selGroupIdx=ismember(ExFNIRS.dataTable.('Subgroup'),selectedGroup);
            end
            
            strs=get(app.listbox_trial,'Items');
            selectedStrs=get(app.listbox_trial,'Value');
            selectedTrial=strs(selectedStrs,:);
            if(isnumeric(ExFNIRS.dataTable.('Trial')))
                selectedTrial=str2num(selectedTrial);
            else
               if(~iscell(selectedTrial))
                   selectedTrial={selectedTrial};
               end
            
               missingIdx=strcmp('Missing',selectedTrial);
               selectedTrial(missingIdx)={''};
            end
            selTrialIdx=ismember(ExFNIRS.dataTable.('Trial'),selectedTrial);
            
            if(ExFNIRS.settings.use_info)
                cInfoGBYstring=ExFNIRS.settings.curInfoGroupBy;
                strs=get(app.listbox_info_groupby,'Items');
                selectedStrs=get(app.listbox_info_groupby,'Value');
                selectedInfoG=strs(selectedStrs,:);
                if(isnumeric(ExFNIRS.dataTable{1,cInfoGBYstring}))
            
                    selInfoGIdx=ismember(ExFNIRS.settings.curInfoGroupByIdx,selectedStrs);
                else
                    selInfoGIdx=ismember(ExFNIRS.dataTable.(cInfoGBYstring),selectedInfoG);
                end
            end
            
            sellFullIdx=selSubIdx&selConditionIdx&selSessionIdx&selBlockIdx&selGroupIdx&selTrialIdx;
            
            if(ExFNIRS.settings.use_info)
                sellFullIdx=sellFullIdx&selInfoGIdx;
            end
            
            if(~any(sellFullIdx))
                statusTextStr='No data matching selection';
                ExFNIRS.statusGroupByStr='Ungrouped';
                set(app.text_status_text,'Text',statusTextStr);
                set(app.text_status,'Text','0 Segments in\n0 Group(s)');
                set(app.popupmenu_info_group,'Items',{''});
                return
            end
            
            ExFNIRS.selectedTable=ExFNIRS.dataTable(sellFullIdx,:);
            
            ExFNIRS.selectedIdx=sellFullIdx;
            
            subColIdx=find(strcmp('SubjectID',ExFNIRS.dataTable.Properties.VariableNames));
            groupColIdx=find(strcmp('Group',ExFNIRS.dataTable.Properties.VariableNames));
            subgroupColIdx=find(strcmp('Subgroup',ExFNIRS.dataTable.Properties.VariableNames));
            sessionColIdx=find(strcmp('Session',ExFNIRS.dataTable.Properties.VariableNames));
            conditionColIdx=find(strcmp('Condition',ExFNIRS.dataTable.Properties.VariableNames));
            trialColIdx=find(strcmp('Trial',ExFNIRS.dataTable.Properties.VariableNames));
            blockColIdx=find(strcmp('Block',ExFNIRS.dataTable.Properties.VariableNames));
            
            if(ExFNIRS.settings.use_group)
                gPlotByGroup=[ExFNIRS.settings.plotby.Group,0];
            else
                gPlotByGroup=[0,ExFNIRS.settings.plotby.Group];
            end
            
            groubbyIdxEnable=[ExFNIRS.settings.plotby.SubjectID,ExFNIRS.settings.plotby.Group&&ExFNIRS.settings.use_group,ExFNIRS.settings.plotby.Group&&~ExFNIRS.settings.use_group,...
                ExFNIRS.settings.plotby.Session,ExFNIRS.settings.plotby.Condition,ExFNIRS.settings.plotby.Trial,ExFNIRS.settings.plotby.Block];
            groupbyIdx=[subColIdx,groupColIdx,subgroupColIdx,sessionColIdx,conditionColIdx,trialColIdx,blockColIdx].*groubbyIdxEnable;
            groupbyIdx=groupbyIdx(groupbyIdx>0);
            
            if(ExFNIRS.settings.plotby.InfoGroupBy)
                groupbyIdx=[groupbyIdx,find(strcmp(cInfoGBYstring,ExFNIRS.dataTable.Properties.VariableNames))];
            end
            
            [groupbyRows,ia,gbyIdx]=unique(ExFNIRS.selectedTable(:,groupbyIdx));
            
            processMinTime=min([ExFNIRS.settings.baseline_start,ExFNIRS.settings.block_start,ExFNIRS.settings.plot_start]);
            processMaxTime=max([ExFNIRS.settings.baseline_end,ExFNIRS.settings.block_end,ExFNIRS.settings.plot_end]);
            
            if(isfield(ExFNIRS,'gby'))
                rmfield(ExFNIRS,'gby');
            end
            ExFNIRS.gby=[];
            ExFNIRS.groupByVars=groupbyRows.Properties.VariableNames;
            
            statusTextStr='Grouping data by ';
            ExFNIRS.statusGroupByStr='';
            for i=1:length(ExFNIRS.groupByVars)
                statusTextStr=sprintf('%s %s X ', statusTextStr,ExFNIRS.groupByVars{i});
                if(i==1)
                    ExFNIRS.statusGroupByStr=ExFNIRS.groupByVars{i};
                else
                    ExFNIRS.statusGroupByStr=sprintf('%s X ', ExFNIRS.statusGroupByStr,ExFNIRS.groupByVars{i});
                end
            end
            
            statusTextStr(end-2:end)=[];
            if(length(ExFNIRS.statusGroupByStr)>2)
                ExFNIRS.statusGroupByStr(end-2:end)=[];
            else
                ExFNIRS.statusGroupByStr='One Group';
            end
            
            
            if(isempty(ExFNIRS.groupByVars))
                statusTextStr='Data is ungrouped';
                ExFNIRS.statusGroupByStr='Ungrouped';
            end
            
            
            set(app.text_status_text,'Text',statusTextStr);
            
            updateInfoGroupByVars(app);
            
            set(app.text_status,'Text',sprintf('%i Observations in\n%i Group(s)',size(ExFNIRS.selectedTable,1),max(gbyIdx)));


            if(processDataNow)
                processCurrentFunction(app);
            
                if(~isfield(ExFNIRS,'curProcessedData')||isempty(ExFNIRS.curProcessedData))
                   flagForUpdate(app,3);
                   error('No processed data yet!');
                end
            
                ExFNIRS.selectedFNIR=ExFNIRS.curProcessedData(sellFullIdx,:);


                preprocessFNIRSData(app);
                processSelectedTable(app,sellFullIdx,gbyIdx);
            end
        end
        
        function writeLogFile(app, logFileName, path)
            
            global ExFNIRS
            
            settings=ExFNIRS.settings;
            
            fullPath=sprintf('%s/%s',path,logFileName);
            fileID = fopen(fullPath,'w');
            versInfo=exploreFNIRS.versInfo();
            fprintf(fileID,'%s\n',versInfo);
            fprintf(fileID,'Exported %s\n',datestr(datetime('now')));
            
            fprintf(fileID,'Group By: %s\n',ExFNIRS.statusGroupByStr);
            fprintf(fileID,'Cur Method: %s\n',ExFNIRS.curMethodName);
            fprintf(fileID,'Averaging Method: %s\n',settings.within_sub_avg_mode_label);
            fprintf(fileID,'GrandAvg Resample Size: %.2f\n',settings.grandavg_resample_size);
            fprintf(fileID,'Resample Size: %.2f\n',settings.barchart_resample_size);
            fprintf(fileID,'Baseline Start: %.2f\n',settings.baseline_start);
            fprintf(fileID,'Baseline End: %.2f\n',settings.baseline_end);
            fprintf(fileID,'Block Start: %.2f\n',settings.block_start);
            fprintf(fileID,'Block End: %.2f\n',settings.block_end);
            fprintf(fileID,'Feature: %s\n',settings.plot_bar_feature);
            
            fprintf(fileID,'\n');
            
            rawMethodDescrip=processFNIRS2.Methods.Raw.DescribeMethod();
            fprintf(fileID,'%s\n',rawMethodDescrip);
            oxyMethodDescrip=processFNIRS2.Methods.Oxy.DescribeMethod();
            fprintf(fileID,'%s\n',oxyMethodDescrip);
            
            
            fclose(fileID);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function exploreFNIRS_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to exploreFNIRS (see VARARGIN)
            
            % Choose default command line output for exploreFNIRS
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            initialize_gui(app, hObject, handles, false);
            
            global ExFNIRS
            
            if(~isfield(ExFNIRS,'defaultRootPath'))
                [ExFNIRS_folder,~,~] = fileparts(mfilename('fullpath'));
                ExFNIRS.defaultRootPath=ExFNIRS_folder;
                curdir=cd;
                cd(ExFNIRS.defaultRootPath);
                addpath('base_functions','GUI','functions');
                cd(curdir);
            end
            
            set(app.text_versInfo,'Text',exploreFNIRS.versInfo());
            
            warning('OFF','MATLAB:table:RowsAddedExistingVars')
            p=inputParser;
            
            validScalarNum = @(x) isnumeric(x) && isscalar(x);
            validScalarPosNum = @(x) isnumeric(x) && isscalar(x) && (x > 0);
            
            if(~isfield(ExFNIRS,'settings')||~isfield(ExFNIRS.settings,'baseline_start'))
                ExFNIRS.settings.baseline_start=0;
                ExFNIRS.settings.baseline_end=5;
                ExFNIRS.settings.block_start=5;
                ExFNIRS.settings.block_end=65;
                ExFNIRS.settings.plot_start=[];
                ExFNIRS.settings.plot_end=[];
                ExFNIRS.settings.barchart_resample_size=60;
                ExFNIRS.settings.timeShiftTo0=true;
                ExFNIRS.settings.ChannelMode='fNIR';  % valid is fNIR, ROI, and Aux
                ExFNIRS.settings.ChannelModes={1,'fNIR';2,'ROI';3,'Aux'};
                ExFNIRS.settings.processRaw=get(app.checkbox_process_raw,'Value');
                ExFNIRS.settings.LME_use_intercept=get(app.checkbox_LME_use_intercept,'Value');
                ExFNIRS.settings.LME_use_discreteTime=get(app.checkbox_discreteTime,'Value');
                ExFNIRS.settings.LME_randomFxStr='1|SubjectID';
                ExFNIRS.settings.LME_use_customStr=get(app.checkbox_lme_usecustom,'Value');
                ExFNIRS.settings.topoSigThrehold={'p',0.05};
                ExFNIRS.settings.LME_customStr='';
                ExFNIRS.settings.use_info=true;
                ExFNIRS.settings.use_group=get(app.checkbox_use_group,'Value');
                ExFNIRS.settings.plot_temporal_y0=get(app.checkbox_yaxis,'Value');
                ExFNIRS.settings.use_baseline=get(app.checkbox_usebaseline,'Value');
                ExFNIRS.dataHierarchy={'SubjectID','Session','Condition','Trial','Block'};
            end
            
            addParameter(p,'filename','',@ischar);
            
            
            addOptional(p,'data',[],@iscell); % Your data, as a cell of FNIRS structs (ideally with populated info fields and the task of interest starting at t=0)
            addOptional(p,'timeShiftTo0',ExFNIRS.settings.timeShiftTo0,@islogical); %Specifies whether to automatically shift the start of the FNIRS period to 0,
            		%best practice though is to turn this off and do it yourself before hand so that task starts at 0s and the baseline is before/after/during. See setT0fnirs()
            addOptional(p,'blStart', ExFNIRS.settings.baseline_start,validScalarNum);  %Time at which baseline starts (absolute)
            addOptional(p,'blEnd',ExFNIRS.settings.baseline_end,validScalarNum);	%Time at which baseline ends (absolute)
            addOptional(p,'blockStart',ExFNIRS.settings.block_start,validScalarNum); %Time at which block or task starts (absolute)
            addOptional(p,'blockEnd',ExFNIRS.settings.block_end,validScalarNum); %Time at which block or task ends (absolute)
            addOptional(p,'plotStart',ExFNIRS.settings.plot_start,validScalarNum); %Default parameter for lower xlimit on plots (affects which models are displayed in barcharts and which timepoints are included)
            addOptional(p,'plotEnd',ExFNIRS.settings.plot_end,validScalarNum); %Default parameter for upper xlimit on plots (see above note)
            addOptional(p,'barSegmentLength', ExFNIRS.settings.barchart_resample_size,validScalarPosNum); %Default averaging/binning period for barcharts AND relevant export information
            
            parse(p,varargin{:});
            
            if(~isempty(p.Results.data)||~isfield(ExFNIRS,'data'))
                ExFNIRS.data=p.Results.data;
            
                if(size(ExFNIRS.data,2)>size(ExFNIRS.data,1))
                    ExFNIRS.data=ExFNIRS.data';
                end
            elseif(~isempty(p.Results.filename))
               exploreFNIRS.LoadEx(p.results.filename);
            end


            ExFNIRS.settings.baseline_start=p.Results.blStart;
            ExFNIRS.settings.baseline_end=p.Results.blEnd;
            ExFNIRS.settings.block_start=p.Results.blockStart;
            ExFNIRS.settings.block_end=p.Results.blockEnd;
            ExFNIRS.settings.plot_start=p.Results.plotStart;
            ExFNIRS.settings.plot_end=p.Results.plotEnd;
            ExFNIRS.settings.barchart_resample_size=p.Results.barSegmentLength;


            segInfoVars={'Session','Trial','Block','Condition','Time'};
            randFxStr{1}='1|SubjectID';
            for i=2:2:length(segInfoVars)*2
               randFxStr{i}=sprintf('%s|SubjectID',segInfoVars{(i)/2});
               randFxStr{i+1}=sprintf('-1+%s|SubjectID',segInfoVars{(i)/2});
            end
            
            set(app.popupmenu_lmer_randomeffects,'Items',randFxStr);
            ExFNIRS.settings.LME_randomFxStrs=randFxStr;
            
            if(ExFNIRS.settings.processRaw)
                set(app.listbox_raw_methods,'Enable','on');
            else
                set(app.listbox_raw_methods,'Enable','off');
                set(app.listbox_raw_methods,'Value',1);
            end


            if(isempty(ExFNIRS.settings.plot_start))
               ExFNIRS.settings.plot_start=min(0,ExFNIRS.settings.block_start);
            end
            
            if(isempty(ExFNIRS.settings.plot_end))
               ExFNIRS.settings.plot_end=ExFNIRS.settings.block_end;
            end


            ExFNIRS.settings.within_sub_avg_mode=get(app.popupmenu_within_sub_avg,'Value'); %0 none, 1 flat, 2 hierarchy
            
            set(app.listbox_hierarchy,'Items',ExFNIRS.dataHierarchy(2:end));
            
            ExFNIRS.settings.timeShiftTo0=p.Results.timeShiftTo0;
            
            set(app.edit_baseline_start,'Value',sprintf('%.2f',ExFNIRS.settings.baseline_start));
            set(app.edit_baseline_end,'Value',sprintf('%.2f',ExFNIRS.settings.baseline_end));
            set(app.edit_block_start,'Value',sprintf('%.2f',ExFNIRS.settings.block_start));
            set(app.edit_block_end,'Value',sprintf('%.2f',ExFNIRS.settings.block_end));
            set(app.edit_plot_start,'Value',sprintf('%.2f',ExFNIRS.settings.plot_start));
            set(app.edit_plot_end,'Value',sprintf('%.2f',ExFNIRS.settings.plot_end));
            set(app.edit_barchart_resample_size,'Value',sprintf('%.2f',ExFNIRS.settings.barchart_resample_size));
            
            set(app.checkbox_process_raw,'Value',ExFNIRS.settings.processRaw);
            strs=get(app.popupmenu_info_group,'Items');
            val=get(app.popupmenu_info_group,'Value');
            if(~iscell(strs))
                selStr=strs;
            elseif(~isempty(strs))
                if(isempty(val))
                    val=1;
                end
                selStr=strs{val};
            else
                selStr='';
            end
            ExFNIRS.settings.curInfoGroup=selStr;


            strs=get(app.popupmenu_groupby_info_field,'Items');
            val=get(app.popupmenu_groupby_info_field,'Value');
            
            if(~iscell(strs))
                selStr=strs;
            elseif(~isempty(strs))
                if(isempty(val))
                    val=1;
                end
                selStr=strs{val};
            else
                selStr='';
            end
            
            ExFNIRS.settings.curInfoGroupBy=selStr;
            
            
            if(isempty(ExFNIRS.data))
                error('Must supply data!');
            end
            
            fsArray=nan(length(ExFNIRS.data(:)));
            for i=length(ExFNIRS.data):-1:1
                if(isempty(ExFNIRS.data{i}))
                    ExFNIRS.data(i)=[];
                    continue;
                end
                if(isfield(ExFNIRS.data{i},'time'))
                   fsArray(i)=median(diff(ExFNIRS.data{i}.time));
                end
            
                if( ExFNIRS.settings.timeShiftTo0)
                    ExFNIRS.data{i}=processFNIRS2.Data.SetT0(ExFNIRS.data{i},min(ExFNIRS.data{i}.time));
                end
            
                if(isfield(ExFNIRS.data{i},'info'))
                    ExFNIRS.data{i}.info.rowID=i;
                end
            end
            
            estimatedFS=nanmedian(fsArray(:));
            
            subIdAuto=1;
            
            for i=1:length(ExFNIRS.data)
               if((~isfield(ExFNIRS.data{i},'raw')&&~isfield(ExFNIRS.data{i},'HbO'))||(length(ExFNIRS.data{i}.time)==1&&(isnan(ExFNIRS.data{i}.time)))||sum(sum(~isnan(ExFNIRS.data{i}.raw(:,2:end)),1),2)==0) %info only
                   ExFNIRS.data{i}.time=nan;
                   ExFNIRS.data{i}.info.missingFNIRS=1;
               else
                   ExFNIRS.data{i}.info.missingFNIRS=0;
            
                   if(~isfield(ExFNIRS.data{i}.info,'Group')||isempty(ExFNIRS.data{i}.info.Group))
                       ExFNIRS.data{i}.info.Group='Missing';
                   end
            
                   if(~isfield(ExFNIRS.data{i}.info,'SubjectID')||isempty(ExFNIRS.data{i}.info.SubjectID))
                       ExFNIRS.data{i}.info.SubjectID=sprintf('Missing%i',subIdAuto);
                       subIdAuto=subIdAutp+1;
                   end
            
                   if(~isfield(ExFNIRS.data{i}.info,'Subgroup')||isempty(ExFNIRS.data{i}.info.Subgroup))
                       ExFNIRS.data{i}.info.Subgroup='Missing';
                   end
            
                   if(~isfield(ExFNIRS.data{i}.info,'Session')||isempty(ExFNIRS.data{i}.info.Session))
                       ExFNIRS.data{i}.info.Session='Missing';
                   end
            
                   if(~isfield(ExFNIRS.data{i}.info,'Trial')||isempty(ExFNIRS.data{i}.info.Trial))
                       ExFNIRS.data{i}.info.Trial='Missing';
                   end
            
                   if(~isfield(ExFNIRS.data{i}.info,'Block')||isempty(ExFNIRS.data{i}.info.Block))
                       ExFNIRS.data{i}.info.Block='Missing';
                   end
            
                   if(~isfield(ExFNIRS.data{i}.info,'Condition')||isempty(ExFNIRS.data{i}.info.Condition))
                       ExFNIRS.data{i}.info.Condition='Missing';
                   end
               end
            end
            
            ExFNIRS.dataTable=BuildSegmentInfoTable(app, ExFNIRS.data);
            
            
            ExFNIRS.settings.updateOnChange=get(app.checkbox_auto_update,'Value');
            
            
            set(app.popupmenu_info_field,'Items',ExFNIRS.dataTable.Properties.VariableNames);
            setDropDownIndex(app.popupmenu_info_field,length(ExFNIRS.dataTable.Properties.VariableNames));
            %set(app.popupmenu_info_field,'Value',ExFNIRS.dataTable.Properties.VariableNames{});
            strs=get(app.popupmenu_info_field,'Items');
            val=get(app.popupmenu_info_field,'Value');
            selStr=strs{val};
            ExFNIRS.settings.curInfoStr=selStr;
            
            set(app.popupmenu_groupby_info_field,'Items',ExFNIRS.dataTable.Properties.VariableNames);
            setDropDownIndex(app.popupmenu_groupby_info_field,'Value',1);
            strs=get(app.popupmenu_groupby_info_field,'Items');
            val=get(app.popupmenu_groupby_info_field,'Value');
            selStr=strs{val};
            ExFNIRS.settings.curInfoGroupBy=selStr;
            
            popupmenu_groupby_info_field_Callback(app,[]); %update fieldbox


            segInfoVars={'SubjectID','Group','Subgroup','Session','Trial','Block','Condition'};
            
            
            for v =1:length(segInfoVars)
               if(~ismember(segInfoVars{v},ExFNIRS.dataTable.Properties.VariableNames))
                    ExFNIRS.dataTable.(segInfoVars{v})=strings(size(ExFNIRS.dataTable,1),1);
                    ExFNIRS.dataTable.(segInfoVars{v})(:,1)='Missing';
               elseif(isnumeric(ExFNIRS.dataTable.(segInfoVars{v})(1)))
                   nIdx=isnan(ExFNIRS.dataTable.(segInfoVars{v}));
                   if(any(nIdx))
                      warning('Missing value to deal with');
                   end
                   %ExFNIRS.dataTable.(segInfoVars{v})(nIdx)=
               elseif(isstring(ExFNIRS.dataTable.(segInfoVars{v})(1)))
                   nIdx=strcmp(ExFNIRS.dataTable.(segInfoVars{v}),'');
                   ExFNIRS.dataTable.(segInfoVars{v})(nIdx)='Missing';
               end
            end
            
            
            %processFNIRS2('UseDeviceCFG','device_fNIR1200.cfg');
            processFNIRS2('blLength',0); %use global mean for import
            
            %ExFNIRS.settings=[];
            
            ExFNIRS.settings.plotby=[];
            
            ExFNIRS.settings.plotby.SubjectID=get(app.checkbox_subjectID_plotby,'Value');
            ExFNIRS.settings.plotby.Group=get(app.checkbox_group_plotby,'Value');
            ExFNIRS.settings.plotby.Session=get(app.checkbox_session_plotby,'Value');
            ExFNIRS.settings.plotby.Trial=get(app.checkbox_trial_plotby,'Value');
            ExFNIRS.settings.plotby.Condition=get(app.checkbox_condition_plotby,'Value');
            ExFNIRS.settings.plotby.Block=get(app.checkbox_block_plotby,'Value');
            ExFNIRS.settings.plotby.InfoGroupBy=get(app.checkbox_block_plotby,'Value');
            
            ExFNIRS.settings.plot_grandaverage_feature='Mean';
            ExFNIRS.settings.plot_grandaverage=get(app.checkbox_plot_grandaverage,'Value');
            ExFNIRS.settings.plot_individual=get(app.checkbox_plot_all_data,'Value');
            ExFNIRS.settings.plot_error=get(app.checkbox_plot_error,'Value');
            ExFNIRS.settings.plot_error_multiply=str2num(get(app.edit_error_multiplier,'String'));
            ExFNIRS.settings.plot_task_lines=get(app.checkbox_mark_task,'Value');
            idx=get(app.popupmenu_errorbar_style,'Value');
            strs=get(app.popupmenu_errorbar_style,'Items');
            ExFNIRS.settings.plot_error_style=strs{idx};
            idx=get(app.popupmenu_errorbar_feature,'Value');
            strs=get(app.popupmenu_errorbar_feature,'Items');
            ExFNIRS.settings.plot_error_feature=strs{idx};
            
            ExFNIRS.settings.plot_legend_mode=2; %1 none %2 last fig %3 all
            
            ExFNIRS.settings.grandavg_resample_size=estimatedFS;
            set(app.edit_grandavg_resample_size,'Value',sprintf('%.3f',ExFNIRS.settings.grandavg_resample_size));
            %ExFNIRS.settings.code_missing=get(app.checkbox_code_nan,'Value');
            
            ExFNIRS.settings.plot_bar_ga=get(app.checkbox_plot_barchart_ga,'Value');
            ExFNIRS.settings.plot_bar_all=get(app.checkbox_plot_barchart_all_points,'Value');
            ExFNIRS.settings.plot_bar_err_mult=str2double(get(app.edit_bar_error_multiplier,'String'));
            ExFNIRS.settings.plot_bar_err_feature='SEM';
            ExFNIRS.settings.plot_bar_err=get(app.checkbox_plot_barchart_error,'Value');
            ExFNIRS.settings.plot_bar_feature='Mean';
            
            ExFNIRS.settings.plot_scatter_err=get(app.checkbox_plot_scatter_error,'Value');
            ExFNIRS.settings.plot_scatter_err_mult=str2double(get(app.edit_scatter_error_multiplier,'String'));
            ExFNIRS.settings.plot_scatter_nonparametric=get(app.checkbox_plot_scatter_nonparametric,'Value');
            ExFNIRS.settings.plot_scatter_line=get(app.checkbox_plot_scatter_line,'Value');
            idx=get(app.popupmenu_scatter_error_feature,'Value');
            strs=get(app.popupmenu_scatter_error_feature,'Items');
            ExFNIRS.settings.plot_scatter_err_feature=strs{idx};
            ExFNIRS.settings.plot_scatter_extend=get(app.checkbox_plot_scatter_extend,'Value');
            idx=get(app.popupmenu_scatter_error_style,'Value');
            strs=get(app.popupmenu_scatter_error_style,'Items');
            ExFNIRS.settings.plot_scatter_error_style=strs{idx};
            ExFNIRS.settings.plot_scatter_flipxy=get(app.checkbox_plot_scatter_flipxy,'Value');
            
            ExFNIRS.settings.LME_enable=get(app.checkbox_LME_enable,'Value');
            ExFNIRS.settings.LME_all_interactions=get(app.checkbox_LME_all_interactions,'Value');
            ExFNIRS.settings.LME_info_covariate=get(app.checkbox_LME_info_covariate,'Value');
            
            ExFNIRS.settings.export_replace_missing_9999=get(app.checkbox_export_9999,'Value');
            
            ExFNIRS.settings.use_group=get(app.checkbox_use_group,'Value');
            if(ExFNIRS.settings.use_group)
                set(app.listbox_group,'Enable','on');
                set(app.listbox_subgroup,'Enable','off');
            else
                set(app.listbox_group,'Enable','off');
                set(app.listbox_subgroup,'Enable','on');
            end
            
            ExFNIRS.settings.ylim_manual=get(app.checkbox_ylim_manual,'Value');
            ExFNIRS.settings.ylim_fixed=get(app.checkbox_ylim_fixed,'Value');
            ExFNIRS.settings.ylim_manual_min=str2num(get(app.edit_ylim_min,'String'));
            ExFNIRS.settings.ylim_manual_max=str2num(get(app.edit_ylim_max,'String'));
            
            ExFNIRS.settings.guiColor=ones(10,3);
            ExFNIRS.settings.use_gui_color=get(app.checkbox_gui_colors,'Value');
            
            [exF_folder,name,ext] = fileparts(mfilename('fullpath'));
            loadGUIcolors(app, sprintf('%s/prefs/%s',exF_folder,'exploreFNIRS_defaultColors.csv'));
            
            idx=get(app.popupmenu_colors,'Value');
            strs=get(app.popupmenu_colors,'Items');
            ExFNIRS.settings.cmap=str2func(strs{idx});
            
            PopulateGUIfields(app, ExFNIRS.dataTable,handles);
            strsRaw=get(app.listbox_raw_methods,'Items');
            strsOxy=get(app.listbox_oxy_methods,'Items');
            
            ExFNIRS.processedData=cell(length(strsOxy)*length(strsRaw),3);
            ExFNIRS.numProcessed=0;
            
            if(isfield(ExFNIRS,'UpdateNeeded')&&ExFNIRS.UpdateNeeded==4)
                ExFNIRS.UpdateNeeded=3;
            end
            
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,3);
            end
        end

        % Value changed function: checkbox_LME_all_interactions
        function checkbox_LME_all_interactions_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_LME_all_interactions (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_LME_all_interactions
            
            global ExFNIRS
            ExFNIRS.settings.LME_all_interactions=get(app.checkbox_LME_all_interactions,'Value');
        end

        % Value changed function: checkbox_LME_enable
        function checkbox_LME_enable_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_LME_enable (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_LME_enable
            
            global ExFNIRS
            ExFNIRS.settings.LME_enable=get(app.checkbox_LME_enable,'Value');
        end

        % Value changed function: checkbox_LME_info_covariate
        function checkbox_LME_info_covariate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_LME_info_covariate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_LME_info_covariate
            
            global ExFNIRS
            ExFNIRS.settings.LME_info_covariate=get(app.checkbox_LME_info_covariate,'Value');
        end

        % Value changed function: checkbox_LME_use_intercept
        function checkbox_LME_use_intercept_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_LME_use_intercept (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_LME_use_intercept
            
            global ExFNIRS
            
            ExFNIRS.settings.LME_use_intercept=get(app.checkbox_LME_use_intercept,'Value');
        end

        % Value changed function: checkbox_auto_color
        function checkbox_auto_color_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_auto_color (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_auto_color
            global ExFNIRS
            ExFNIRS.settings.use_gui_color=~get(app.checkbox_auto_color,'Value');
            set(app.checkbox_gui_colors,'Value',ExFNIRS.settings.use_gui_color);
        end

        % Value changed function: checkbox_auto_update
        function checkbox_auto_update_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_auto_update (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_auto_update
            global ExFNIRS
            ExFNIRS.settings.updateOnChange=get(app.checkbox_auto_update,'Value');
            
            if(ExFNIRS.settings.updateOnChange&&ExFNIRS.UpdateNeeded)
                updateSelectedTable(app);
            end
        end

        % Value changed function: checkbox_block_plotby
        function checkbox_block_plotby_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_block_plotby (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_block_plotby
            global ExFNIRS
            ExFNIRS.settings.plotby.Block=get(app.checkbox_block_plotby,'Value');
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,true);
            end
        end

        % Value changed function: checkbox_condition_plotby
        function checkbox_condition_plotby_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_condition_plotby (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_condition_plotby
            global ExFNIRS
            ExFNIRS.settings.plotby.Condition=get(app.checkbox_condition_plotby,'Value');
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,true);
            end
        end

        % Value changed function: checkbox_discreteTime
        function checkbox_discreteTime_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_discreteTime (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_discreteTime
            global ExFNIRS
            ExFNIRS.settings.LME_use_discreteTime=get(app.checkbox_discreteTime,'Value');
        end

        % Value changed function: checkbox_export_9999
        function checkbox_export_9999_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_export_9999 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_export_9999
            
            global ExFNIRS
            ExFNIRS.settings.export_replace_missing_9999=get(app.checkbox_export_9999,'Value');
        end

        % Value changed function: checkbox_group_plotby
        function checkbox_group_plotby_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_group_plotby (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_group_plotby
            global ExFNIRS
            ExFNIRS.settings.plotby.Group=get(app.checkbox_group_plotby,'Value');
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,true);
            end
        end

        % Value changed function: checkbox_gui_colors
        function checkbox_gui_colors_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_gui_colors (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_gui_colors
            global ExFNIRS
            ExFNIRS.settings.use_gui_color=get(app.checkbox_gui_colors,'Value');
            set(app.checkbox_auto_color,'Value',~ExFNIRS.settings.use_gui_color);
        end

        % Value changed function: checkbox_info_groupby_plotby
        function checkbox_info_groupby_plotby_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_info_groupby_plotby (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_info_groupby_plotby
            global ExFNIRS
            ExFNIRS.settings.plotby.InfoGroupBy=get(app.checkbox_info_groupby_plotby,'Value');
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app, true);
            end
        end

        % Value changed function: checkbox_lme_usecustom
        function checkbox_lme_usecustom_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_lme_usecustom (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_lme_usecustom
            
            global ExFNIRS
            
            ExFNIRS.settings.LME_use_customStr=get(app.checkbox_lme_usecustom,'Value');
            
            if(ExFNIRS.settings.LME_use_customStr&&isempty(ExFNIRS.settings.LME_customStr))
                pushbutton_custom_lme_Callback(app,[]);
            else
            
            end
        end

        % Value changed function: checkbox_mark_task
        function checkbox_mark_task_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_mark_task (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_mark_task
            global ExFNIRS
            ExFNIRS.settings.plot_task_lines=get(app.checkbox_mark_task,'Value');
        end

        % Value changed function: checkbox_plot_all_data
        function checkbox_plot_all_data_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_plot_all_data (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_plot_all_data
            global ExFNIRS
            ExFNIRS.settings.plot_individual=get(app.checkbox_plot_all_data,'Value');
        end

        % Value changed function: checkbox_plot_barchart_all_points
        function checkbox_plot_barchart_all_points_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_plot_barchart_all_points (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_plot_barchart_all_points
            
            global ExFNIRS
            ExFNIRS.settings.plot_bar_all=get(app.checkbox_plot_barchart_all_points,'Value');
        end

        % Value changed function: checkbox_plot_barchart_error
        function checkbox_plot_barchart_error_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_plot_barchart_error (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_plot_barchart_error
            
            global ExFNIRS
            ExFNIRS.settings.plot_bar_err=get(app.checkbox_plot_barchart_error,'Value');
        end

        % Value changed function: checkbox_plot_barchart_ga
        function checkbox_plot_barchart_ga_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_plot_barchart_ga (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_plot_barchart_ga
            global ExFNIRS
            ExFNIRS.settings.plot_bar_ga=get(app.checkbox_plot_barchart_ga,'Value');
        end

        % Value changed function: checkbox_plot_error
        function checkbox_plot_error_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_plot_error (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_plot_error
            global ExFNIRS
            ExFNIRS.settings.plot_error=get(app.checkbox_plot_error,'Value');
        end

        % Value changed function: checkbox_plot_grandaverage
        function checkbox_plot_grandaverage_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_plot_grandaverage (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_plot_grandaverage
            global ExFNIRS
            ExFNIRS.settings.plot_grandaverage=get(app.checkbox_plot_grandaverage,'Value');
        end

        % Value changed function: checkbox_plot_scatter_error
        function checkbox_plot_scatter_error_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_plot_scatter_error (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_plot_scatter_error
            
            global ExFNIRS
            ExFNIRS.settings.plot_scatter_err=get(app.checkbox_plot_scatter_error,'Value');
        end

        % Value changed function: checkbox_plot_scatter_extend
        function checkbox_plot_scatter_extend_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_plot_scatter_extend (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_plot_scatter_extend
            global ExFNIRS
            ExFNIRS.settings.plot_scatter_extend=get(app.checkbox_plot_scatter_extend,'Value');
        end

        % Value changed function: checkbox_plot_scatter_flipxy
        function checkbox_plot_scatter_flipxy_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_plot_scatter_flipxy (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_plot_scatter_flipxy
            global ExFNIRS
            ExFNIRS.settings.plot_scatter_flipxy=get(app.checkbox_plot_scatter_flipxy,'Value');
        end

        % Value changed function: checkbox_plot_scatter_line
        function checkbox_plot_scatter_line_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_plot_scatter_line (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_plot_scatter_line
            
            global ExFNIRS
            ExFNIRS.settings.plot_scatter_line=get(app.checkbox_plot_scatter_line,'Value');
        end

        % Value changed function: 
        % checkbox_plot_scatter_nonparametric
        function checkbox_plot_scatter_nonparametric_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_plot_scatter_nonparametric (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_plot_scatter_nonparametric
            
            global ExFNIRS
            ExFNIRS.settings.plot_scatter_nonparametric=get(app.checkbox_plot_scatter_nonparametric,'Value');
        end

        % Value changed function: checkbox_process_raw
        function checkbox_process_raw_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_process_raw (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_process_raw
            
            global ExFNIRS
            
            ExFNIRS.settings.processRaw=get(app.checkbox_process_raw,'Value');
            
            if(ExFNIRS.settings.processRaw)
                set(app.listbox_raw_methods,'Enable','on');
            else
                set(app.listbox_raw_methods,'Enable','off');
                set(app.listbox_raw_methods,'Value',1);
            end
            
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,3);
            end
        end

        % Value changed function: checkbox_session_plotby
        function checkbox_session_plotby_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_session_plotby (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_session_plotby
            global ExFNIRS
            ExFNIRS.settings.plotby.Session=get(app.checkbox_session_plotby,'Value');
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,true);
            end
        end

        % Value changed function: checkbox_subjectID_plotby
        function checkbox_subjectID_plotby_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_subjectID_plotby (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_subjectID_plotby
            global ExFNIRS
            ExFNIRS.settings.plotby.SubjectID=get(app.checkbox_subjectID_plotby,'Value');
            
            if(isfield(ExFNIRS.settings,'updateOnChange')&&ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                ExFNIRS.settings.updateOnChange=false;
                flagForUpdate(app,true);
            end
        end

        % Value changed function: checkbox_trial_plotby
        function checkbox_trial_plotby_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_trial_plotby (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_trial_plotby
            global ExFNIRS
            ExFNIRS.settings.plotby.Trial=get(app.checkbox_trial_plotby,'Value');
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,true);
            end
        end

        % Value changed function: checkbox_use_group
        function checkbox_use_group_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_use_group (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_use_group
            global ExFNIRS
            ExFNIRS.settings.use_group=get(app.checkbox_use_group,'Value');
            set(app.checkbox_use_subgroup,'Value',~ExFNIRS.settings.use_group);
            if(ExFNIRS.settings.use_group)
                set(app.listbox_group,'Enable','on');
                set(app.listbox_subgroup,'Enable','off');
            else
                set(app.listbox_group,'Enable','off');
                set(app.listbox_subgroup,'Enable','on');
            end
            
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,true);
            end
        end

        % Value changed function: checkbox_use_subgroup
        function checkbox_use_subgroup_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_use_subgroup (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_use_subgroup
            
            global ExFNIRS
            ExFNIRS.settings.use_group=~get(app.checkbox_use_subgroup,'Value');
            set(app.checkbox_use_group,'Value',ExFNIRS.settings.use_group);
            
            if(ExFNIRS.settings.use_group)
                set(app.listbox_group,'Enable','on');
                set(app.listbox_subgroup,'Enable','off');
            else
                set(app.listbox_group,'Enable','off');
                set(app.listbox_subgroup,'Enable','on');
            end
            
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,true);
            end
        end

        % Value changed function: checkbox_usebaseline
        function checkbox_usebaseline_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_usebaseline (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_usebaseline
            
            
            global ExFNIRS
            
            ExFNIRS.settings.use_baseline=get(app.checkbox_usebaseline,'Value');
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,2);
            end
            
            if(ExFNIRS.settings.use_baseline)
               set(app.edit_baseline_start,'Enable','on');
               set(app.edit_baseline_end,'Enable','on');
            else
                set(app.edit_baseline_start,'Enable','off');
                set(app.edit_baseline_end,'Enable','off');
            end
        end

        % Value changed function: checkbox_yaxis
        function checkbox_yaxis_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_yaxis (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_yaxis
            
            global ExFNIRS
            
            ExFNIRS.settings.plot_temporal_y0=get(app.checkbox_yaxis,'Value');
        end

        % Value changed function: checkbox_ylim_fixed
        function checkbox_ylim_fixed_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_ylim_fixed (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_ylim_fixed
            global ExFNIRS
            
            ExFNIRS.settings.ylim_fixed=get(app.checkbox_ylim_fixed,'Value');
            
            if(ExFNIRS.settings.ylim_fixed)
               set(app.checkbox_ylim_manual,'Value',0);
               ExFNIRS.settings.ylim_manual=false;
            end
        end

        % Value changed function: checkbox_ylim_manual
        function checkbox_ylim_manual_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_ylim_manual (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_ylim_manual
            
            global ExFNIRS
            
            ExFNIRS.settings.ylim_manual=get(app.checkbox_ylim_manual,'Value');
            
            if(ExFNIRS.settings.ylim_manual)
               set(app.checkbox_ylim_fixed,'Value',0);
               ExFNIRS.settings.ylim_fixed=false;
            end
        end

        % Value changed function: edit_bar_error_multiplier
        function edit_bar_error_multiplier_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_bar_error_multiplier (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_bar_error_multiplier as text
            %        str2double(get(hObject,'String')) returns contents of edit_bar_error_multiplier as a double
            global ExFNIRS
            ExFNIRS.settings.plot_bar_err_mult=str2double(get(app.edit_bar_error_multiplier,'String'));
            set(app.edit_bar_error_multiplier,'Value',sprintf('%.1f',ExFNIRS.settings.plot_bar_err_mult));
        end

        % Value changed function: edit_barchart_resample_size
        function edit_barchart_resample_size_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_barchart_resample_size (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_barchart_resample_size as text
            %        str2double(get(hObject,'String')) returns contents of edit_barchart_resample_size as a double
            
            global ExFNIRS
            ExFNIRS.settings.barchart_resample_size=str2num(get(app.edit_barchart_resample_size,'String'));
            set(app.edit_barchart_resample_size,'Value',sprintf('%.2f',ExFNIRS.settings.barchart_resample_size));
            
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,2);
            end
        end

        % Value changed function: edit_baseline_end
        function edit_baseline_end_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_baseline_end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_baseline_end as text
            %        str2double(get(hObject,'String')) returns contents of edit_baseline_end as a double
            global ExFNIRS
            ExFNIRS.settings.baseline_end=str2num(get(app.edit_baseline_end,'String'));
            set(app.edit_baseline_end,'Value',sprintf('%.2f',ExFNIRS.settings.baseline_end));
            
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,2);
            end
        end

        % Value changed function: edit_baseline_start
        function edit_baseline_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_baseline_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_baseline_start as text
            %        str2double(get(hObject,'String')) returns contents of edit_baseline_start as a double
            
            global ExFNIRS
            ExFNIRS.settings.baseline_start=str2num(get(app.edit_baseline_start,'String'));
            set(app.edit_baseline_start,'Value',sprintf('%.2f',ExFNIRS.settings.baseline_start));
            
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,2);
            end
        end

        % Value changed function: edit_block_end
        function edit_block_end_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_block_end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_block_end as text
            %        str2double(get(hObject,'String')) returns contents of edit_block_end as a double
            
            global ExFNIRS
            ExFNIRS.settings.block_end=str2num(get(app.edit_block_end,'String'));
            set(app.edit_block_end,'Value',sprintf('%.2f',ExFNIRS.settings.block_end));
        end

        % Value changed function: edit_block_start
        function edit_block_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_block_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_block_start as text
            %        str2double(get(hObject,'String')) returns contents of edit_block_start as a double
            
            global ExFNIRS
            ExFNIRS.settings.block_start=str2num(get(app.edit_block_start,'String'));
            set(app.edit_block_start,'Value',sprintf('%.2f',ExFNIRS.settings.block_start));
        end

        % Value changed function: edit_error_multiplier
        function edit_error_multiplier_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_error_multiplier (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_error_multiplier as text
            %        str2double(get(hObject,'String')) returns contents of edit_error_multiplier as a double
            global ExFNIRS
            ExFNIRS.settings.plot_error_multiply=str2num(get(app.edit_error_multiplier,'String'));
            set(app.edit_error_multiplier,'Value',sprintf('%.1f',ExFNIRS.settings.plot_error_multiply));
        end

        % Value changed function: edit_grandavg_resample_size
        function edit_grandavg_resample_size_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_grandavg_resample_size (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_grandavg_resample_size as text
            %        str2double(get(hObject,'String')) returns contents of edit_grandavg_resample_size as a double
            global ExFNIRS
            ExFNIRS.settings.grandavg_resample_size=str2num(get(app.edit_grandavg_resample_size,'String'));
            set(app.edit_grandavg_resample_size,'Value',sprintf('%.3f',ExFNIRS.settings.grandavg_resample_size));
            
            strsRaw=get(app.listbox_raw_methods,'Items');
            strsOxy=get(app.listbox_oxy_methods,'Items');
            
            ExFNIRS.processedData=cell(length(strsOxy)*length(strsRaw),3);
            ExFNIRS.numProcessed=0;
            
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
               flagForUpdate(app,2);
            end
        end

        % Value changed function: edit_plot_end
        function edit_plot_end_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_plot_end (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_plot_end as text
            %        str2double(get(hObject,'String')) returns contents of edit_plot_end as a double
            
            global ExFNIRS
            ExFNIRS.settings.plot_end=str2num(get(app.edit_plot_end,'Value'));
            set(app.edit_plot_end,'Value',sprintf('%.2f',ExFNIRS.settings.plot_end));
        end

        % Value changed function: edit_plot_start
        function edit_plot_start_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_plot_start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_plot_start as text
            %        str2double(get(hObject,'String')) returns contents of edit_plot_start as a double
            
            global ExFNIRS
            ExFNIRS.settings.plot_start=str2num(get(app.edit_plot_start,'Value'));
            set(app.edit_plot_start,'Value',sprintf('%.2f',ExFNIRS.settings.plot_start));
        end

        % Value changed function: edit_scatter_error_multiplier
        function edit_scatter_error_multiplier_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_scatter_error_multiplier (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_scatter_error_multiplier as text
            %        str2double(get(hObject,'String')) returns contents of edit_scatter_error_multiplier as a double
            
            global ExFNIRS
            ExFNIRS.settings.plot_scatter_err_mult=str2double(get(app.edit_scatter_error_multiplier,'Value'));
            set(app.edit_scatter_error_multiplier,'Value',sprintf('%.1f',ExFNIRS.settings.plot_scatter_err_mult));
        end

        % Value changed function: edit_ylim_max
        function edit_ylim_max_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_ylim_max (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_ylim_max as text
            %        str2double(get(hObject,'String')) returns contents of edit_ylim_max as a double
            global ExFNIRS
            
            ExFNIRS.settings.ylim_manual_max=str2double(get(app.edit_ylim_max,'Value'));
            set(app.edit_ylim_max,'Value',sprintf('%.2f',ExFNIRS.settings.ylim_manual_max));
        end

        % Value changed function: edit_ylim_min
        function edit_ylim_min_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_ylim_min (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_ylim_min as text
            %        str2double(get(hObject,'String')) returns contents of edit_ylim_min as a double
            global ExFNIRS
            
            ExFNIRS.settings.ylim_manual_min=str2double(get(app.edit_ylim_min,'Value'));
            set(app.edit_ylim_min,'Value',sprintf('%.2f',ExFNIRS.settings.ylim_manual_min));
        end

        % Value changed function: listbox_block
        function listbox_block_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_block (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_block contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_block
            global ExFNIRS
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,true);
            end
        end

        % Value changed function: listbox_condition
        function listbox_condition_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_condition (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_condition contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_condition
            global ExFNIRS
            
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,true);
            end
        end

        % Value changed function: listbox_group
        function listbox_group_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_group (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_group contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_group
            global ExFNIRS
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,true);
            end
        end

        % Value changed function: listbox_hierarchy
        function listbox_hierarchy_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_hierarchy (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_hierarchy contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_hierarchy
            global ExFNIRS
            
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,true);
            end
        end

        % Value changed function: listbox_info_groupby
        function listbox_info_groupby_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_info_groupby (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_info_groupby contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_info_groupby
            
            global ExFNIRS
            
            
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,true);
            end
        end

        % Value changed function: listbox_oxy_methods
        function listbox_oxy_methods_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_oxy_methods (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_oxy_methods contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_oxy_methods
            global ExFNIRS
            
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,3);
            end
        end

        % Value changed function: listbox_raw_methods
        function listbox_raw_methods_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_raw_methods (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_raw_methods contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_raw_methods
            global ExFNIRS
            
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,3);
            end
        end

        % Value changed function: listbox_session
        function listbox_session_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_session (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_session contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_session
            global ExFNIRS
            
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,true);
            end
        end

        % Value changed function: listbox_subgroup
        function listbox_subgroup_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_subgroup (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_subgroup contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_subgroup
            
            
            global ExFNIRS
            
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,true);
            end
        end

        % Value changed function: listbox_subjectID
        function listbox_subjectID_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_subjectID (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_subjectID contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_subjectID
            
            global ExFNIRS
            
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,true);
            end
        end

        % Value changed function: listbox_trial
        function listbox_trial_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_trial (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_trial contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_trial
            global ExFNIRS
            
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,true);
            end
        end

        % Value changed function: popupmenu_ChannelMode
        function popupmenu_ChannelMode_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu_ChannelMode (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_ChannelMode contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_ChannelMode
            global ExFNIRS
            
            idx=get(app.popupmenu_ChannelMode,'Value');
            setExChannelMode(ExFNIRS.settings.ChannelModes{idx,2});
        end

        % Value changed function: popupmenu_bar_error_feature
        function popupmenu_bar_error_feature_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu_bar_error_feature (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_bar_error_feature contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_bar_error_feature
            global ExFNIRS
            idx=get(app.popupmenu_bar_error_feature,'Value');
            strs=get(app.popupmenu_bar_error_feature,'Items');
            ExFNIRS.settings.plot_bar_err_feature=strs{idx};
        end

        % Value changed function: popupmenu_bar_feature
        function popupmenu_bar_feature_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu_bar_feature (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_bar_feature contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_bar_feature
            
            global ExFNIRS
            idx=get(app.popupmenu_bar_feature,'Value');
            strs=get(app.popupmenu_bar_feature,'Items');
            ExFNIRS.settings.plot_bar_feature=strs{idx};
        end

        % Value changed function: popupmenu_colors
        function popupmenu_colors_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu_colors (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_colors contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_colors
            global ExFNIRS
            idx=get(app.popupmenu_colors,'Value');
            strs=get(app.popupmenu_colors,'Items');
            ExFNIRS.settings.cmap=str2func(strs{idx});
        end

        % Value changed function: popupmenu_errorbar_feature
        function popupmenu_errorbar_feature_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu_errorbar_feature (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_errorbar_feature contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_errorbar_feature
            
            global ExFNIRS
            idx=get(app.popupmenu_errorbar_feature,'Value');
            strs=get(app.popupmenu_errorbar_feature,'Items');
            ExFNIRS.settings.plot_error_feature=strs{idx};
        end

        % Value changed function: popupmenu_errorbar_style
        function popupmenu_errorbar_style_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu_errorbar_style (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_errorbar_style contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_errorbar_style
            
            global ExFNIRS
            
            idx=get(app.popupmenu_errorbar_style,'Value');
            strs=get(app.popupmenu_errorbar_style,'Items');
            ExFNIRS.settings.plot_error_style=strs{idx};
        end

        % Value changed function: popupmenu_grandaverage_feature
        function popupmenu_grandaverage_feature_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu_grandaverage_feature (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_grandaverage_feature contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_grandaverage_feature
            global ExFNIRS
            idx=get(app.popupmenu_grandaverage_feature,'Value');
            strs=get(app.popupmenu_grandaverage_feature,'Items');
            ExFNIRS.settings.plot_grandaverage_feature=strs{idx};
        end

        % Value changed function: popupmenu_groupby_info_field
        function popupmenu_groupby_info_field_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu_groupby_info_field (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_groupby_info_field contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_groupby_info_field
            
            global ExFNIRS
            
            strs=get(app.popupmenu_groupby_info_field,'Items');
            val=get(app.popupmenu_groupby_info_field,'Value');
            
            selStr=strs{val};
            
            ExFNIRS.settings.use_info=true;
            
            
            ExFNIRS.settings.curInfoGroupBy=selStr;
            infoVars=ExFNIRS.dataTable.Properties.VariableNames;
            
            set(app.popupmenu_groupby_info_field,'Items',infoVars);
            [a,b]=ismember(ExFNIRS.settings.curInfoGroupBy,infoVars);
            
            if(a==1)
                set(app.popupmenu_groupby_info_field,'Value',b);
            else
                set(app.popupmenu_groupby_info_field,'Value',1);
            end
            
            idx=get(app.popupmenu_groupby_info_field,'Value');
            ExFNIRS.settings.curInfoGroupBy=infoVars{idx};
            
            ExFNIRS.settings.curInfoGroupByNumeric=false;
            
            if(~isempty(ExFNIRS.settings.curInfoGroupBy))
                [uVars,~,ExFNIRS.settings.curInfoGroupByIdx]=unique(ExFNIRS.dataTable(:,ExFNIRS.settings.curInfoGroupBy));
            
                if(isnumeric(uVars{1,1}))
                   uVars=table2array(ExFNIRS.dataTable(:,ExFNIRS.settings.curInfoGroupBy));
                   uVars(isnan(uVars))=-9999;
                   %uVars=unique(uVars);
                   %uVars(uVars==-9999)=nan;
                   uVars=num2str(uVars,'%.2f');
                   ExFNIRS.settings.curInfoGroupByNumeric=true;
                   [uVars,~,ExFNIRS.settings.curInfoGroupByIdx]=unique(uVars,'rows');
                elseif(isstring(uVars{1,1}))
                   uVars=table2cell(uVars);
                end
                if(~iscell(uVars))
                    uVars=cellstr(uVars);
                end
            
                for i=1:length(uVars)
                   if(isempty(uVars{i,1})||(isstring(uVars{i})&&ismissing(uVars{i}))||(ischar(uVars{i})&&ismissing(uVars(i))))
                      uVars{i,1}='Missing';
                   end
                end
            
                nanIndex=strcmp('-9999.00',uVars);
            
            
                uVars(nanIndex)={'NaN'};
                set(app.listbox_info_groupby,'Items',uVars);
                set(app.listbox_info_groupby,'Value',1:length(uVars));


                segInfoVars={'Group','Subgroup','Session','Trial','Block','Condition',ExFNIRS.settings.curInfoGroupBy};
                randFxStr{1}='1|SubjectID';
                for i=2:2:length(segInfoVars)*2
                   randFxStr{i}=sprintf('%s|SubjectID',segInfoVars{(i)/2});
                   randFxStr{i+1}=sprintf('-1+%s|SubjectID',segInfoVars{(i)/2});
                end
            
                set(app.popupmenu_lmer_randomeffects,'Items',randFxStr);
                ExFNIRS.settings.LME_randomFxStrs=randFxStr;
            
            else
                set(app.listbox_info_groupby,'Items','');
            end
            
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app, true);
            end
        end

        % Value changed function: popupmenu_info_field
        function popupmenu_info_field_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu_info_field (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_info_field contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_info_field
            global ExFNIRS
            
            strs=get(app.popupmenu_info_field,'Items');
            val=get(app.popupmenu_info_field,'Value');
            
            selStr=strs{val};
            
            ExFNIRS.settings.curInfoStr=selStr;
        end

        % Value changed function: popupmenu_info_group
        function popupmenu_info_group_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu_info_group (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_info_group contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_info_group
            
            global ExFNIRS
            
            strs=get(app.popupmenu_info_group,'Items');
            val=get(app.popupmenu_info_group,'Value');
            
            selStr=strs{val};
            
            ExFNIRS.settings.curInfoGroup=selStr;
            
            updateInfoGroupByVars(app);
        end

        % Value changed function: popupmenu_legend_mode
        function popupmenu_legend_mode_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu_legend_mode (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_legend_mode contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_legend_mode
            global ExFNIRS
            ExFNIRS.settings.plot_legend_mode=get(app.popupmenu_legend_mode,'Value');
        end

        % Value changed function: popupmenu_lmer_randomeffects
        function popupmenu_lmer_randomeffects_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu_lmer_randomeffects (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_lmer_randomeffects contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_lmer_randomeffects
            global ExFNIRS
            
            ExFNIRS.settings.LME_randomFxStr=ExFNIRS.settings.LME_randomFxStrs{get(app.popupmenu_lmer_randomeffects,'Value')};
        end

        % Value changed function: popupmenu_scatter_error_feature
        function popupmenu_scatter_error_feature_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu_scatter_error_feature (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_scatter_error_feature contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_scatter_error_feature
            
            global ExFNIRS
            idx=get(app.popupmenu_scatter_error_feature,'Value');
            strs=get(app.popupmenu_scatter_error_feature,'Items');
            ExFNIRS.settings.plot_scatter_err_feature=strs{idx};
        end

        % Value changed function: popupmenu_scatter_error_style
        function popupmenu_scatter_error_style_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu_scatter_error_style (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_scatter_error_style contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_scatter_error_style
            
            global ExFNIRS
            
            idx=get(app.popupmenu_scatter_error_style,'Value');
            strs=get(app.popupmenu_scatter_error_style,'Items');
            ExFNIRS.settings.plot_scatter_error_style=strs{idx};
        end

        % Value changed function: popupmenu_topoSig
        function popupmenu_topoSig_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu_topoSig (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_topoSig contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_lmer_randomeffects
            global ExFNIRS
            
            switch(get(app.popupmenu_topoSig,'Value'))
                case 1 % p=0.1
                   ExFNIRS.settings.topoSigThrehold={'p',0.1};
                case 2 % p=0.05
                   ExFNIRS.settings.topoSigThrehold={'p',0.05};
                case 3
                   ExFNIRS.settings.topoSigThrehold={'p',0.01};
               case 4
                   ExFNIRS.settings.topoSigThrehold={'q',0.1};
               case 5
                   ExFNIRS.settings.topoSigThrehold={'q',0.05};
               case 6
                   ExFNIRS.settings.topoSigThrehold={'q-twostep',0.1};
               case 7
                   ExFNIRS.settings.topoSigThrehold={'q-twostep',0.05};
            end
        end

        % Value changed function: popupmenu_within_sub_avg
        function popupmenu_within_sub_avg_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu_within_sub_avg (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_within_sub_avg contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_within_sub_avg
            global ExFNIRS
            ExFNIRS.settings.within_sub_avg_mode=get(app.popupmenu_within_sub_avg,'Value');
            
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app, true,app);
            end
        end

        % Button pushed function: pushbutton_biomarker_select_all
        function pushbutton_biomarker_select_all_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_biomarker_select_all (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            strs=get(app.listbox_biomarker,'Items');
            if(iscell(strs)||ismatrix(strs))
                set(app.listbox_biomarker,'Value',[1:size(strs,1)]);
            end
        end

        % Button pushed function: pushbutton_biomarker_select_none
        function pushbutton_biomarker_select_none_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_biomarker_select_none (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            set(app.listbox_biomarker,'Value',[]);
        end

        % Button pushed function: pushbutton_block_select_all
        function pushbutton_block_select_all_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_block_select_all (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            strs=get(app.listbox_block,'Items');
            if(iscell(strs)||ismatrix(strs))
                set(app.listbox_block,'Value',[1:size(strs,1)]);
            end
            
            global ExFNIRS
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(true,handles);
            end
        end

        % Button pushed function: pushbutton_block_select_none
        function pushbutton_block_select_none_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_block_select_none (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            set(app.listbox_block,'Value',[]);
        end

        % Button pushed function: pushbutton_clear_processed
        function pushbutton_clear_processed_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_clear_processed (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global ExFNIRS
            strsRaw=get(app.listbox_raw_methods,'Items');
            strsOxy=get(app.listbox_oxy_methods,'Items');
            
            ExFNIRS.processedData=cell(length(strsOxy)*length(strsRaw),3);
            ExFNIRS.numProcessed=0;
            
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(apps);
            else
                flagForUpdate(app,3);
            end
        end

        % Button pushed function: pushbutton_condition_select_all
        function pushbutton_condition_select_all_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_condition_select_all (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            strs=get(app.listbox_condition,'Items');
            if(iscell(strs)||ismatrix(strs))
                set(app.listbox_condition,'Value',[1:size(strs,1)]);
            end
            
            global ExFNIRS
            
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,true);
            end
        end

        % Button pushed function: pushbutton_condition_select_none
        function pushbutton_condition_select_none_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_condition_select_none (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(app.listbox_condition,'Value',[]);
        end

        % Button pushed function: pushbutton_custom_lme
        function pushbutton_custom_lme_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_custom_lme (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global ExFNIRS
            
            prompt = {'Please enter custom LME string'};
            dlgtitle = 'Define custom LME model terms';
            dims = [1 100];
            answer = inputdlg(prompt,dlgtitle,dims,{ExFNIRS.settings.LME_customStr});
            
            if(~isempty(answer))
                ExFNIRS.settings.LME_customStr=answer{1};
                set(app.checkbox_lme_usecustom,'Value',1);
                ExFNIRS.settings.LME_use_customStr=true;
                set(app.pushbutton_custom_lme,'TooltipString',answer{1});
            
            else
            
            end
        end

        % Button pushed function: pushbutton_exLoad
        function pushbutton_exLoad_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_exLoad (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            exploreFNIRS.LoadEx();
        end

        % Button pushed function: pushbutton_exSave
        function pushbutton_exSave_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_exSave (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            exploreFNIRS.SaveEx();
        end

        % Button pushed function: pushbutton_export_csv
        function pushbutton_export_csv_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_export_csv (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global ExFNIRS
            
            if(ExFNIRS.UpdateNeeded)
                updateSelectedTable(app);
            end
            
            if(isempty(ExFNIRS.selectedTable)||isempty(ExFNIRS.gby))
                return;
            end
            
            answerFormat = questdlg(sprintf('Select Export Table Format:\n\nWide Format: 1 row per segment, 1 column per biomarkerXchannelXtimepoint\nLong Format 1 row per timepointXsegment, 1 column per channelXbiomarker\n'), ...
            	'Table Format', ...
            	'Wide Format','Long Format','Cancel','Wide Format');
            
            if strcmp(answerFormat,'Cancel')
                return
            end
            
            curTimes=[];
            for i=1:length(ExFNIRS.gby)
                curTimes=[curTimes;ExFNIRS.gby(i).gbyGrandBar.segmentTimes];
            end
            curTimes=unique(curTimes,'row');
            
            fprintf('Current Block Times:')
            cTimesTable=array2table(curTimes);
            cTimesTable.Properties.VariableNames={'Start','MidPoint','End'};
            display(cTimesTable);


            fprintf('Current Viewing Window %.1f to %.1fs\n',ExFNIRS.settings.plot_start,ExFNIRS.settings.plot_end);
            fprintf('Current Task Period %.1f:s to %.1fs\n',ExFNIRS.settings.block_start,ExFNIRS.settings.block_end);
            
            
            answerTime = questdlg(sprintf('Choose times to export:\n\nCurrent sampling size is: %.1fs\n\nCurrent Viewing Window: %.1f to %.1fs\nCurrent Task Period %.1f:s to %.1fs\n\nNote: Incomplete bins will not be exported',ExFNIRS.settings.barchart_resample_size,ExFNIRS.settings.plot_start,ExFNIRS.settings.plot_end,ExFNIRS.settings.block_start,ExFNIRS.settings.block_end), ...
            	'Export Time Selection', ...
            	'All Times','Viewing Window','Baseline to Task','Viewing Window');%'All Times','Viewing Window','Baseline to Task','Task Only','Cancel','Baseline to Task');
            
            switch(answerTime)
                case 'All Times'
                    t_start=min(curTimes(:,1));
                    t_end=max(curTimes(:,3));
                case 'Viewing Window'
                    t_start=ExFNIRS.settings.plot_start;
                    t_end=ExFNIRS.settings.plot_end;
                case 'Baseline to Task'
                    t_start=min(ExFNIRS.settings.baseline_start,ExFNIRS.settings.block_start);
                    t_end=max(ExFNIRS.settings.baseline_end,ExFNIRS.settings.block_end);
                case 'Task Only'
                    t_start=ExFNIRS.settings.block_start;
                    t_end=ExFNIRS.settings.block_end;
            end
            
            startIdx=curTimes(:,1)>=t_start;
            endIdx=curTimes(:,3)<=t_end;
            curTimes=curTimes(startIdx&endIdx,:);
            
            fprintf('Exporting Times:')
            cTimesTable=cTimesTable(startIdx&endIdx,:);
            display(cTimesTable);


            % Handle response
            switch answerFormat
                case 'Wide Format'
                    exportWideTable(app, curTimes(:,1));
                case 'Long Format'
                    exportLongTable(app, curTimes(:,1));
                case 'Cancel'
            
            end
        end

        % Button pushed function: pushbutton_group_select_all
        function pushbutton_group_select_all_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_group_select_all (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global ExFNIRS
            
            if(ExFNIRS.settings.use_group)
                strs=get(app.listbox_group,'Items');
                if(iscell(strs)||ismatrix(strs))
                    set(app.listbox_group,'Value',[1:size(strs,1)]);
                end
            else
                strs=get(app.listbox_subgroup,'Items');
                if(iscell(strs)||ismatrix(strs))
                    set(app.listbox_subgroup,'Value',[1:size(strs,1)]);
                end
            end


            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,true);
            end
        end

        % Button pushed function: pushbutton_group_select_none
        function pushbutton_group_select_none_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_group_select_none (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global ExFNIRS
            if(ExFNIRS.settings.use_group)
                set(app.listbox_group,'Value',[]);
            else
                set(app.listbox_subgroup,'Value',[]);
            end
        end

        % Button pushed function: pushbutton_gui_color_10
        function pushbutton_gui_color_10_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_gui_color_10 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global ExFNIRS
            
            ExFNIRS.settings.guiColor(10,:)=uisetcolor(ExFNIRS.settings.guiColor(10,:));
            set(app.pushbutton_gui_color_10,'ForegroundColor',ExFNIRS.settings.guiColor(10,:))
        end

        % Button pushed function: pushbutton_gui_color_1
        function pushbutton_gui_color_1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_gui_color_1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global ExFNIRS
            
            ExFNIRS.settings.guiColor(1,:)=uisetcolor(ExFNIRS.settings.guiColor(1,:));
            set(app.pushbutton_gui_color_1,'ForegroundColor',ExFNIRS.settings.guiColor(1,:))
        end

        % Button pushed function: pushbutton_gui_color_2
        function pushbutton_gui_color_2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_gui_color_2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global ExFNIRS
            
            ExFNIRS.settings.guiColor(2,:)=uisetcolor(ExFNIRS.settings.guiColor(2,:));
            set(app.pushbutton_gui_color_2,'ForegroundColor',ExFNIRS.settings.guiColor(2,:))
        end

        % Button pushed function: pushbutton_gui_color_3
        function pushbutton_gui_color_3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_gui_color_3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global ExFNIRS
            
            ExFNIRS.settings.guiColor(3,:)=uisetcolor(ExFNIRS.settings.guiColor(3,:));
            set(app.pushbutton_gui_color_3,'ForegroundColor',ExFNIRS.settings.guiColor(3,:))
        end

        % Button pushed function: pushbutton_gui_color_4
        function pushbutton_gui_color_4_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_gui_color_4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global ExFNIRS
            
            ExFNIRS.settings.guiColor(4,:)=uisetcolor(ExFNIRS.settings.guiColor(4,:));
            set(app.pushbutton_gui_color_4,'ForegroundColor',ExFNIRS.settings.guiColor(4,:))
        end

        % Button pushed function: pushbutton_gui_color_5
        function pushbutton_gui_color_5_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_gui_color_5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global ExFNIRS
            
            ExFNIRS.settings.guiColor(5,:)=uisetcolor(ExFNIRS.settings.guiColor(5,:));
            set(app.pushbutton_gui_color_5,'ForegroundColor',ExFNIRS.settings.guiColor(5,:))
        end

        % Button pushed function: pushbutton_gui_color_6
        function pushbutton_gui_color_6_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_gui_color_6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global ExFNIRS
            
            ExFNIRS.settings.guiColor(6,:)=uisetcolor(ExFNIRS.settings.guiColor(6,:));
            set(app.pushbutton_gui_color_6,'ForegroundColor',ExFNIRS.settings.guiColor(6,:))
        end

        % Button pushed function: pushbutton_gui_color_7
        function pushbutton_gui_color_7_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_gui_color_7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global ExFNIRS
            
            ExFNIRS.settings.guiColor(7,:)=uisetcolor(ExFNIRS.settings.guiColor(7,:));
            set(app.pushbutton_gui_color_7,'ForegroundColor',ExFNIRS.settings.guiColor(7,:))
        end

        % Button pushed function: pushbutton_gui_color_8
        function pushbutton_gui_color_8_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_gui_color_8 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global ExFNIRS
            
            ExFNIRS.settings.guiColor(8,:)=uisetcolor(ExFNIRS.settings.guiColor(8,:));
            set(app.pushbutton_gui_color_8,'ForegroundColor',ExFNIRS.settings.guiColor(8,:))
        end

        % Button pushed function: pushbutton_gui_color_9
        function pushbutton_gui_color_9_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_gui_color_9 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global ExFNIRS
            
            ExFNIRS.settings.guiColor(9,:)=uisetcolor(ExFNIRS.settings.guiColor(9,:));
            set(app.pushbutton_gui_color_9,'ForegroundColor',ExFNIRS.settings.guiColor(9,:))
        end

        % Button pushed function: pushbutton_gui_color_load
        function pushbutton_gui_color_load_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_gui_color_load (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            [file, pathname] = uigetfile({'*.csv';'*.*'},'Import colors list');
            if(isempty(file)||~ischar(file)||(isnumeric(file)&&file==0))
                return
            end
            
            loadGUIcolors(app,sprintf('%s/%s',pathname,file));
        end

        % Button pushed function: pushbutton_gui_color_move_down
        function pushbutton_gui_color_move_down_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_gui_color_move_down (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global ExFNIRS
            
            colorStr=get(app.listbox_color_order,'Items');
            colorVal=get(app.listbox_color_order,'Value');
            
            if(isempty(colorVal))
                set(app.listbox_color_order,'Value',1);
                colorVal=1;
            end
            
            numClrStr=length(colorStr);
            if(colorVal==length(colorStr))
                return;
            else
                newInd=[(1:colorVal-1),(colorVal+1),colorVal,(colorVal+2):numClrStr];
                newInd(newInd>numClrStr)=[];
                ExFNIRS.settings.guiColor=ExFNIRS.settings.guiColor(newInd,:);
                set(app.listbox_color_order,'Items',colorStr(newInd));
                set(app.listbox_color_order,'Value',colorVal+1);
            end
            
            updateButtonListColors(app);
        end

        % Button pushed function: pushbutton_gui_color_move_up
        function pushbutton_gui_color_move_up_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_gui_color_move_up (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global ExFNIRS
            
            colorStr=get(app.listbox_color_order,'Items');
            colorVal=get(app.listbox_color_order,'Value');
            
            if(isempty(colorVal))
                set(app.listbox_color_order,'Value',1);
                colorVal=1;
            end
            
            numClrStr=length(colorStr);
            if(colorVal==1)
                return;
            else
                newInd=[1:colorVal-2,colorVal,colorVal-1,(colorVal+1):numClrStr];
                newInd(newInd>numClrStr)=[];
                ExFNIRS.settings.guiColor=ExFNIRS.settings.guiColor(newInd,:);
                set(app.listbox_color_order,'Items',colorStr(newInd));
                set(app.listbox_color_order,'Value',colorVal-1);
            end
            
            updateButtonListColors(app);
        end

        % Button pushed function: pushbutton_gui_color_save
        function pushbutton_gui_color_save_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_gui_color_save (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            [file, pathname] = uiputfile({'*.csv';'*.*'},'Export colors list');
            if(isempty(file)||~ischar(file)||(isnumeric(file)&&file==0))
                return
            end
            global ExFNIRS
            csvwrite(sprintf('%s/%s',pathname,file),ExFNIRS.settings.guiColor);
        end

        % Button pushed function: pushbutton_import_oxy
        function pushbutton_import_oxy_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_import_oxy (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            processFNIRS2('ImportRawMethods','');
            
            global ExFNIRS
            
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,3);
            end
        end

        % Button pushed function: pushbutton_import_raw
        function pushbutton_import_raw_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_import_raw (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            processFNIRS2('ImportOxyMethods','');
            
            global ExFNIRS
            
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,3);
            end
        end

        % Button pushed function: pushbutton_infoData_barchart
        function pushbutton_infoData_barchart_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_infoData_barchart (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            global ExFNIRS
            
            if(ExFNIRS.UpdateNeeded)
               updateSelectedTable(app);
            end
            
            if(~isfield(ExFNIRS,'gby'))
                warning('No groups match selection criteria');
                return;
            end
            
            curInfoGroup=ExFNIRS.settings.curInfoGroup;
            
            gbyVars=ExFNIRS.groupByVars;
            if(~isempty(curInfoGroup)&&~strcmp(curInfoGroup,'(Time)'))
                [ismem,idx]=ismember(curInfoGroup,gbyVars);
                if(ismem)
                    gbyVars(idx)=[];
                    useCurInfoGroup=true;
                else
                    useCurInfoGroup=false;
                end
            else
            
                useCurInfoGroup=false;
            end


            numGroups=length(ExFNIRS.gby);
            
            if(numGroups==0)
                return;
            end
            
            ExFNIRS.figHandles.main=figure(1100);
            clf(ExFNIRS.figHandles.main)
            cla(ExFNIRS.figHandles.main);
            dcm_obj = datacursormode(ExFNIRS.figHandles.main);
            set(dcm_obj,'UpdateFcn',@app.myDataTipUpdateFcn);
            % end
            
            num2Plot=numGroups;
            
            
            gbyStrs=cell(numGroups,1);
            curInfoGby=cell(0);
            subplotGby=[];
            gbyIdx=nan(numGroups,1);
            
            for g=1:numGroups
                gbyStrs{g}='';
               if(~isempty(ExFNIRS.gby(g).gbyTables))
                   for i=1:length(gbyVars)
                       gbyStrs{g}=sprintf('%s%s:%s,',gbyStrs{g},gbyVars{i},num2strOrNot(app, ExFNIRS.gby(g).gbyTables.(gbyVars{i})(1)));
                   end
                   if(useCurInfoGroup)
                       curInfoGby{g}=num2strOrNot(app, ExFNIRS.gby(g).gbyTables.(curInfoGroup)(1));
                   end
               end
               if(~isempty(gbyStrs{g}))
                gbyStrs{g}(end)='';
               end
            end
            
            [uGbyStrs,~,uGroupIdx]=unique(cellstr(gbyStrs));
            numUgroups=length(uGbyStrs);
            
            
            if(ExFNIRS.settings.use_gui_color)
                cIndex=ExFNIRS.settings.guiColor(1:numUgroups,:);
            else
                cIndex=ExFNIRS.settings.cmap(numUgroups);%linspecer(num2Plot,'qualitative');
            end


            if(useCurInfoGroup)
                [uCurInfoG,a,uCurIdx]=unique(cellstr(curInfoGby));
                numCurInfoG=length(uCurInfoG);
                barChartData=nan(max(uCurIdx),numUgroups,3);
            else
                barChartData=nan(1,numUgroups,3);
                uCurInfoG='';
                numCurInfoG=1;
            end
            
            errorFeature=ExFNIRS.settings.plot_bar_err_feature;
            plotFeature=ExFNIRS.settings.plot_bar_feature;
            
            gAStrs=cell(numUgroups,1);
            gAerrStrs=cell(numUgroups,1);
            
            
            curInfoStr=ExFNIRS.settings.curInfoStr;
            
            if(ExFNIRS.settings.within_sub_avg_mode==3)
                dataH=ExFNIRS.dataHierarchy;
            elseif(ExFNIRS.settings.within_sub_avg_mode==2)
                dataH='SubjectID';
            else
                dataH=[];
            end
            
            barGroup=zeros(numCurInfoG,1);
            
            for g=1:numGroups
                curTable=ExFNIRS.gby(g).gbyTables;
                curData=curTable(:,curInfoStr);
                subplotGby.gby(g)=ExFNIRS.gby(g);
            
                if(useCurInfoGroup)
                    cBarSec=uCurIdx(g); % which section to put the bar in
                    curBarGroup=uGroupIdx(g);
                else
                   cBarSec=1;
                   curBarGroup=g;
                end
            
                curData=table2array(curData);
            
                if(isstring(curData))
                   warning('Strings return count');
                   [~,~,curData]=unique(curData);
                   plotFeature='Count';
                   % return;
                end
                curData(curData==-9999)=nan;
            
                %switch modes here
                if(strcmp(plotFeature,'Count'))
                    curHAvg=length(pf2_base.hierarchicalAverage(curData,curTable(:,dataH),@nanmean));
                elseif(strcmp(plotFeature,'Mean'))
                    curHAvg=nanmean(pf2_base.hierarchicalAverage(curData,curTable(:,dataH),@nanmean));
                elseif(strcmp(plotFeature,'Median'))
                    curHAvg=nanmedian(pf2_base.hierarchicalAverage(curData,curTable(:,dataH),@nanmedian));
                else
                    error('Unknown parameter');
                    %curHAvg=nanmedian(hierarchicalAverage(curData,curTable(:,dataH),@nanmedian));
                end


                if(ExFNIRS.settings.plot_bar_ga)
                      barChartData(cBarSec,curBarGroup,1)=curHAvg;
                      gAStrs{curBarGroup}=sprintf('%s',uGbyStrs{curBarGroup});
                end
            
                if(ExFNIRS.settings.plot_bar_err)
                    if(~strcmp(plotFeature,'Count'))
            
                      errMulitply=ExFNIRS.settings.plot_bar_err_mult;
            
                      gaFeat=curHAvg;
            
                      if(strcmp(errorFeature,'MaxMin'))
                          curHerr=nanmax(hierarchicalAverage(curData,curTable(:,dataH),@nanmax));
            
                          barChartData(cBarSec,curBarGroup,2)=curHerr;
                          curHerr=nanmin(pf2_base.hierarchicalAverage(curData,curTable(:,dataH),@nanmin));
                          barChartData(cBarSec,curBarGroup,3)=curHerr;
                      elseif(strcmp(errorFeature,'SD'))
                          curHerr=nanstd(pf2_base.hierarchicalAverage(curData,curTable(:,dataH),@nanmean));
                          barChartData(cBarSec,curBarGroup,2)=curHerr*errMulitply;
                      elseif(strcmp(errorFeature,'SEM'))
                          curHerr=nanstd(pf2_base.hierarchicalAverage(curData,curTable(:,dataH),@nanmean));
                          curN=length(pf2_base.hierarchicalAverage(curData,curTable(:,dataH),@nanmean));
                          curHerr=curHerr/sqrt(curN);
                          barChartData(cBarSec,curBarGroup,2)=curHerr*errMulitply;
                      end
            
                      gAErrStrs{curBarGroup}=sprintf('%s',gbyStrs{g});
                    else
                        curHerr=0;
                        barChartData(cBarSec,curBarGroup,2)=curHerr;
                        gAErrStrs{curBarGroup}='';
                    end
                end
            
            end


            if(isempty(barChartData))
                warning('All data is missing');
                return
            elseif(sum(~isnan(barChartData(:,:,1)))==0)
                warning('All data is missing');
                return
            end
            
            if(ExFNIRS.settings.plot_bar_err)
                pf2_base.external.barweb(barChartData(:,:,1),barChartData(:,:,2),1,uCurInfoG, [], [], [], cIndex,[],gAStrs,[],'hide');
                ylimLower=min(min(barChartData(:,:,1)))-max(max(barChartData(:,:,2)));
                ylimUpper=max(max(barChartData(:,:,1)))+max(max(barChartData(:,:,2)));
                yrange=ylimUpper-ylimLower;
                ylim([min(ylimLower-0.1*yrange,0),max(ylimUpper+0.1*yrange,0)]);
                ylabel(sprintf('%s %s   +/- (%s)',plotFeature,curInfoStr,errorFeature));
            
                if(useCurInfoGroup)
                   title(sprintf('%s by %s',curInfoStr,curInfoGroup));
                   xlabel(curInfoGroup);
                end
            else
                pf2_base.external.barweb(barChartData(:,:,1),[],1,uCurInfoG, [], [], [], cIndex,[],gAStrs,[],'hide');
                ylimLower=min(min(barChartData(:,:,1)));
                ylimUpper=max(max(barChartData(:,:,1)));
                yrange=ylimUpper-ylimLower;
                ylim([min(ylimLower-0.1*yrange,0),max(ylimUpper+0.1*yrange,0)]);
                ylabel(sprintf('%s %s   +/- (%s)',plotFeature,curInfoStr,errorFeature));
            
                if(useCurInfoGroup)
                   title(sprintf('%s by %s',curInfoStr,curInfoGroup));
                   xlabel(curInfoGroup);
                end
            end
            
            if(ExFNIRS.settings.plot_legend_mode==3||(ExFNIRS.settings.plot_legend_mode==2))
                legend(gAStrs(:),'Location', 'Best');
                legend boxoff;
            end
            
            fprintf('Info Table Values\n');
            for i=1:length(gAStrs(:))
                fprintf('%s\tMean %.2f\tError: %.2f\n',gAStrs{i},barChartData(1,i,1),barChartData(1,i,2));
            end
            
            hold off;
            
            if(ExFNIRS.settings.LME_enable)
                x=ExFNIRS.groupByVars;
                curLMEGbyString='';
            
                useAllInteractions=ExFNIRS.settings.LME_all_interactions;
            
                %mdlPrtString='Time';
            
                if(useAllInteractions)
            
                    for i=1:length(x)
                        curLMEGbyString=sprintf('%s*%s',curLMEGbyString,x{i});
                    end
                else
                    for i=1:length(x)
                         curLMEGbyString=sprintf('%s+%s',curLMEGbyString,x{i});
                    end
                end
            
                if(~isempty(curLMEGbyString))
                    curLMEGbyString(1)=[];
                end
            
                dummyCodeStr='reference';
                if(ExFNIRS.settings.LME_use_customStr&&~isempty(ExFNIRS.settings.LME_customStr))
                    lmeString=sprintf('%s~%s',ExFNIRS.settings.curInfoStr,ExFNIRS.settings.LME_customStr);
                    if(contains(lmeString,'-1+')||contains(lmeString,'~-1'))
                       dummyCodeStr='full';
                    end
                elseif(ExFNIRS.settings.LME_use_intercept)
                    lmeString=sprintf('%s~%s+(%s)',ExFNIRS.settings.curInfoStr,curLMEGbyString,ExFNIRS.settings.LME_randomFxStr);
            
                else
                   lmeString=sprintf('%s~-1+%s+(%s)',ExFNIRS.settings.curInfoStr,curLMEGbyString,ExFNIRS.settings.LME_randomFxStr);
                   dummyCodeStr='full';
                end
            
            
                try
                    rng(2019);
                    curInfoChartLME=fitlme(ExFNIRS.selectedTable,lmeString,'FitMethod','REML','CheckHessian',true,'DummyVarCoding',dummyCodeStr);
            
                    nullMdlstring=sprintf('%s~1+(1|SubjectID)',ExFNIRS.settings.curInfoStr);
                    curInfoChartLME_ML=fitlme(ExFNIRS.selectedTable,lmeString,'FitMethod','ML','DummyVarCoding',dummyCodeStr);
                    nullInfoChartLME=fitlme(ExFNIRS.selectedTable,nullMdlstring,'FitMethod','ML','DummyVarCoding',dummyCodeStr);
            
            %         curInfoChartLME_emm= pf2_base.external.emmeans(curInfoChartLME, {'orig'}, 'effects');
            %         h = emmip(curInfoChartLME_emm,'orig');
            
                    fprintf('Info Chart LME model: %s',ExFNIRS.settings.curInfoStr);
                    if(useAllInteractions)
                        fprintf(' - All Interactions\n');
                    else
                        fprintf(' - No Interactions\n');
                    end
                    ExFNIRS.curInfoChartModel=curInfoChartLME;
            
                    disp(curInfoChartLME);
                    displayLME(app, curInfoChartLME);
                    disp(compare(nullInfoChartLME,curInfoChartLME_ML));
            
                    %disp(curInfoChartLME.anova);
                    mdlTest=eye(length(curInfoChartLME.Coefficients.Name));
                    if(ExFNIRS.settings.LME_use_intercept)
                        mdlTest=mdlTest(2:end,:);
                    end
            
                    [curMdlFit{1:4}]=coefTest(curInfoChartLME,mdlTest,zeros(size(mdlTest,1),1),'DFMethod','satterthwaite');
                        fprintf('\nModel Fit (H0: All F=0): p=%.5f\tF=%.2f\tdf1=%i\tdf2=%i\n\n',curMdlFit{1},curMdlFit{2},curMdlFit{3},curMdlFit{4});
                        tic
                        curChartContrast=autoContrast(app, curInfoChartLME);
                        toc
                        disp(curChartContrast);
                    ExFNIRS.curInfoChartContrast=curChartContrast;
                catch ME
                    warning('Could not generate model for info figure %s',ExFNIRS.settings.curInfoStr);
                    warning(ME.message);
                end
            end
        end

        % Button pushed function: pushbutton_infoData_scatter
        function pushbutton_infoData_scatter_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_infoData_scatter (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % hObject    handle to pushbutton_plot_scatter (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)


            global ExFNIRS
            
            
            if(ExFNIRS.UpdateNeeded)
               updateSelectedTable(app);
            end
            
            if(~isfield(ExFNIRS,'gby'))
                warning('No groups match selection criteria');
                return;
            end
            
            curInfoGroup=ExFNIRS.settings.curInfoGroup; % Plot by
            
            curInfoVarY=ExFNIRS.settings.curInfoGroupBy; % y Var
            curInfoVarX=ExFNIRS.settings.curInfoStr; % X var
            
            gbyVars=ExFNIRS.groupByVars;
            if(~isempty(curInfoGroup)&&~strcmp(curInfoGroup,'(Time)'))
                [ismem,idx]=ismember(curInfoGroup,gbyVars);
                if(ismem)
                    gbyVars(idx)=[];
                    useCurInfoGroup=true;
                else
                    useCurInfoGroup=false;
                end
            else
                useCurInfoGroup=false;
            end
            
            numGroups=length(ExFNIRS.gby);
            
            if(numGroups==0)
                return;
            end
            
            
            if(ExFNIRS.settings.ylim_fixed)
                ExFNIRS.settings.ylim_fixed_min=inf;
                xlim_fixed_min=inf;
                ExFNIRS.settings.ylim_fixed_max=-inf;
                xlim_fixed_max=-inf;
            end
            
            
            % end
            
            gbyStrs=cell(numGroups,1);
            gbyShortStrs=cell(numGroups,1);
            curInfoGby=cell(0);
            
            for g=1:numGroups
                gbyStrs{g}='';
                gbyShortStrs{g}='';
               if(~isempty(ExFNIRS.gby(g).gbyTables))
                   for i=1:length(gbyVars)
                       gbyStrs{g}=sprintf('%s%s:%s,',gbyStrs{g},gbyVars{i},num2strOrNot(app, ExFNIRS.gby(g).gbyTables.(gbyVars{i})(1)));
                       gbyShortStrs{g}=sprintf('%s%s:%s,',gbyShortStrs{g},gbyVars{i}(1),num2strOrNot(app, ExFNIRS.gby(g).gbyTables.(gbyVars{i})(1)));
                   end
                   if(useCurInfoGroup)
                       curInfoGby{g}=num2strOrNot(app, ExFNIRS.gby(g).gbyTables.(curInfoGroup)(1));
                   end
               end
               if(~isempty(gbyStrs{g}))
                    gbyStrs{g}(end)='';
                    gbyShortStrs{g}(end)='';
               end
            end
            
            numUgroups=length(unique(cellstr(gbyStrs)));
            
            if(numUgroups==1)
                num2Plot=1;
                if(ExFNIRS.settings.use_gui_color)
                    cIndex=ExFNIRS.settings.guiColor(1:numUgroups,:);
                else
                    cIndex=ExFNIRS.settings.cmap(numUgroups);%linspecer(num2Plot,'qualitative');
                end
            else
                num2Plot=numGroups;
                if(ExFNIRS.settings.use_gui_color)
                    cIndex=ExFNIRS.settings.guiColor(1:numUgroups,:);
                else
                    cIndex=ExFNIRS.settings.cmap(numUgroups);%linspecer(num2Plot,'qualitative');
                end
            end
            
            
            [uCurInfoG,firstCurIdx,uCurIdx]=unique(cellstr(curInfoGby));
            numCurInfoG=max(uCurIdx);
            uCurGIdxCount=nan(size(uCurIdx));
            for i =1:numCurInfoG
                uCurGIdxCount(uCurIdx==i)=1:sum(uCurIdx==i);
            end
            
            if(~useCurInfoGroup||isnan(numCurInfoG))
               useCurInfoGroup=false;
               numCurInfoG=1;
               uCurInfoG='';
            end
            
            
            errorFeature=ExFNIRS.settings.plot_bar_err_feature;
            plotFeature=ExFNIRS.settings.plot_bar_feature;
            
            if(strcmp(plotFeature,'Count')&&ExFNIRS.settings.plot_bar_ga)
                plotFeature='N';
                plotCount=true;
            else
                plotCount=false;
            end
            
            
            subplotHandles=cell(numCurInfoG,1);
            
            
            if(numCurInfoG>1)
                xType='groupby';
                yType='';
                figType='';
                numSubX=numCurInfoG;
                numSubY=1;
            else
                xType='';
                yType='';
                figType='';
                numSubX=numCurInfoG;
                numSubY=1;
            end
            
            sH{1,1}.h=figure(1200);
            clf(sH{1,1}.h);
            dcm_obj = datacursormode(sH{1,1}.h);
            set(dcm_obj,'UpdateFcn',@app.myDataTipUpdateFcn);
            for s=1:(numSubX*numSubY)
                xInd=rem(s,numSubX);
                if(xInd==0)
                    xInd=numSubX;
                end
                h=subplot(numSubY,numSubX,s);
                sH{1,1}.subH{floor((s-1)/numSubX)+1,xInd}=h;
            
                legend(h, 'off');
            end
            
            curSx=1;
            curSy=1;
            curFigIdx=[1,1];
            
            
            curInfoStr=ExFNIRS.settings.curInfoStr;
            
            if(ExFNIRS.settings.within_sub_avg_mode==3)
                dataH=ExFNIRS.dataHierarchy;
            elseif(ExFNIRS.settings.within_sub_avg_mode==2)
                dataH='SubjectID';
            else
                dataH=[];
            end


            legendGFXhandles{1}=[];
            legendGFXstrs{1}=cell(0);
            
            num2Plot=numUgroups;
            
            
            pointStrs=cell(num2Plot,1);
            gAStrs=cell(num2Plot,1);
            gAerrStrs=cell(num2Plot,1);
            
            curChart=1;
            
            curUstrX="";
            curUstrX(1)=[];
            curUstrY="";
            curUstrY(1)=[];
            
            plotXstr=false;
            plotYstr=false;
            
            
            for g=1:numGroups
                curTable=ExFNIRS.gby(g).gbyTables;
                curDataX=curTable(:,curInfoVarX);
                curDataX=table2array(curDataX);
                curDataY=curTable(:,curInfoVarY);
                curDataY=table2array(curDataY);
            
                if(isstring(curDataX)||ischar(curDataX))
                   %warning('Strings return count');
                   curDataX=string(curDataX);
                   [uDataX,~,curDataIdx]=unique(curDataX);
                   if(~isempty(uDataX))
                       curUstrX(end+1:end+length(uDataX))=uDataX;
                       [curUstrX,~,curUidxX]=unique(curUstrX);
                       curDataX=nan(size(curDataIdx));
                       for udx=1:length(uDataX)
                           cdx=find(ismember(curUstrX,uDataX(udx)));
                           curDataX(curDataIdx==udx)=cdx;
                       end
                       %plotFeature='String';
                       % return;
                       plotXstr=true;
                   end
                end
                curDataX(curDataX==-9999)=nan;
            
                if(isstring(curDataY)||ischar(curDataY))
                   %warning('Strings return count');
                   curDataY=string(curDataY);
                   [uDataY,~,curDataIdxY]=unique(curDataY);
                   if(~isempty(uDataY))
                       curUstrY(end+1:end+length(uDataY))=uDataY;
                       [curUstrY,~,curUidxY]=unique(curUstrY);
                       curDataY=nan(size(curDataIdxY));
                       for udx=1:length(uDataY)
                           cdx=find(ismember(curUstrY,uDataY(udx)));
                           curDataY(curDataIdxY==udx)=cdx;
                       end
                       %plotFeature='String';
                       plotYstr=true;
                       % return;
                   end
                end
                curDataY(curDataY==-9999)=nan;
            
                if(useCurInfoGroup)
                    curGroupInfoIdx=uCurIdx(g);
                    curGroupIdxOffset=(curGroupInfoIdx-1)*1;
                    curUgroupIdx=uCurGIdxCount(g);
                else
                    curGroupInfoIdx=1;
                    curGroupIdxOffset=0;
                    curUgroupIdx=g;
                end
            
                if(useCurInfoGroup)
                    if(strcmp(xType,'groupby'))
                        curSx=curGroupInfoIdx;
                    elseif(strcmp(yType,'groupby'))
                        curSy=curGroupInfoIdx;
                    end
                end
            
                if(strcmp(xType,'ugroup'))
                    curSx=curUgroupIdx;
                elseif(strcmp(yType,'ugroup'))
                    curSy=curUgroupIdx;
                end


                if(plotXstr)
                    [curHAvgX,outH]=pf2_base.hierarchicalAverage(curDataX,curTable(:,dataH),@mode);
                elseif(strcmp(plotFeature,'Mean'))
                    [curHAvgX,outH]=pf2_base.hierarchicalAverage(curDataX,curTable(:,dataH),@nanmean);
                elseif(strcmp(plotFeature,'Median'))
                    [curHAvgX,outH]=pf2_base.hierarchicalAverage(curDataX,curTable(:,dataH),@nanmedian);
                elseif(strcmp(plotFeature,'Count')||strcmp(plotFeature,'N'))
                    [curHAvgX,outH]=pf2_base.hierarchicalAverage(curDataX,curTable(:,dataH),@nnz);
                else
                    error('Unknown parameter');
                    %curHAvg=nanmedian(hierarchicalAverage(curData,curTable(:,dataH),@nanmedian));
                end
            
                if(plotYstr)
                    [curHAvgY,outH]=pf2_base.hierarchicalAverage(curDataY,curTable(:,dataH),@mode);
                elseif(strcmp(plotFeature,'Mean'))
                    [curHAvgY,outH]=pf2_base.hierarchicalAverage(curDataY,curTable(:,dataH),@nanmean);
                elseif(strcmp(plotFeature,'Median'))
                    [curHAvgY,outH]=pf2_base.hierarchicalAverage(curDataY,curTable(:,dataH),@nanmedian);
                elseif(strcmp(plotFeature,'Count')||strcmp(plotFeature,'N'))
                    [curHAvgY,outH]=pf2_base.hierarchicalAverage(curDataY,curTable(:,dataH),@nnz);
                else
                    error('Unknown parameter');
                    %curHAvg=nanmedian(hierarchicalAverage(curData,curTable(:,dataH),@nanmedian));
                end
            
                curSy=1;
            
                curPlotHandle=sH{curFigIdx(1),curFigIdx(2)}.subH{curSy,curSx};
                lastSubPlot=(curSy==numSubY&&curSx==numSubX);
                hold(curPlotHandle,'on')
            
            
                curFeatureY=curHAvgY;


                if(length(curFeatureY)~=length(curHAvgX))
                    if(length(curFeatureY)>length(curHAvgX))
                        curFeatureY=curFeatureY(ismember(curGrand.info.Observation,outH));
                    else
                        temp=nan(size(curHAvgX));
                        temp(ismember(outH,curGrand.info.Observation))=curFeatureY;
                        curFeatureY=temp;
                    end
                end
            
            
                 sColor=cIndex(curUgroupIdx,:);


                if(ExFNIRS.settings.plot_scatter_nonparametric)


                    validIdx=sum([isnan(curHAvgX),isnan(curFeatureY)],2)==0;
                    validIdx=validIdx&(~isempty(curHAvgX)&&~isempty(curFeatureY));
                    xVals=curHAvgX(validIdx);
                    yVals=curFeatureY(validIdx);
            
                    [~,p] = sort(xVals,'descend');
                    r = 1:length(xVals);
                    r(p) = r;
                    xVals=r';
            
                    [~,p] = sort(yVals,'descend');
                    r = 1:length(yVals);
                    r(p) = r;
                    yVals=r';
            
                    validIdx=sum([isnan(xVals),isnan(yVals)],2)==0;
                    validIdx=validIdx&(~isempty(curHAvgX)&&~isempty(curFeatureY));
            
                    xVals=xVals(validIdx);
                    yVals=yVals(validIdx);
                    N=length(xVals);
                else
                    validIdx=sum([isnan(curHAvgX),isnan(curFeatureY)],2)==0;
                    validIdx=validIdx&(~isempty(curHAvgX)&&~isempty(curFeatureY));
                    xVals=curHAvgX(validIdx);
                    yVals=curFeatureY(validIdx);
                    N=length(xVals);
                end
            
                 if(plotXstr)
                   uData=[xVals,yVals];
                   microvar=(nanmax(yVals)-nanmin(yVals))/100;
                    [uRows,~,uRowIdx]=unique(uData,'rows');
                    bincounts = histc(uRowIdx,1:max(uRowIdx));
                    for xv=1:length(bincounts)
                        if(bincounts(xv)>1)
                           stepsize=0.8/(bincounts(xv)-1);
                           offset=(-0.4:stepsize:0.4);
                           if(bincounts(xv)<10)
                               offset=offset/(10-bincounts(xv));
                           else
                                offset=(abs(offset).^1.5).*sign(offset);
                           end
                           xVals(uRowIdx==xv)=[uRows(xv,1)+offset];
                           yVals(uRowIdx==xv)=[uRows(xv,2)-microvar+g/numGroups*(2*microvar)];
                        end
            
                    end
                end
            
                if(plotYstr)
                    uData=[xVals,yVals];
                    microvar=(nanmax(xVals)-nanmin(xVals))/100;
                    [uRows,~,uRowIdx]=unique(uData,'rows');
                    bincounts = histc(uRowIdx,1:max(uRowIdx));
                    for yv=1:length(bincounts)
                        if(bincounts(yv)>1)
                           stepsize=0.8/(bincounts(yv)-1);
                           offset=(-0.4:stepsize:0.4);
                           if(bincounts(yv)<10)
                               offset=offset/(10-bincounts(yv));
                           else
                               offset=(abs(offset).^1.5).*sign(offset);
                           end
                           yVals(uRowIdx==yv)=[uRows(yv,2)+offset];
                           xVals(uRowIdx==yv)=[uRows(yv,1)-microvar+g/numGroups*(2*microvar)];
                        end
            
                    end
                end
            
                if(ExFNIRS.settings.plot_scatter_flipxy)
                    temp=xVals;
                    xVals=yVals;
                    yVals=temp;
            
            
                end


                    sHdots=scatter(curPlotHandle,xVals,yVals,25,sColor,'filled');
            
                       pointStrs{curUgroupIdx}= gbyStrs{g};
                       curPointStr=pointStrs{curUgroupIdx};
                       %if(ExFNIRS.settings.plot_legend_mode==3||(ExFNIRS.settings.plot_legend_mode==2&&lastSubPlot))
                           sH{curFigIdx(1),curFigIdx(2)}.legendHandles{curSy,curSx}.h{curUgroupIdx}=sHdots;
                       %end
            
                    tagStr=sprintf('%s',curPointStr);
                    set(sHdots,'tag',tagStr);
            
            
                    curFeatureString=sprintf('%s',curInfoVarY);
            
            
                    if(ExFNIRS.settings.plot_scatter_nonparametric)
                        curFeatureString=sprintf('Rank %s',curFeatureString);
                    end
            
                    switch(xType)
                        case 'groupby'
                            title(curPlotHandle,curInfoGby{g});
                    end


                    if(ExFNIRS.settings.plot_scatter_flipxy)
            
                        switch(yType)
            
                            case 'groupby'
                                ylabel(curPlotHandle,{curInfoGby{g};curInfoStr});
            
                            otherwise
                                ylabel(curPlotHandle,curInfoStr);
                        end
                        xlabel(curPlotHandle,curFeatureString);
                    else
            
            
                        switch(yType)
            
                            case 'groupby'
                                ylabel(curPlotHandle,{curInfoGby{curUgroupIdx};curFeatureString});
            
                            otherwise
                                ylabel(curPlotHandle,curFeatureString);
                        end
                        if(ExFNIRS.settings.plot_scatter_nonparametric)
                            xlabel(curPlotHandle,sprintf('Rank %s',curInfoStr));
                        else
                            xlabel(curPlotHandle,curInfoStr);
                        end
                    end


                    if(ExFNIRS.settings.plot_scatter_line||ExFNIRS.settings.plot_scatter_err)
                        if(N>2)
                            [coefficients,PolyS] = polyfit(xVals, yVals, 1);
                            CI = pf2_base.external.polyparci(coefficients,PolyS);
                            xFit = linspace(min(xVals), max(xVals), 1000);
                            [yFit,deltaY] = polyval(coefficients, xFit,PolyS);
            
                            if(ExFNIRS.settings.plot_scatter_extend)
                                curXlim=xlim(curPlotHandle);
                                xFitExtend = linspace(min(curXlim), max(curXlim), 1000);
                                yFitExtend = polyval(coefficients, xFitExtend);
                            end
            
                            errMulitply=ExFNIRS.settings.plot_bar_err_mult;
                            %CI=[0,0;0,0];
            
                            yEst=polyval(coefficients, xVals,PolyS);
                            yDiff=yVals-yEst;
                            SD=std(yDiff);
            
                            %SD=sqrt(N)*(CI(:,2)-CI(:,1))'/3.92;
                            SEM=SD/sqrt(N);
            
                            switch(ExFNIRS.settings.plot_scatter_err_feature)
                                case 'SEM'
                                    %yCI_Upper = polyval(coefficients+SEM*errMulitply, xFit);
                                    %yCI_Lower = polyval(coefficients-SEM*errMulitply, xFit);
                                    yCI_Upper = yFit+SEM*errMulitply;
                                    yCI_Lower = yFit-SEM*errMulitply;
                                case 'SD'
                                    %yCI_Upper = polyval(coefficients+SD*errMulitply, xFit);
                                    %yCI_Lower = polyval(coefficients-SD*errMulitply, xFit);
                                    yCI_Upper = yFit+SD*errMulitply;
                                    yCI_Lower = yFit-SD*errMulitply;
                                case '95%CI'
                                    yCI_Upper = polyval(CI(1,:), xFit);
                                    yCI_Lower = polyval(CI(2,:), xFit);
                                case '50%PI'
                                    yCI_Upper = yFit+deltaY*(tinv(0.50,(N-1)));
                                    yCI_Lower = yFit-deltaY*(tinv(0.50,(N-1)));
                                case '67%PI'
                                    yCI_Upper = yFit+deltaY*(tinv(0.67,(N-1)));
                                    yCI_Lower = yFit-deltaY*(tinv(0.67,(N-1)));
                                case '90%PI'
                                    yCI_Upper = yFit+deltaY*(tinv(0.90,(N-1)));
                                    yCI_Lower = yFit-deltaY*(tinv(0.90,(N-1)));
                                case '95%PI'
                                    yCI_Upper = yFit+deltaY*(tinv(0.95,(N-1)));
                                    yCI_Lower = yFit-deltaY*(tinv(0.95,(N-1)));
                            end
                        end
                    end


                    if(ExFNIRS.settings.plot_scatter_err&&N>2)
                        errStyle=ExFNIRS.settings.plot_scatter_error_style;
            
                        plotShaded=false;
            
                        switch(errStyle)
                            case 'Dashed'
                                errStyle='--';
                                lineWidth=2;
                            case 'Fine'
                                errStyle='-';
                                lineWidth=0.5;
                            case 'Shaded'
                                errStyle='-';
                                lineWidth=0.5;
                                plotShaded=true;
                            otherwise
                                error('Unspecified error style');
                        end
            
                        errColor=sColor+(1-sColor)*0.55;
                        if(plotShaded)
                              errAlpha=0.15;
                              yPatch=[yCI_Lower,fliplr(yCI_Upper)];
                              xPatch=[xFit,fliplr(xFit)];
                              %xPatch(isnan(yPatch))=[];
                              %yPatch(isnan(yPatch))=[];
            
                              h=patch(curPlotHandle,xPatch,yPatch,-1,'facecolor',errColor,'edgecolor','none','facealpha',errAlpha);
                              set(h,'HandleVisibility','off');
                              set(h,'HitTest','off');
                              set(h.Annotation.LegendInformation,'IconDisplayStyle','off');
                        end
            
                        h=plot(curPlotHandle,xFit,yCI_Upper,'LineStyle',errStyle,'Color',errColor,'LineWidth',lineWidth);
                        set(h.Annotation.LegendInformation,'IconDisplayStyle','off');
                        h=plot(curPlotHandle,xFit,yCI_Lower,'LineStyle',errStyle,'Color',errColor,'LineWidth',lineWidth);
                        set(h.Annotation.LegendInformation,'IconDisplayStyle','off');
                    else
            
                               gAErrStrs{curGroupInfoIdx}='';
            
                    end
            
                    if(ExFNIRS.settings.plot_scatter_line&&N>2)
                        hold(curPlotHandle,'on')
                        if(ExFNIRS.settings.plot_scatter_extend)
                            gaH=plot(curPlotHandle,xFitExtend, yFitExtend, 'r-', 'LineWidth', 2,'Color',sColor);
                        else
                            gaH=plot(curPlotHandle,xFit, yFit, 'r-', 'LineWidth', 2,'Color',sColor);
                        end
            
                        set(gaH.Annotation.LegendInformation,'IconDisplayStyle','off');
            
                        [rho,pval] = corr(xVals,yVals,'Type','Spearman');
            
            
                        [r,p]=corr(xVals,yVals,'Type','Pearson');
            
            
                           fitStr=gbyStrs{g};
                           gAStrs{curUgroupIdx}= fitStr;
                           %if(ExFNIRS.settings.plot_legend_mode==3||(ExFNIRS.settings.plot_legend_mode==2&&lastSubPlot))
                               sH{curFigIdx(1),curFigIdx(2)}.legendHandles{curSy,curSx}.h{curUgroupIdx}=gaH;
                           %end
            
            
                        tagStr=sprintf('%s (N %i)\nRho=%.3f, p=%.4f\nr=%.3f p=%.4f',fitStr,N,rho,pval,r,p);
                        set(gaH,'tag',tagStr);
                    elseif(~ExFNIRS.settings.plot_scatter_line)
                        %sH{curFigIdx(1),curFigIdx(2)}.legendHandles{curSy,curSx}.h=cell(0);
                        %gAStrs=cell(0);
                    end
            
            
                    curLgdHandles=sH{curFigIdx(1),curFigIdx(2)}.legendHandles{curSy,curSx}.h(:);
                    numCurLgd=length(curLgdHandles);
                    numFilled=0;
                    for i=1:numCurLgd
                        numFilled=numFilled+~isempty(curLgdHandles{i});
                    end
            
                    if(ExFNIRS.settings.ylim_fixed)
                        ylim(curPlotHandle,'auto');
                        cylim=ylim(curPlotHandle);
                        ExFNIRS.settings.ylim_fixed_min=min(ExFNIRS.settings.ylim_fixed_min,cylim(1));
                        ExFNIRS.settings.ylim_fixed_max=max(ExFNIRS.settings.ylim_fixed_max,cylim(2));
            
                        xlim(curPlotHandle,'auto');
                        cxlim=xlim(curPlotHandle);
                        xlim_fixed_min=min(xlim_fixed_min,cxlim(1));
                        xlim_fixed_max=max(xlim_fixed_max,cxlim(2));
                    elseif(ExFNIRS.settings.ylim_manual)
                        if(ExFNIRS.settings.plot_scatter_flipxy)
                            if(~plotCount)
                                xlim(curPlotHandle,[ExFNIRS.settings.ylim_manual_min,ExFNIRS.settings.ylim_manual_max]);
                            else
                                xlim(curPlotHandle,[0,ExFNIRS.settings.ylim_manual_max]);
                            end
                        else
                            if(~plotCount)
                                ylim(curPlotHandle,[ExFNIRS.settings.ylim_manual_min,ExFNIRS.settings.ylim_manual_max]);
                            else
                                ylim(curPlotHandle,[0,ExFNIRS.settings.ylim_manual_max]);
                            end
                        end
                    elseif(plotCount)
                        if(ExFNIRS.settings.plot_scatter_flipxy)
                            cxlim=xlim(curPlotHandle);
                            xlim(curPlotHandle,[0,cxlim(2)]);
                        else
                            cylim=ylim(curPlotHandle);
                            ylim(curPlotHandle,[0,cylim(2)]);
                        end
            
                    else
                        ylim(curPlotHandle,'auto');
                    end
                end
            
             if(ExFNIRS.settings.plot_scatter_flipxy)
                 temp=plotXstr;
                plotXstr=plotYstr;
                plotYstr=temp;
            
                temp=curUstrX;
                curUstrX=curUstrY;
                curUstrY=temp;
             end
            
            
            if(plotCount)
                if(ExFNIRS.settings.plot_scatter_flipxy)
                    xlim_fixed_min=0;
                else
                    ExFNIRS.settings.ylim_fixed_min=0;
                end
            end
            
            
            for i=1:size(sH,1)
                for b=1:size(sH,2)
                    for x=1:numSubX
                        for y=1:numSubY
                            if(ExFNIRS.settings.ylim_fixed)
                                ylim(sH{i,b}.subH{y,x},[ExFNIRS.settings.ylim_fixed_min,ExFNIRS.settings.ylim_fixed_max]);
                                xlim(sH{i,b}.subH{y,x},[xlim_fixed_min,xlim_fixed_max]);
                            end
            
                            if(plotXstr)
                                xlim(sH{i,b}.subH{y,x},[0,length(curUstrX)]+0.5);
                                xticks(sH{i,b}.subH{y,x},1:(length(curUstrX)));
                               xticklabels(sH{i,b}.subH{y,x},curUstrX);
            
                            end
            
                            if(plotYstr)
                                ylim(sH{i,b}.subH{y,x},[0,length(curUstrY)]+0.5);
                                yticks(sH{i,b}.subH{y,x},1:(length(curUstrY)));
                               yticklabels(sH{i,b}.subH{y,x},curUstrY);
            
                            end
            
                            if((ExFNIRS.settings.plot_legend_mode==3||(ExFNIRS.settings.plot_legend_mode==2&&(x==numSubX)&&y==numSubY))&&numUgroups>1)
                                lgStrs=[];
                                for k=1:length(pointStrs)
                                   if(isnumeric(pointStrs{k}))
                                       pointStrs{k}='';
                                   end
                                   lgStrs=[lgStrs;pointStrs(k)];
                                end
            
                                legend(sH{i,b}.subH{y,x},pointStrs(:),'Location', 'Best');
                            end
            
                            hold(sH{i,b}.subH{y,x},'off')
                        end
                    end
            
                    addDebugAnnotation(app, sH{i,b}.h);
            
                    curCorrstr=sprintf('%s vs. %s',curInfoVarX,curInfoVarY);


                    if(ExFNIRS.settings.plot_scatter_nonparametric)
                        suptStr=sprintf('Rank %s',curCorrstr);
                    else
                        suptStr=curCorrstr;
                    end
            
                    switch(xType)
                        case 'groupby'
                            pf2_base.external.suptitle(sprintf('%s by %s',suptStr,curInfoGroup));
                        otherwise
                            pf2_base.external.suptitle(suptStr);
                    end
            
                end
            end
        end

        % Button pushed function: pushbutton_info_groupby_select_all
        function pushbutton_info_groupby_select_all_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_info_groupby_select_all (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            strs=get(app.listbox_info_groupby,'Items');
            if(iscell(strs)||ismatrix(strs))
                set(app.listbox_info_groupby,'Value',[1:size(strs,1)]);
            end
            
            global ExFNIRS
            
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,true);
            end
        end

        % Button pushed function: 
        % pushbutton_info_groupby_select_none
        function pushbutton_info_groupby_select_none_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_info_groupby_select_none (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global ExFNIRS
            
            strs=get(app.listbox_info_groupby,'Items');
            if(iscell(strs)||ismatrix(strs))
                set(app.listbox_info_groupby,'Value',[]);
            end
            
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,true);
            end
        end

        % Button pushed function: pushbutton_lme_plot_topo
        function pushbutton_lme_plot_topo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_lme_plot_topo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global ExFNIRS
            switch(ExFNIRS.settings.ChannelMode)
                case 'fNIR'
                    strs=get(app.listbox_optode,'Items');
                    if(iscell(strs)||ismatrix(strs))
                        set(app.listbox_optode,'Value',[1:size(strs,1)]);
                    end
                case 'ROI'
            
                case 'Aux'
            
            end
            
            plot_barchart(app, false,true)
        end

        % Button pushed function: pushbutton_move_hierarchy_down
        function pushbutton_move_hierarchy_down_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_move_hierarchy_down (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            hierarchyStr=get(app.listbox_hierarchy,'Items');
            hierarchyVal=get(app.listbox_hierarchy,'Value');
            
            numHstr=length(hierarchyStr);
            if(hierarchyVal==numHstr)
                return;
            else
                newInd=[1:hierarchyVal-1,hierarchyVal+1,hierarchyVal,(hierarchyVal+2):numHstr];
                newInd(newInd>numHstr)=[];
                set(app.listbox_hierarchy,'Items',hierarchyStr(newInd));
                set(app.listbox_hierarchy,'Value',hierarchyVal+1);
            end
            
            hierarchyStr=get(app.listbox_hierarchy,'Items');
            global ExFNIRS
            ExFNIRS.dataHierarchy(2:numHstr+1)=hierarchyStr;
            
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,true);
            end
        end

        % Button pushed function: pushbutton_move_hierarchy_up
        function pushbutton_move_hierarchy_up_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_move_hierarchy_up (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            hierarchyStr=get(app.listbox_hierarchy,'Items');
            hierarchyVal=get(app.listbox_hierarchy,'Value');
            
            numHstr=length(hierarchyStr);
            if(hierarchyVal==1)
                return;
            else
                newInd=[1:hierarchyVal-2,hierarchyVal,hierarchyVal-1,(hierarchyVal+1):numHstr];
                newInd(newInd>numHstr)=[];
                set(app.listbox_hierarchy,'Items',hierarchyStr(newInd));
                set(app.listbox_hierarchy,'Value',hierarchyVal-1);
            end
            
            hierarchyStr=get(app.listbox_hierarchy,'Items');
            
            global ExFNIRS
            ExFNIRS.dataHierarchy(2:numHstr+1)=hierarchyStr;
            
            
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,true);
            end
        end

        % Button pushed function: pushbutton_optodes_select_all
        function pushbutton_optodes_select_all_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_optodes_select_all (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            strs=get(app.listbox_optode,'Items');
            if(iscell(strs)||ismatrix(strs))
                set(app.listbox_optode,'Value',[1:size(strs,1)]);
            end
        end

        % Button pushed function: pushbutton_optodes_select_none
        function pushbutton_optodes_select_none_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_optodes_select_none (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            set(app.listbox_optode,'Value',[]);
        end

        % Button pushed function: pushbutton_plot_barchart
        function pushbutton_plot_barchart_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_plot_barchart (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            plot_barchart(app, true,false);
        end

        % Button pushed function: pushbutton_plot_scatter
        function pushbutton_plot_scatter_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_plot_scatter (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % if one timepoint
            % 	if multi biomarker + multichannels
            % 	groupby x channels (fig biomarker)
            % 	if 1 biomarker + mult channels
            % 	groupby x channels
            % 	if 1 channel + mult biomarker
            % 	groupby x biomarkers
            % 	if 1 channel + 1 biomarker
            % 	groupby
            % if 1 groups
            % 	biomarker mode
            % 	if 1 timepoint
            % 	groupby x channels
            % 	if multi timepoints
            % 	groupby x time   (fig channels)
            % if no groupby
            % 	if one timepoint
            % 		biomarker x channels
            % 	if multitimepoints & multi channel
            % 		time x channels (fig biomarker)
            % 	if multitimepoints & 1 channel
            % 		time x biomarker
            
            
            global ExFNIRS
            
            
            if(ExFNIRS.UpdateNeeded)
               updateSelectedTable(app);
            end
            
            if(nargin<4)
               plotTopo=false;
            end
            
            if(~isfield(ExFNIRS,'gby'))
                warning('No groups match selection criteria');
                return;
            end
            
            curInfoGroup=ExFNIRS.settings.curInfoGroup;
            
            gbyVars=ExFNIRS.groupByVars;
            if(~isempty(curInfoGroup)&&~strcmp(curInfoGroup,'(Time)'))
                [ismem,idx]=ismember(curInfoGroup,gbyVars);
                if(ismem)
                    gbyVars(idx)=[];
                    useCurInfoGroup=true;
                else
                    useCurInfoGroup=false;
                end
            else
                useCurInfoGroup=false;
            end
            
            numGroups=length(ExFNIRS.gby);
            
            biomStrs=get(app.listbox_biomarker,'Items');
            selBioM=get(app.listbox_biomarker,'Value');
            selectedBioM=biomStrs(selBioM);
            numBioM=length(selBioM);
            
            optStrs=get(app.listbox_optode,'Items');
            selOpt=get(app.listbox_optode,'Value');
            selectedOptStr=optStrs(selOpt',:);
            numOpt=length(selOpt);
            
            if(numOpt==0||numGroups==0||numBioM==0)
                return;
            end
            
            
            if(ExFNIRS.settings.ylim_fixed)
                ExFNIRS.settings.ylim_fixed_min=inf;
                xlim_fixed_min=inf;
                ExFNIRS.settings.ylim_fixed_max=-inf;
                xlim_fixed_max=-inf;
            end
            
            
            % end
            
            gbyStrs=cell(numGroups,1);
            gbyShortStrs=cell(numGroups,1);
            curInfoGby=cell(0);
            
            for g=1:numGroups
                gbyStrs{g}='';
                gbyShortStrs{g}='';
               if(~isempty(ExFNIRS.gby(g).gbyTables))
                   for i=1:length(gbyVars)
                       gbyStrs{g}=sprintf('%s%s:%s,',gbyStrs{g},gbyVars{i},num2strOrNot(app, ExFNIRS.gby(g).gbyTables.(gbyVars{i})(1)));
                       gbyShortStrs{g}=sprintf('%s%s:%s,',gbyShortStrs{g},gbyVars{i}(1),num2strOrNot(app, ExFNIRS.gby(g).gbyTables.(gbyVars{i})(1)));
                   end
                   if(useCurInfoGroup)
                       curInfoGby{g}=num2strOrNot(app, ExFNIRS.gby(g).gbyTables.(curInfoGroup)(1));
                   end
               end
               if(~isempty(gbyStrs{g}))
                    gbyStrs{g}(end)='';
                    gbyShortStrs{g}(end)='';
               end
            end
            
            numUgroups=length(unique(cellstr(gbyStrs)));
            
            if(numUgroups==1)
                num2Plot=numBioM;
                plotGroupByBioM=true;
                temp=table2cell(pf2_base.getBioColors())';
                for i=1:size(temp,1)
                   cIndex(i,:)=temp{i,:};
                end
                cIndex=cIndex(selBioM,:);
            else
                num2Plot=numGroups;
                plotGroupByBioM=false;
                if(ExFNIRS.settings.use_gui_color)
                    cIndex=ExFNIRS.settings.guiColor(1:numUgroups,:);
                else
                    cIndex=ExFNIRS.settings.cmap(numUgroups);%linspecer(num2Plot,'qualitative');
                end
            end


            barChartTimes=[];
            for i=1:numGroups
                if(isfield(ExFNIRS.gby(i).gbyGrandBar,'time'))
                    curGrandTime=ExFNIRS.gby(i).gbyGrandBar.time;
                    barChartTimes=[barChartTimes;curGrandTime];
                end
            end
            barChartTimes=sort(unique(round(barChartTimes)));
            
            [uCurInfoG,firstCurIdx,uCurIdx]=unique(cellstr(curInfoGby));
            numCurInfoG=max(uCurIdx);
            uCurGIdxCount=nan(size(uCurIdx));
            for i =1:numCurInfoG
                uCurGIdxCount(uCurIdx==i)=1:sum(uCurIdx==i);
            end
            
            if(~useCurInfoGroup||isnan(numCurInfoG))
               useCurInfoGroup=false;
               numCurInfoG=1;
               uCurInfoG='';
            end
            
            
            barChartTimes=barChartTimes(barChartTimes<ExFNIRS.settings.plot_end&barChartTimes>=ExFNIRS.settings.plot_start);
            numChartTimes=length(barChartTimes);
            errorFeature=ExFNIRS.settings.plot_bar_err_feature;
            plotFeature=ExFNIRS.settings.plot_bar_feature;
            
            if(strcmp(plotFeature,'Count')&&ExFNIRS.settings.plot_bar_ga)
                plotFeature='N';
                plotCount=true;
            else
                plotCount=false;
            end
            
            if(plotGroupByBioM)
                subplotHandles=cell(numOpt*numCurInfoG*numChartTimes,1);
            else
                subplotHandles=cell(numOpt*numBioM*numChartTimes*numCurInfoG,1);
            end
            if(plotTopo)
                if(numChartTimes>1)
                    if(numCurInfoG>1&&numBioM>1&&numUgroups>1) %everything
                        xType='ugroup';
            			yType='time';
            			figType='groupby,bio';
                        numSubX=numUgroups;
                        numSubY=numChartTimes;
                    elseif(numCurInfoG<=1&&numBioM>=1&&numUgroups>1) %no infogroup
                        xType='ugroup';
            			yType='time';
            			figType='bioM';
                        numSubX=numUgroups;
                        numSubY=numChartTimes;
                    elseif(numCurInfoG>1&&numUgroups<=1&&numBioM>1) % no groups bu infogroup
            			xType='groupby';
            			yType='time';
            			figType='bioM';
                        numSubX=numCurInfoG;
                        numSubY=numChartTimes;
                    elseif(numUgroups<=1&&numCurInfoG<=1&&numBioM>1) % only biomarkers and time
            			xType='bioM';
            			yType='time';
            			figType='';
                        numSubX=numBioM;
                        numSubY=numChartTimes;
            		else
            			xType='time';
            			yType='bioM';
            			figType='';
                        numSubX=numChartTimes;
                        numSubY=1;
                    end
                else
                    if(numCurInfoG>1&&numUgroups>1)
            			xType='groupby';
            			yType='ugroup';
            			figType='bioM';
                        numSubX=numCurInfoG;
                        numSubY=numUgroups;
            		elseif(numCurInfoG<=1&&numUgroups>1)
            			xType='ugroup';
            			yType='bioM';
            			figType='';
                        numSubX=numUgroups;
                        numSubY=numBioM;
                    elseif(numCurInfoG>1&&numUgroups<=1)
            			xType='groupby';
            			yType='bioM';
            			figType='';
                        numSubX=numCurInfoG;
                        numSubY=numBioM;
            		else
            			xType='bioM';
            			yType='';
            			figType='';
                        numSubX=numBioM;
                        numSubY=1;
            		end
                end
            elseif(~plotGroupByBioM)
            	if(numChartTimes>1)
            		if(numOpt>1&&numCurInfoG>1)
            			xType='groupby';
            			yType='time';
            			figType='bio,channels';
                        numSubX=numCurInfoG;
                        numSubY=numChartTimes;
            		elseif(numOpt==1&&numCurInfoG>1)
            			xType='groupby';
            			yType='time';
            			figType='bioM';
                        numSubX=numCurInfoG;
                        numSubY=numChartTimes;
            		elseif(numCurInfoG<=1&&numOpt>1)
            			xType='time';
            			yType='channels';
            			figType='bioM';
                        numSubX=numChartTimes;
                        numSubY=numOpt;
            		else
            			xType='time';
            			yType='bioM';
            			figType='channels';
                        numSubX=numChartTimes;
                        numSubY=numBioM;
            		end
            	else
            		if(numOpt>1&&numCurInfoG>1)
            			xType='groupby';
            			yType='channels';
            			figType='bioM';
                        numSubX=numCurInfoG;
                        numSubY=numOpt;
            		elseif(numOpt==1&&numCurInfoG>1)
            			xType='groupby';
            			yType='bioM';
            			figType='';
                        numSubX=numCurInfoG;
                        numSubY=numBioM;
            		elseif(numCurInfoG<=1&&numOpt>1)
            			xType='channels';
            			yType='bioM';
            			figType='';
                        numSubX=numOpt;
                        numSubY=numBioM;
            		else
            			xType='bioM';
            			yType='channels';
            			figType='';
                        numSubX=numBioM;
                        numSubY=1;
            		end
            	end
            else %plot with biomarkers embedded
            	if(numChartTimes>1)
            		if(numOpt>=1&&numCurInfoG>1)
            			xType='groupby';
            			yType='time';
            			figType='channels';
                        numSubX=numCurInfoG;
                        numSubY=numChartTimes;
            		elseif(numCurInfoG<=1&&numOpt>1)
            			xType='time';
            			yType='channels';
            			figType='';
                        numSubX=numChartTimes;
                        numSubY=numOpt;
            		else
            			xType='time';
            			yType='channels';
            			figType='';
                        numSubX=numChartTimes;
                        numSubY=1;
            		end
            	else
            		if(numOpt>=1&&numCurInfoG>1)
            			xType='groupby';
            			yType='channels';
            			figType='';
                        numSubX=numCurInfoG;
                        numSubY=numOpt;
            		elseif(numCurInfoG<=1&&numOpt>1)
            			xType='channels';
            			yType='';
            			figType='';
                        numSubX=numOpt;
                        numSubY=1;
            		else
            			xType='channels';
            			yType='';
            			figType='';
                        numSubX=1;
                        numSubY=1;
            		end
            	end
            end
            
            if(plotTopo)
                emptyTopoData=[];
                emptyTopoData.r=nan(1,numOpt);
                emptyTopoData.p=nan(1,numOpt);
                emptyTopoData.rho=nan(1,numOpt);
                emptyTopoData.pval=nan(1,numOpt);
                emptyTopoData.n=nan(1,numOpt);
            end
            
            switch(figType)
                case 'channels'
                    for i=1:numOpt
                        sH{i,1}.h=figure(1200+i);
                        clf(sH{i,1}.h);
                        dcm_obj = datacursormode(sH{i,1}.h);
                        set(dcm_obj,'UpdateFcn',@app.myDataTipUpdateFcn);
                        for s=1:(numSubX*numSubY)
                            xInd=rem(s,numSubX);
                            if(xInd==0)
                                xInd=numSubX;
                            end
                            h=subplot(numSubY,numSubX,s);
                            sH{i,1}.subH{floor((s-1)/numSubX)+1,xInd}=h;
                            legend(h, 'off');
                        end
                    end
                case 'bioM'
                    for i=1:numBioM
                        sH{i,1}.h=figure(1200+i);
                        clf(sH{i,1}.h);
                        dcm_obj = datacursormode(sH{i,1}.h);
                        set(dcm_obj,'UpdateFcn',@app.myDataTipUpdateFcn);
                        for s=1:(numSubX*numSubY)
                            xInd=rem(s,numSubX);
                            if(xInd==0)
                                xInd=numSubX;
                            end
                            h=subplot(numSubY,numSubX,s);
                            sH{i,1}.subH{floor((s-1)/numSubX)+1,xInd}=h;
                            if(plotTopo)
                               topoData{i,1}.subH{floor((s-1)/numSubX)+1,xInd}=emptyTopoData;
                            end
                            legend(h, 'off');
                        end
                    end
                case 'bio,channels'
                    for i=1:numOpt
                        for b=1:numBioM
                            sH{i,b}.h=figure(1200+i+50*b);
                            clf(sH{i,b}.h);
                            dcm_obj = datacursormode(sH{i,b}.h);
                            set(dcm_obj,'UpdateFcn',@app.myDataTipUpdateFcn);
                            for s=1:(numSubX*numSubY)
                                xInd=rem(s,numSubX);
                                if(xInd==0)
                                    xInd=numSubX;
                                end
                                h=subplot(numSubY,numSubX,s);
                                sH{i,b}.subH{floor((s-1)/numSubX)+1,xInd}=h;
                                legend(h, 'off');
                            end
                        end
            
                    end
                case 'groupby,bio'
                    for i=1:numCurInfoG
                        for b=1:numBioM
                            sH{i,b}.h=figure(1200+i+50*b);
                            clf(sH{i,b}.h);
                            dcm_obj = datacursormode(sH{i,b}.h);
                            set(dcm_obj,'UpdateFcn',@app.myDataTipUpdateFcn);
                            for s=1:(numSubX*numSubY)
                                xInd=rem(s,numSubX);
                                if(xInd==0)
                                    xInd=numSubX;
                                end
                                h=subplot(numSubY,numSubX,s);
                                sH{i,b}.subH{floor((s-1)/numSubX)+1,xInd}=h;
                                if(plotTopo)
                                   topoData{i,b}.subH{floor((s-1)/numSubX)+1,xInd}=emptyTopoData;
                                end
                                legend(h, 'off');
                            end
                        end
            
                    end
                otherwise
                    sH{1,1}.h=figure(1200);
                    clf(sH{1,1}.h);
                    dcm_obj = datacursormode(sH{1,1}.h);
                    set(dcm_obj,'UpdateFcn',@app.myDataTipUpdateFcn);
                    for s=1:(numSubX*numSubY)
                        xInd=rem(s,numSubX);
                        if(xInd==0)
                            xInd=numSubX;
                        end
                        h=subplot(numSubY,numSubX,s);
                        sH{1,1}.subH{floor((s-1)/numSubX)+1,xInd}=h;
                        if(plotTopo)
                           topoData{1,1}.subH{floor((s-1)/numSubX)+1,xInd}=emptyTopoData;
                        end
                        legend(h, 'off');
                    end
            end
            
            curSx=1;
            curSy=1;
            curFigIdx=[1,1];
            
            
            curInfoStr=ExFNIRS.settings.curInfoStr;
            
            curUinfoStr="";
            curUinfoStr(1)=[];
            
            plotInfoStr=false;


            if(ExFNIRS.settings.within_sub_avg_mode==3)
                dataH=ExFNIRS.dataHierarchy;
            elseif(ExFNIRS.settings.within_sub_avg_mode==2)
                dataH='SubjectID';
            else
                dataH=[];
            end


            for chIdx=1:numOpt
            
                if(strcmp(figType,'channels'))
                    figure(sH{chIdx}.h);
                    curFigIdx=[chIdx,1];
                elseif(strcmp(xType,'channels'))
                    curSx=chIdx;
                elseif(strcmp(yType,'channels'))
                    curSy=chIdx;
                end
            
                ch=selOpt(chIdx);
                legendGFXhandles{1}=[];
                legendGFXstrs{1}=cell(0);
            
                if(plotGroupByBioM)
                    num2Plot=numBioM;
                elseif(numUgroups>1)
                    num2Plot=numUgroups;
                end
            
                %barChartTimes=times;
            
                pointStrs=cell(num2Plot,1);
                gAStrs=cell(num2Plot,1);
                gAerrStrs=cell(num2Plot,1);
            
                for b=1:numBioM
                    bioM=selectedBioM(b);
                    if(iscell(bioM))
                        bioM=bioM{1};
                    end
            
                    if(strcmp(figType,'bio,channels'))
                        figure(sH{chIdx,b}.h);
                        datacursormode(sH{chIdx,b}.h)
                        curFigIdx=[chIdx,b];
                    elseif(strcmp(figType,'bioM'))
                        figure(sH{b,1}.h);
                        datacursormode(sH{b,1}.h)
                        curFigIdx=[b,1];
                    elseif(strcmp(xType,'bioM'))
                        curSx=b;
                    elseif(strcmp(yType,'bioM'))
                        curSy=b;
                    end
            
            
                    if(numUgroups>1)
                        curChart=b;
                    else
                        curChart=1;
                    end
            
            
                    for g=1:numGroups
                        curGrand=ExFNIRS.gby(g).gbyGrandBar;
                        curTable=ExFNIRS.gby(g).gbyTables;
            
                        if(isempty(curGrand)||isempty(curTable))
                           continue;
                        end
            
                        curData=curTable(:,curInfoStr);
            
                        curData=table2array(curData);
            
                        if(isstring(curData)||ischar(curData))
                           %warning('Strings return count');
                           curData=string(curData);
                           [uDataX,~,curDataIdx]=unique(curData);
                           if(~isempty(uDataX))
                               curUinfoStr(end+1:end+length(uDataX))=uDataX;
                               [curUinfoStr,~,curUidxX]=unique(curUinfoStr);
                               curData=nan(size(curDataIdx));
                               for udx=1:length(uDataX)
                                   cdx=find(ismember(curUinfoStr,uDataX(udx)));
                                   curData(curDataIdx==udx)=cdx;
                               end
                               %plotFeature='String';
                               % return;
                               plotInfoStr=true;
                           end
                        end
                        curData(curData==-9999)=nan;
            
                        if(useCurInfoGroup)
                            curGroupInfoIdx=uCurIdx(g);
                            curGroupIdxOffset=(curGroupInfoIdx-1)*numChartTimes;
                            curUgroupIdx=uCurGIdxCount(g);
                        else
                            curGroupInfoIdx=1;
                            curGroupIdxOffset=0;
                            curUgroupIdx=g;
                        end
            
                        if(useCurInfoGroup)
                            if(strcmp(xType,'groupby'))
                                curSx=curGroupInfoIdx;
                            elseif(strcmp(yType,'groupby'))
                                curSy=curGroupInfoIdx;
                            end
                        end
            
                        if(strcmp(xType,'ugroup'))
                            curSx=curUgroupIdx;
                        elseif(strcmp(yType,'ugroup'))
                            curSy=curUgroupIdx;
                        end
            
            
                        if(strcmp(figType,'groupby,bio'))
                            figure(sH{curGroupInfoIdx,b}.h);
                            datacursormode(sH{curGroupInfoIdx,b}.h)
                            curFigIdx=[curGroupInfoIdx,b];
                        end
            
                        if(plotInfoStr)
                            [curHAvg,outH]=pf2_base.hierarchicalAverage(curData,curTable(:,dataH),@mode);
                        elseif(strcmp(plotFeature,'Count'))
                            [curHAvg,outH]=pf2_base.hierarchicalAverage(curData,curTable(:,dataH),@nnz);
                        elseif(strcmp(plotFeature,'Mean'))
                            [curHAvg,outH]=pf2_base.hierarchicalAverage(curData,curTable(:,dataH),@nanmean);
                        elseif(strcmp(plotFeature,'Median'))
                            [curHAvg,outH]=pf2_base.hierarchicalAverage(curData,curTable(:,dataH),@nanmedian);
            
                        else
                            error('Unknown parameter');
                            %curHAvg=nanmedian(hierarchicalAverage(curData,curTable(:,dataH),@nanmedian));
                        end
            
                        if(numChartTimes==0)
                            error('No data in selected time range!');
                        end
            
                        for t=1:numChartTimes
                            if(strcmp(xType,'time'))
                                curSx=t;
                            elseif(strcmp(yType,'time'))
                                curSy=t;
                            end
            
                            curPlotHandle=sH{curFigIdx(1),curFigIdx(2)}.subH{curSy,curSx};
                            lastSubPlot=(curSy==numSubY&&curSx==numSubX);
                            hold(curPlotHandle,'on')
            
            
                            [timeIdx,timeIdxRev]=ismember(round(curGrand.time),barChartTimes(t));
                            timeIdxRev=timeIdxRev(timeIdxRev>0);
            
                            if(isempty(timeIdxRev))
            
                                continue;
                            end
            
            
                          switch(ExFNIRS.settings.ChannelMode)
                              case 'fNIR'
                                  data2plot=curGrand.(bioM);
                              case 'ROI'
                                  if(~pf2_base.isnestedfield(curGrand,'ROI.HbO.data'))
                                      error('ROI data must be calculated using a build ROI step');
                                  end
            
                                  data2plot=curGrand.ROI.(bioM);
                              case 'Aux'
                                  data2plot=curGrand.Aux.(bioM);
                          end
                            curFeatureY=permute(data2plot.data(timeIdx,ch,:),[3,1,2]);
            
                            if(strcmp(plotFeature,'Count')||strcmp(plotFeature,'N'))
                                [curFeatureY]=pf2_base.hierarchicalAverage(curFeatureY,curGrand.info.Hierarchy,@nnz);
                            elseif(strcmp(plotFeature,'Mean'))
                                [curFeatureY]=pf2_base.hierarchicalAverage(curFeatureY,curGrand.info.Hierarchy,@nanmean);
                            elseif(strcmp(plotFeature,'Median'))
                                [curFeatureY]=pf2_base.hierarchicalAverage(curFeatureY,curGrand.info.Hierarchy,@nanmedian);
                            else
                                error('Unknown parameter');
                                %curHAvg=nanmedian(hierarchicalAverage(curData,curTable(:,dataH),@nanmedian));
                            end
            
                            if(length(curFeatureY)~=length(curHAvg))
                                if(length(curFeatureY)>length(curHAvg))
                                    curFeatureY=curFeatureY(ismember(curGrand.info.Observation,outH));
                                else
                                    temp=nan(size(curHAvg));
                                    temp(ismember(outH,curGrand.info.Observation))=curFeatureY;
                                    curFeatureY=temp;
                                end
                            end
            
                            if(~plotGroupByBioM||numBioM==1)
                                 %gAStrs{curUgroupIdx,curChart}=sprintf('%s',gbyStrs{curUgroupIdx});
                                 sColor=cIndex(curUgroupIdx,:);
                            elseif(numBioM>1)
                                 %gAStrs{b,curChart}=sprintf('%s',selectedBioM{b});
                                 sColor=cIndex(b,:);
                            end


                            if(ExFNIRS.settings.plot_scatter_nonparametric)
            
                                topoMode='rhocorr';
            
                                validIdx=sum([isnan(curHAvg),isnan(curFeatureY)],2)==0;
                                validIdx=validIdx&(~isempty(curHAvg)&&~isempty(curFeatureY));
                                xVals=curHAvg(validIdx);
                                yVals=curFeatureY(validIdx);
            
                                [~,p] = sort(xVals,'descend');
                                r = 1:length(xVals);
                                r(p) = r;
                                xVals=r';
            
                                [~,p] = sort(yVals,'descend');
                                r = 1:length(yVals);
                                r(p) = r;
                                yVals=r';
            
                                validIdx=sum([isnan(xVals),isnan(yVals)],2)==0;
                                validIdx=validIdx&(~isempty(curHAvg)&&~isempty(curFeatureY));
            
                                xVals=xVals(validIdx);
                                yVals=yVals(validIdx);
                                N=length(xVals);
                            else
                                validIdx=sum([isnan(curHAvg),isnan(curFeatureY)],2)==0;
                                validIdx=validIdx&(~isempty(curHAvg)&&~isempty(curFeatureY));
                                xVals=curHAvg(validIdx);
                                yVals=curFeatureY(validIdx);
                                N=length(xVals);
                                topoMode='rcorr';
                            end


                             if(plotInfoStr)
                               uData=[xVals,yVals];
                               microvar=(nanmax(yVals)-nanmin(yVals))/100;
                                [uRows,~,uRowIdx]=unique(uData,'rows');
                                bincounts = histc(uRowIdx,1:max(uRowIdx));
                                for xv=1:length(bincounts)
                                    if(bincounts(xv)>1)
                                       stepsize=0.8/(bincounts(xv)-1);
                                       offset=(-0.4:stepsize:0.4);
                                       if(bincounts(xv)<10)
                                           offset=offset/(10-bincounts(xv));
                                       else
                                            offset=(abs(offset).^1.5).*sign(offset);
                                       end
                                       xVals(uRowIdx==xv)=[uRows(xv,1)+offset];
                                       yVals(uRowIdx==xv)=[uRows(xv,2)-microvar+g/numGroups*(2*microvar)];
                                    end
            
                                end
                             end
            
                            if(ExFNIRS.settings.plot_scatter_flipxy)
                                temp=xVals;
                                xVals=yVals;
                                yVals=temp;
                            end
            
                            if(plotTopo)
            
                                switch(yType)
                                    case 'ugroup'
                                        rowLabel=gbyShortStrs{g};
                                    case 'groupby'
                                        rowLabel=curInfoGby{g};
                                    case 'bioM'
                                        rowLabel=sprintf('\\Delta[%s]',bioM);
                                    case 'time'
                                        rowLabel=sprintf('t=%i',round(barChartTimes(t)));
                                    otherwise
                                        rowLabel='Unkown';
                                end
            
                                switch(xType)
                                    case 'ugroup'
                                        titleSTR=gbyShortStrs{g};
                                    case 'groupby'
                                        titleSTR=curInfoGby{g};
                                    case 'bioM'
                                        titleSTR=sprintf('\\Delta[%s]',bioM);
                                    case 'time'
                                        titleSTR=sprintf('t=%i',round(barChartTimes(t)));
                                    otherwise
                                        titleSTR='Unkown';
                                end


                                if(~isempty(xVals)&&~isempty(yVals))
                                     [rho,pval] = corr(xVals,yVals,'Type','Spearman');
                                     [r,p]=corr(xVals,yVals,'Type','Pearson');
            
                                     curData=topoData{curFigIdx(1),curFigIdx(2)}.subH{curSy,curSx};
            
                                     curData.p(chIdx)=p;
                                     curData.r(chIdx)=r;
                                     curData.rho(chIdx)=rho;
                                     curData.pval(chIdx)=pval;
                                     curData.N(chIdx)=N;
            
                                     topoData{curFigIdx(1),curFigIdx(2)}.subH{curSy,curSx}=curData;
            
                                     if(chIdx==numOpt)
                                         if(ExFNIRS.settings.plot_scatter_nonparametric)
                                             curP=curData.pval;
                                             curR=curData.rho;
                                             clrBtitle='rho';
                                         else
                                             curP=curData.p;
                                             curR=curData.r;
                                             clrBtitle='r';
                                         end
            
                                         curDf=curData.N-2;
                                         m=length(curP);
                                        [curQ,curK]=performFDR(app, curP,ExFNIRS.settings.topoSigThrehold{2});
                                        [curQ_rev,curK_rev]=performFDR_twostep(app, curP,ExFNIRS.settings.topoSigThrehold{2});
            
                                        curT=(curR./sqrt((1-curR.^2)/(N-2)));
            
                                        estimate_tPval=tinv(ones(size(curT(:)))*(1-ExFNIRS.settings.topoSigThrehold{2}), curDf(:));
                                        estimate_tPval_q=tinv(ones(size(curT(:)))*(1-ExFNIRS.settings.topoSigThrehold{2}*curK/m), curDf(:));
                                        estimate_tPval_qrev=tinv(ones(size(curT(:)))*(1-ExFNIRS.settings.topoSigThrehold{2}*curK_rev/m), curDf(:));
            
                                        estimate_rPval=estimate_tPval(:)./(sqrt(curDf(:)+estimate_tPval(:).^2));
                                        estimate_rPval_q=estimate_tPval_q(:)./(sqrt(curDf(:)+estimate_tPval_q(:).^2));
                                        estimate_rPval_qrev=estimate_tPval_qrev(:)./(sqrt(curDf(:)+estimate_tPval_qrev(:).^2));
            
                                        switch(ExFNIRS.settings.topoSigThrehold{1})
                                            case 'p'
                                                curpthresh=ExFNIRS.settings.topoSigThrehold{2};
                                            case 'q'
                                                estimate_rPval=estimate_rPval_q;
                                                curpthresh=ExFNIRS.settings.topoSigThrehold{2}*curK/m;
                                            case 'q-twostep'
                                                estimate_rPval=estimate_rPval_qrev;
                                                curpthresh=ExFNIRS.settings.topoSigThrehold{2}*curK_rev/m;
                                        end
            
            
                                        if(any(curP<=curpthresh))
            
            
                                            if(any(curP>curpthresh&curR>estimate_rPval'))
                                                minR1=min(estimate_rPval(curR>estimate_rPval'));
                                                curR(curP>curpthresh&curR>estimate_rPval')=minR1/2;
                                            else
                                                minR1=min(estimate_rPval(curR>estimate_rPval'));
                                            end
            
            
                                            if(any(curP>curpthresh&curR<-1*estimate_rPval'))
                                                minR2=max(-1*estimate_rPval(curR<(-1*estimate_rPval')));
                                                curR(curP>curpthresh&curR<(-1*estimate_rPval'))=minR2/2;
                                            else
                                                minR2=max(-1*estimate_rPval(curR<(-1*estimate_rPval')));
                                            end
            
                                            if(isempty(minR2))
                                                minR2=-1*minR1;
                                            elseif(isempty(minR1))
                                                minR1=-1*minR2;
                                            end
            
            
                                            switch(ExFNIRS.settings.ChannelMode)
                                                case 'fNIR'
                                                     axes(curPlotHandle);
                                                     processFNIRS2.Data.Plot.InterpolateValues([],curR,[minR1,minR2],[],1,titleSTR,clrBtitle);
                                                    %interpolateNIR(abs(curR),'Mode',topoMode,'fontSize',12,'transparent',true,'lowerThreshold',min([abs(minR2),minR1]),'TitleText',titleSTR,'ChannelLabels',true)%,7,11,2,1,false,'[Hb-Oxy] Natural High Vs. Low',12,'hot',true)
                                                case 'ROI'
                                                    roiInfo=ExFNIRS.currentROI;
                                                    interpolateNIR(mapROIvaluesToCh(roiInfo,abs(curR)),'Mode','corr','fontSize',12,'transparent',true,'lowerThreshold',min([abs(minR2),minR1]),'TitleText',titleST,'ChannelLabels',true)%,7,11,2,1,false,'[Hb-Oxy] Natural High Vs. Low',12,'hot',true)
                                            end
                                            if(curSx==1) % first column
                                                curAxes=curPlotHandle;
                                                axesPos=curAxes.OuterPosition;
                                                th=annotation(gcf,'textbox',[0,axesPos(2),axesPos(3),axesPos(4)/2],'Items',rowLabel,'FitBoxToText','on');
                                            end
                                        else
                                            plot(curPlotHandle,0,0);
                                            curAxes=curPlotHandle;
                                            axes(curPlotHandle);
                                            axesPos=curAxes.OuterPosition;
                                            axis off
                                            title(sprintf('%s_N_S',titleSTR));
                                            if(curSx==1) % first column
                                                th=annotation(gcf,'textbox',[0,max(axesPos(2),0.05),axesPos(3),axesPos(4)/2],'Items',rowLabel,'FitBoxToText','on');
                                            end
                                        end
                                     end
                                elseif(chIdx==numOpt)
                                    plot(curPlotHandle,0,0);
                                    curAxes=curPlotHandle;
                                    axes(curPlotHandle);
                                    axesPos=curAxes.OuterPosition;
                                    axis off
                                    title(sprintf('%s_N_S',titleSTR));
                                    if(curSx==1) % first column
                                        th=annotation(gcf,'textbox',[0,axesPos(2),axesPos(3),axesPos(4)/2],'Items',rowLabel,'FitBoxToText','on');
                                    end
                                end
            
                            else
                                sHdots=scatter(curPlotHandle,xVals,yVals,25,sColor,'filled');
                                if(~plotGroupByBioM)
                                   pointStrs{curUgroupIdx}= gbyStrs{g};
                                   curPointStr=pointStrs{curUgroupIdx};
                                   %if(ExFNIRS.settings.plot_legend_mode==3||(ExFNIRS.settings.plot_legend_mode==2&&lastSubPlot))
                                       sH{curFigIdx(1),curFigIdx(2)}.legendHandles{curSy,curSx}.h{curUgroupIdx}=sHdots;
                                   %end
                                else
                                   pointStrs{b}=bioM;
                                   curPointStr=bioM;
                                  %if(ExFNIRS.settings.plot_legend_mode==3||(ExFNIRS.settings.plot_legend_mode==2&&lastSubPlot))
                                       sH{curFigIdx(1),curFigIdx(2)}.legendHandles{curSy,curSx}.h{b}=sHdots;
                                   %end
                                end
            
                                tagStr=sprintf('%s',curPointStr);
                                set(sHdots,'tag',tagStr);
            
            
                                if(plotGroupByBioM&&numBioM>1)
                                    curFeatureString=sprintf('\\Delta[X]');
                                else
                                    curFeatureString=sprintf('\\Delta[%s]',bioM);
                                end
            
                                if(ExFNIRS.settings.plot_scatter_nonparametric)
                                    curFeatureString=sprintf('Rank %s',curFeatureString);
                                end
            
                                switch(xType)
                                    case 'time'
                                        title(curPlotHandle,sprintf('t=%i',round(barChartTimes(t))));
                                    case 'groupby'
                                        title(curPlotHandle,curInfoGby{g});
                                    case 'channels'
                                        title(curPlotHandle,sprintf('Opt. %i',ch));
                                    case 'bioM'
                                        title(curPlotHandle,bioM);
                                end
            
            
                                if(ExFNIRS.settings.plot_scatter_flipxy)
            
                                    switch(yType)
                                        case 'time'
                                            xlabel(curPlotHandle,{sprintf('t=%i',round(barChartTimes(t)));curFeatureString});
                                        case 'groupby'
                                            xlabel(curPlotHandle,{curInfoGby{curUgroupIdx};curFeatureString});
                                        case 'channels'
                                            xlabel(curPlotHandle,{sprintf('Opt. %i',ch);curFeatureString});
                                        case 'bioM'
                                            if(numBioM>1)
                                                xlabel(curPlotHandle,{bioM,curFeatureString});
                                            else
                                                xlabel(curPlotHandle,curFeatureString);
                                            end
                                        otherwise
                                            ylabel(curPlotHandle,{sprintf('Opt. %i',ch);curFeatureString});
                                    end
                                    if(ExFNIRS.settings.plot_scatter_nonparametric)
                                        ylabel(curPlotHandle,sprintf('Rank %s',curInfoStr));
                                    else
                                        ylabel(curPlotHandle,curInfoStr);
                                    end
            
                                else
            
            
                                    switch(yType)
                                        case 'time'
                                            ylabel(curPlotHandle,{sprintf('t=%i',round(barChartTimes(t)));curFeatureString});
                                        case 'groupby'
                                            ylabel(curPlotHandle,{curInfoGby{curUgroupIdx};curFeatureString});
                                        case 'channels'
                                            ylabel(curPlotHandle,{sprintf('Opt. %i',ch);curFeatureString});
                                        case 'bioM'
                                            if(numBioM>1)
                                                ylabel(curPlotHandle,{bioM,curFeatureString});
                                            else
                                                ylabel(curPlotHandle,curFeatureString);
                                            end
                                        otherwise
                                            ylabel(curPlotHandle,{sprintf('Opt. %i',ch);curFeatureString});
                                    end
                                    if(ExFNIRS.settings.plot_scatter_nonparametric)
                                        xlabel(curPlotHandle,sprintf('Rank %s',curInfoStr));
                                    else
                                        xlabel(curPlotHandle,curInfoStr);
                                    end
                                end


                                if(ExFNIRS.settings.plot_scatter_line||ExFNIRS.settings.plot_scatter_err)
                                    if(N>2)
                                        [coefficients,PolyS] = polyfit(xVals, yVals, 1);
                                        CI = pf2_base.external.polyparci(coefficients,PolyS);
                                        xFit = linspace(min(xVals), max(xVals), 1000);
                                        [yFit,deltaY] = polyval(coefficients, xFit,PolyS);
            
                                        if(ExFNIRS.settings.plot_scatter_extend)
                                            curXlim=xlim(curPlotHandle);
                                            xFitExtend = linspace(min(curXlim), max(curXlim), 1000);
                                            yFitExtend = polyval(coefficients, xFitExtend);
                                        end
            
                                        errMulitply=ExFNIRS.settings.plot_bar_err_mult;
                                        %CI=[0,0;0,0];
            
                                        yEst=polyval(coefficients, xVals,PolyS);
                                        yDiff=yVals-yEst;
                                        SD=std(yDiff);
            
                                        %SD=sqrt(N)*(CI(:,2)-CI(:,1))'/3.92;
                                        SEM=SD/sqrt(N);
            
                                        switch(ExFNIRS.settings.plot_scatter_err_feature)
                                            case 'SEM'
                                                %yCI_Upper = polyval(coefficients+SEM*errMulitply, xFit);
                                                %yCI_Lower = polyval(coefficients-SEM*errMulitply, xFit);
                                                yCI_Upper = yFit+SEM*errMulitply;
                                                yCI_Lower = yFit-SEM*errMulitply;
                                            case 'SD'
                                                %yCI_Upper = polyval(coefficients+SD*errMulitply, xFit);
                                                %yCI_Lower = polyval(coefficients-SD*errMulitply, xFit);
                                                yCI_Upper = yFit+SD*errMulitply;
                                                yCI_Lower = yFit-SD*errMulitply;
                                            case '95%CI'
                                                yCI_Upper = polyval(CI(1,:), xFit);
                                                yCI_Lower = polyval(CI(2,:), xFit);
                                            case '50%PI'
                                                yCI_Upper = yFit+deltaY*(tinv(0.50,(N-1)));
                                                yCI_Lower = yFit-deltaY*(tinv(0.50,(N-1)));
                                            case '67%PI'
                                                yCI_Upper = yFit+deltaY*(tinv(0.67,(N-1)));
                                                yCI_Lower = yFit-deltaY*(tinv(0.67,(N-1)));
                                            case '90%PI'
                                                yCI_Upper = yFit+deltaY*(tinv(0.90,(N-1)));
                                                yCI_Lower = yFit-deltaY*(tinv(0.90,(N-1)));
                                            case '95%PI'
                                                yCI_Upper = yFit+deltaY*(tinv(0.95,(N-1)));
                                                yCI_Lower = yFit-deltaY*(tinv(0.95,(N-1)));
                                        end
                                    end
                                end


                                if(ExFNIRS.settings.plot_scatter_err&&N>2)
                                    errStyle=ExFNIRS.settings.plot_scatter_error_style;
            
                                    plotShaded=false;
            
                                    switch(errStyle)
                                        case 'Dashed'
                                            errStyle='--';
                                            lineWidth=2;
                                        case 'Fine'
                                            errStyle='-';
                                            lineWidth=0.5;
                                        case 'Shaded'
                                            errStyle='-';
                                            lineWidth=0.5;
                                            plotShaded=true;
                                        otherwise
                                            error('Unspecified error style');
                                    end
            
                                    errColor=sColor+(1-sColor)*0.55;
                                    if(plotShaded)
                                          errAlpha=0.15;
                                          yPatch=[yCI_Lower,fliplr(yCI_Upper)];
                                          xPatch=[xFit,fliplr(xFit)];
                                          %xPatch(isnan(yPatch))=[];
                                          %yPatch(isnan(yPatch))=[];
            
                                          h=patch(curPlotHandle,xPatch,yPatch,-1,'facecolor',errColor,'edgecolor','none','facealpha',errAlpha);
                                          set(h,'HandleVisibility','off');
                                          set(h,'HitTest','off');
                                          set(h.Annotation.LegendInformation,'IconDisplayStyle','off');
                                    end
            
                                    h=plot(curPlotHandle,xFit,yCI_Upper,'LineStyle',errStyle,'Color',errColor,'LineWidth',lineWidth);
                                    set(h.Annotation.LegendInformation,'IconDisplayStyle','off');
                                    h=plot(curPlotHandle,xFit,yCI_Lower,'LineStyle',errStyle,'Color',errColor,'LineWidth',lineWidth);
                                    set(h.Annotation.LegendInformation,'IconDisplayStyle','off');
                                else
                                      if(numUgroups>1||numBioM==1)
                                           gAErrStrs{curGroupInfoIdx}='';
                                      elseif(numBioM>1)
                                           gAErrStrs{b}='';
                                      end
                                end
            
                                if(ExFNIRS.settings.plot_scatter_line&&N>2)
                                    hold(curPlotHandle,'on')
                                    if(ExFNIRS.settings.plot_scatter_extend)
                                        gaH=plot(curPlotHandle,xFitExtend, yFitExtend, 'r-', 'LineWidth', 2,'Color',sColor);
                                    else
                                        gaH=plot(curPlotHandle,xFit, yFit, 'r-', 'LineWidth', 2,'Color',sColor);
                                    end
            
                                    set(gaH.Annotation.LegendInformation,'IconDisplayStyle','off');
            
                                    [rho,pval] = corr(xVals,yVals,'Type','Spearman');
            
            
                                    [r,p]=corr(xVals,yVals,'Type','Pearson');
            
                                    if(~plotGroupByBioM)
                                       fitStr=gbyStrs{g};
                                       gAStrs{curUgroupIdx}= fitStr;
                                       %if(ExFNIRS.settings.plot_legend_mode==3||(ExFNIRS.settings.plot_legend_mode==2&&lastSubPlot))
                                           sH{curFigIdx(1),curFigIdx(2)}.legendHandles{curSy,curSx}.h{curUgroupIdx}=gaH;
                                       %end
                                    else
                                       gAStrs{b}=bioM;
                                       fitStr=bioM;
                                       %if(ExFNIRS.settings.plot_legend_mode==3||(ExFNIRS.settings.plot_legend_mode==2&&lastSubPlot))
                                           sH{curFigIdx(1),curFigIdx(2)}.legendHandles{curSy,curSx}.h{b}=gaH;
                                       %end
                                    end
            
                                    tagStr=sprintf('%s (N %i)\nRho=%.3f, p=%.4f\nr=%.3f p=%.4f',fitStr,N,rho,pval,r,p);
                                    set(gaH,'tag',tagStr);
                                elseif(~ExFNIRS.settings.plot_scatter_line)
                                    %sH{curFigIdx(1),curFigIdx(2)}.legendHandles{curSy,curSx}.h=cell(0);
                                    %gAStrs=cell(0);
                                end
            
            
                                curLgdHandles=sH{curFigIdx(1),curFigIdx(2)}.legendHandles{curSy,curSx}.h(:);
                                numCurLgd=length(curLgdHandles);
                                numFilled=0;
                                for i=1:numCurLgd
                                    numFilled=numFilled+~isempty(curLgdHandles{i});
                                end
            
                                if(ExFNIRS.settings.ylim_fixed)
                                    ylim(curPlotHandle,'auto');
                                    cylim=ylim(curPlotHandle);
                                    ExFNIRS.settings.ylim_fixed_min=min(ExFNIRS.settings.ylim_fixed_min,cylim(1));
                                    ExFNIRS.settings.ylim_fixed_max=max(ExFNIRS.settings.ylim_fixed_max,cylim(2));
            
                                    xlim(curPlotHandle,'auto');
                                    cxlim=xlim(curPlotHandle);
                                    xlim_fixed_min=min(xlim_fixed_min,cxlim(1));
                                    xlim_fixed_max=max(xlim_fixed_max,cxlim(2));
                                elseif(ExFNIRS.settings.ylim_manual)
                                    if(ExFNIRS.settings.plot_scatter_flipxy)
                                        if(~plotCount)
                                            xlim(curPlotHandle,[ExFNIRS.settings.ylim_manual_min,ExFNIRS.settings.ylim_manual_max]);
                                        else
                                            xlim(curPlotHandle,[0,ExFNIRS.settings.ylim_manual_max]);
                                        end
                                    else
                                        if(~plotCount)
                                            ylim(curPlotHandle,[ExFNIRS.settings.ylim_manual_min,ExFNIRS.settings.ylim_manual_max]);
                                        else
                                            ylim(curPlotHandle,[0,ExFNIRS.settings.ylim_manual_max]);
                                        end
                                    end
                                elseif(plotCount)
                                    if(ExFNIRS.settings.plot_scatter_flipxy)
                                        cxlim=xlim(curPlotHandle);
                                        xlim(curPlotHandle,[0,cxlim(2)]);
                                    else
                                        cylim=ylim(curPlotHandle);
                                        ylim(curPlotHandle,[0,cylim(2)]);
                                    end
            
                                else
                                    ylim(curPlotHandle,'auto');
                                end
                            end
                        end
            
                    end
                end
            end
            
            if(plotCount)
                if(ExFNIRS.settings.plot_scatter_flipxy)
                    xlim_fixed_min=0;
                else
                    ExFNIRS.settings.ylim_fixed_min=0;
                end
            end
            
            
            for i=1:size(sH,1)
                for b=1:size(sH,2)
                    for x=1:numSubX
                        for y=1:numSubY
                            if(ExFNIRS.settings.ylim_fixed&&~plotTopo)
                                ylim(sH{i,b}.subH{y,x},[ExFNIRS.settings.ylim_fixed_min,ExFNIRS.settings.ylim_fixed_max]);
                                xlim(sH{i,b}.subH{y,x},[xlim_fixed_min,xlim_fixed_max]);
                            end


                            if(plotInfoStr&&ExFNIRS.settings.plot_scatter_flipxy)
                                ylim(sH{i,b}.subH{y,x},[0,length(curUinfoStr)]+0.5);
                                yticks(sH{i,b}.subH{y,x},1:(length(curUinfoStr)));
                               yticklabels(sH{i,b}.subH{y,x},curUinfoStr);
            
                            elseif(plotInfoStr)
                                xlim(sH{i,b}.subH{y,x},[0,length(curUinfoStr)]+0.5);
                                xticks(sH{i,b}.subH{y,x},1:(length(curUinfoStr)));
                               xticklabels(sH{i,b}.subH{y,x},curUinfoStr);
            
                            end


                            if((ExFNIRS.settings.plot_legend_mode==3||(ExFNIRS.settings.plot_legend_mode==2&&(x==numSubX)&&y==numSubY))&&~plotTopo)
                                lgStrs=[];
                                for k=1:length(pointStrs)
                                   if(isnumeric(pointStrs{k}))
                                       pointStrs{k}='';
                                   end
                                   lgStrs=[lgStrs;pointStrs(k)];
                                end
            
                                legend(sH{i,b}.subH{y,x},pointStrs(:),'Location', 'Best');
                            end
            
                            hold(sH{i,b}.subH{y,x},'off')
                        end
                    end
            
                    addDebugAnnotation(app, sH{i,b}.h);
            
            
                    if(ExFNIRS.settings.plot_scatter_nonparametric)
                        suptStr=sprintf('Rank %s',curInfoStr);
                    else
                        suptStr=curInfoStr;
                    end
            
                    switch(figType)
                        case 'bioM'
                            suptStr=sprintf('%s: %s',suptStr,selectedBioM{i});
                            pf2_base.external.suptitle(sH{i,b}.h,suptStr);
                        case 'channels'
                            suptStr=sprintf('%s: Optode %i',suptStr,selOpt(i));
                            pf2_base.external.suptitle(sH{i,b}.h,suptStr);
                        case 'bio,channels'
                            suptStr=sprintf('%s: Optode %i [%s]',suptStr,selOpt(i),selectedBioM{b});
                            pf2_base.external.suptitle(sH{i,b}.h,suptStr);
                        case 'groupby,bio'
                            suptStr=sprintf('%s: %s [%s]',suptStr,uCurInfoG{i},selectedBioM{b});
                            pf2_base.external.suptitle(sH{i,b}.h,suptStr);
                        otherwise
                            pf2_base.external.suptitle(suptStr);
                    end
            
                    if(plotTopo)
                       sigStr=sprintf('Thresholded at %s=%.2f',ExFNIRS.settings.topoSigThrehold{1},ExFNIRS.settings.topoSigThrehold{2});
                       th=annotation(sH{i,b}.h,'textbox',[0,1,0,0],'Items',sigStr,'FitBoxToText','on');
                    end
                end
            end
        end

        % Button pushed function: pushbutton_plot_scatter_topo
        function pushbutton_plot_scatter_topo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_plot_scatter_topo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global ExFNIRS
            switch(ExFNIRS.settings.ChannelMode)
                case 'fNIR'
                    strs=get(app.listbox_optode,'Items');
                    if(iscell(strs)||ismatrix(strs))
                        set(app.listbox_optode,'Value',[1:size(strs,1)]);
                    end
                case 'ROI'
            
                case 'Aux'
            
            end
            
            pushbutton_plot_scatter_Callback(app,true);
        end

        % Button pushed function: pushbutton_plot_temporal
        function pushbutton_plot_temporal_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_plot_temporal (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global ExFNIRS
            
            if(ExFNIRS.UpdateNeeded)
                updateSelectedTable(app);
            end
            
            if(~isfield(ExFNIRS,'gby'))
                warning('No groups match selection criteria');
                return;
            end
            curInfoGroup=ExFNIRS.settings.curInfoGroup;
            
            gbyVars=ExFNIRS.groupByVars;
            if(~isempty(curInfoGroup)&&~strcmp(curInfoGroup,'(Time)'))
                [ismem,idx]=ismember(curInfoGroup,gbyVars);
                if(ismem)
                    gbyVars(idx)=[];
                    useCurInfoGroup=true;
                else
                    useCurInfoGroup=false;
                end
            else
                useCurInfoGroup=false;
            end
            
            
            numGroups=length(ExFNIRS.gby);
            
            biomStrs=get(app.listbox_biomarker,'Items');
            selBioM=get(app.listbox_biomarker,'Value');
            selectedBioM=biomStrs(selBioM);
            numBioM=length(selBioM);
            
            optStrs=get(app.listbox_optode,'Items');
            selOpt=get(app.listbox_optode,'Value');
            selectedOptStr=optStrs(selOpt',:);
            %selectedOpt=str2num(selectedOpt);
            selectedOpt=selOpt;
            numOpt=length(selectedOpt);
            
            if(numOpt==0||numGroups==0||numBioM==0)
                return;
            end
            
            if(ExFNIRS.settings.ylim_fixed)
                ExFNIRS.settings.ylim_fixed_min=inf;
                ExFNIRS.settings.ylim_fixed_max=-inf;
            end
            
            curInfoGby=cell(0);
            
            for g=1:numGroups
                gbyStrs{g}='';
               if(~isempty(ExFNIRS.gby(g).gbyTables))
                   for i=1:length(gbyVars)
                       gbyStrs{g}=sprintf('%s%s:%s,',gbyStrs{g},gbyVars{i},num2strOrNot(app, ExFNIRS.gby(g).gbyTables.(gbyVars{i})(1)));
                   end
                   if(useCurInfoGroup)
                       curInfoGby{g}=num2strOrNot(app, ExFNIRS.gby(g).gbyTables.(curInfoGroup)(1));
                   end
               end
               if(~isempty(gbyStrs{g}))
                    gbyStrs{g}(end)='';
               end
            end
            
            [uCurInfoG,firstCurIdx,uCurIdx]=unique(cellstr(curInfoGby));
            
            numCurInfoG=length(uCurInfoG);
            
            numUgroups=length(unique(cellstr(gbyStrs)));
            
            uCurGIdxCount=nan(size(uCurIdx));
            for i =1:numCurInfoG
                uCurGIdxCount(uCurIdx==i)=1:sum(uCurIdx==i);
            end
            
            if(numUgroups==1)
                num2Plot=numBioM;
                plotGroupByBioM=true;
                bioColorTable=table2cell(pf2_base.getBioColors());
                cIndex=[];
                for i=1:length(bioColorTable)
                    cIndex(i,:)=bioColorTable{i};
                end
            else
                num2Plot=numUgroups;
                plotGroupByBioM=false;
                if(ExFNIRS.settings.use_gui_color)
                    cIndex=ExFNIRS.settings.guiColor(1:numUgroups,:);
                else
                    cIndex=ExFNIRS.settings.cmap(numUgroups);%linspecer(num2Plot,'qualitative');
                end
            end
            
            
            errorFeature=ExFNIRS.settings.plot_error_feature;
            errMulitply=ExFNIRS.settings.plot_error_multiply;
            plotFeature=ExFNIRS.settings.plot_grandaverage_feature;
            
            if(strcmp(plotFeature,'Count')&&ExFNIRS.settings.plot_grandaverage)
              plotFeature='N';
              plotCount=true;
            else
                plotCount=false;
            end
            
            
            if(~plotGroupByBioM)
                if(numOpt>1&&numCurInfoG>1)
                    xType='channels';
                    yType='groupby';
                    figType='bioM';
                    numSubX=numOpt;
                    numSubY=numCurInfoG;
                elseif(numOpt==1&&numCurInfoG>1)
                    xType='bioM';
                    yType='groupby';
                    figType='';
                    numSubX=numBioM;
                    numSubY=numCurInfoG;
                elseif(numCurInfoG<=1&&numOpt>1)
                    xType='channels';
                    yType='bioM';
                    figType='';
                    numSubX=numOpt;
                    numSubY=numBioM;
                else
                    xType='bioM';
                    yType='';
                    figType='';
                    numSubX=numBioM;
                    numSubY=1;
                end
            else %plot with biomarkers embedded
                if(numOpt>=1&&numCurInfoG>1)
                    xType='channels';
                    yType='groupby';
                    figType='';
                    numSubX=numOpt;
                    numSubY=numCurInfoG;
                elseif(numCurInfoG<=1&&numOpt>1)
                    xType='channels';
                    yType='';
                    figType='';
                    numSubX=numOpt;
                    numSubY=1;
                else
                    xType='';
                    yType='';
                    figType='';
                    numSubX=1;
                    numSubY=1;
                end
            end


            switch(figType)
                case 'bioM'
                    for i=1:numBioM
                        sH{i,1}.h=figure(900+i);
                        clf(sH{i,1}.h);
                        dcm_obj = datacursormode(sH{i,1}.h);
                        set(dcm_obj,'UpdateFcn',@app.myDataTipUpdateFcn);
                        for s=1:(numSubX*numSubY)
                            xInd=rem(s,numSubX);
                            if(xInd==0)
                                xInd=numSubX;
                            end
                            h=subplot(numSubY,numSubX,s);
                            if(ExFNIRS.settings.plot_temporal_y0)
                                yh=plot([ExFNIRS.settings.plot_start,ExFNIRS.settings.plot_end],[0,0],'k');
                                set(yh,'HandleVisibility','off');
                            end
                            sH{i,1}.subH{floor((s-1)/numSubX)+1,xInd}=h;
                            legend(h, 'off');
                        end
                        multiPlot=true;
                    end
                otherwise
                    sH{1,1}.h=figure(900);
                    clf(sH{1,1}.h);
                    dcm_obj = datacursormode(sH{1,1}.h);
                    set(dcm_obj,'UpdateFcn',@app.myDataTipUpdateFcn);
                    for s=1:(numSubX*numSubY)
                        xInd=rem(s,numSubX);
                        if(xInd==0)
                            xInd=numSubX;
                        end
                        h=subplot(numSubY,numSubX,s);
                        if(ExFNIRS.settings.plot_temporal_y0)
                            yh=plot([ExFNIRS.settings.plot_start,ExFNIRS.settings.plot_end],[0,0],'k');
                            set(yh,'HandleVisibility','off');
                        end
                        sH{1,1}.subH{floor((s-1)/numSubX)+1,xInd}=h;
                        legend(h, 'off');
                    end
                    multiPlot=false;
            end
            
            curSx=1;
            curSy=1;
            curFigIdx=[1,1];
            
            for chIdx=1:numOpt
                ch=selectedOpt(chIdx);
                gStrs=cell(num2Plot,1);
                for b=1:numBioM
                    bioM=selectedBioM(b);
                    if(iscell(bioM))
                        bioM=bioM{1};
                    end
            
                    if(~plotGroupByBioM)
                        gStrs=cell(num2Plot,1);
                    end
            
                    hGrandErr1=cell(num2Plot,1);
                    hGrandErr2=cell(num2Plot,1);
            
                    for g=1:numGroups
                        if(useCurInfoGroup)
                            curGroupInfoIdx=uCurIdx(g);
                            curUgroupIdx=uCurGIdxCount(g);
                        else
                            curGroupInfoIdx=1;
                            curUgroupIdx=g;
                        end
            
            
                        if(strcmp(figType,'bioM'))
                            curFigH=sH{b,1};
                        else
                           curFigH=sH{1,1};
                        end
            
                        switch(xType)
                            case 'channels'
                                curSx=chIdx;
                            case 'bioM'
                                curSx=b;
                            case 'groupby'
                                curSx=curGroupInfoIdx;
                        end
            
                        switch(yType)
                            case 'channels'
                                curSy=chIdx;
                            case 'bioM'
                                curSy=b;
                            case 'groupby'
                                curSy=curGroupInfoIdx;
                        end
            
                        if(curSy==numSubY&&curSx==numSubX)
                            lastSubplot=true;
                        else
                            lastSubplot=false;
                        end
            
                        curFNIRS=ExFNIRS.gby(g).gbyFNIRS;
                        curGrand=ExFNIRS.gby(g).gbyGrand;
            
            
                        hold(curFigH.subH{curSy,curSx},'on');
                        if(ExFNIRS.settings.plot_individual&&~plotCount)
                           for i=1:length(curFNIRS)
                               if(~isfield(curFNIRS{i},'HbO'))
                                   continue;
                               end
                               if(~isfield(curFigH,'legendHandles'))
                                   if(plotGroupByBioM)
                                        curFigH.legendHandles{curSy,curSx}.h=cell(numBioM,1);
                                   else
                                       curFigH.legendHandles{curSy,curSx}.h=cell(numUgroups,1);
                                   end
                               end
            
                               switch ExFNIRS.settings.ChannelMode
                                   case 'fNIR'
                                       data2plot=curFNIRS{i};
                                       dataTime=curFNIRS{i}.time;
                                   case 'ROI'
                                       if(~pf2_base.isnestedfield(curGrand,'ROI.HbO.data'))
                                          error('ROI data must be calculated using a build ROI step');
                                       end
                                      if(~isempty(curFNIRS{i})&&isfield(curFNIRS{i},'ROI'))
                                       data2plot=curFNIRS{i}.ROI;
                                      else
                                         data2plot=[];
                                      end
                                      dataTime=curFNIRS{i}.time;
            
                                   case 'Aux'
                                       data2plot=curFNIRS{i}.Aux;
                                       if(isfield(data2plot,bioM)&&ndims(data2plot.(bioM)(:,ch))>1) %if has its own time use that
                                           dataTime=data2plot.(bioM)(:,1);
                                           data2plot.(bioM)=data2plot.(bioM)(:,2);
                                       elseif(isfield(data2plot,'time')) %otherwise use aux time
                                           dataTime=data2plot.time;
                                       else
                                           dataTime=curFNIRS{i}.time;  %or fnirs time
                                       end
            
                               end
            
                              if(plotGroupByBioM)
                                  if(~isempty(data2plot))
                                      h=plot(curFigH.subH{curSy,curSx},dataTime,data2plot.(bioM)(:,ch),'color',cIndex(b,:));
                                      set(h,'Tag',getFormattedTrialString(app, curFNIRS{i}));
                                      if(ExFNIRS.settings.plot_grandaverage||~isempty(curFigH.legendHandles{curSy,curSx}.h{b}))
                                        set(h.Annotation.LegendInformation,'IconDisplayStyle','off');
                                      end
                                      gStrs{b}=selectedBioM{b};
                                      curFigH.legendHandles{curSy,curSx}.h{b}=h;
                                  end
                              else
                                  if(~isempty(data2plot.(bioM))&&isfield(data2plot,bioM))
                                      h=plot(curFigH.subH{curSy,curSx},dataTime,data2plot.(bioM)(:,ch),'color',cIndex(curUgroupIdx,:));
                                      set(h,'Tag',getFormattedTrialString(app, curFNIRS{i}));
                                      if(ExFNIRS.settings.plot_grandaverage||~isempty(curFigH.legendHandles{curSy,curSx}.h{curUgroupIdx}))
                                          if(~isempty(h))
                                             set(h.Annotation.LegendInformation,'IconDisplayStyle','off');
                                          end
                                      end
                                      gStrs{curUgroupIdx}=gbyStrs{g};
                                      curFigH.legendHandles{curSy,curSx}.h{curUgroupIdx}=h;
                                  end
                              end


                              hold on;
                           end
                        end
            
                        if(ExFNIRS.settings.plot_grandaverage)
                              switch(ExFNIRS.settings.ChannelMode)
                                  case 'fNIR'
                                      data2plot=curGrand.(bioM);
                                  case 'ROI'
                                      if(~pf2_base.isnestedfield(curGrand,'ROI.HbO.data'))
                                          warning('ROI data must be calculated using a build ROI step');
                                          data2plot=[];
                                      else
                                        data2plot=curGrand.ROI.(bioM);
                                      end
                                  case 'Aux'
                                        data2plot=curGrand.Aux.(bioM);
                                        %if(ndims(data2plot)>1)
                                        %    data2plot=data2plot(:,2);
                                        %end
            
                              end
            
            
                              if(~isempty(data2plot))
                                  if(plotGroupByBioM)
                                      hGrand=plot(curFigH.subH{curSy,curSx},curGrand.time,data2plot.(plotFeature)(:,ch),'LineWidth',3,'color',cIndex(b,:));
                                  else
                                      hGrand=plot(curFigH.subH{curSy,curSx},curGrand.time,data2plot.(plotFeature)(:,ch),'LineWidth',3,'color',cIndex(curUgroupIdx,:));
                                  end
            
                                  if(numUgroups>1||numBioM==1)&&~isempty(gbyStrs{g})
                                       gStrs{curUgroupIdx}=gbyStrs{g};
                                       set(hGrand,'Tag',sprintf('%s: %s',plotFeature,gStrs{curUgroupIdx}));
                                       curFigH.legendHandles{curSy,curSx}.hG{curUgroupIdx}=hGrand;
                                  elseif(~multiPlot)
                                       gStrs{b}=selectedBioM{b};
                                       set(hGrand,'Tag',sprintf('%s: %s',plotFeature,gStrs{b}));
                                       curFigH.legendHandles{curSy,curSx}.hG{b}=hGrand;
                                  end
                              end
            
            
                        end
            
                        if(ExFNIRS.settings.plot_error&&~plotCount)
                            errStyle=ExFNIRS.settings.plot_error_style;
                            plotShaded=false;
            
                            switch(errStyle)
                                case 'Dashed'
                                    errStyle='--';
                                    lineWidth=2;
                                case 'Fine'
                                    errStyle='-';
                                    lineWidth=0.5;
                                case 'Shaded'
                                    errStyle='-';
                                    lineWidth=0.5;
                                    plotShaded=true;
                                otherwise
                                    error('Unspecified error style');
                            end
            
                            if(plotGroupByBioM)
                                errColor=cIndex(b,:);
                                errColor=errColor+(1-errColor)*0.55;
                            else
                                errColor=cIndex(curUgroupIdx,:);
                                errColor=errColor+(1-errColor)*0.55;
                            end
            
                            switch ExFNIRS.settings.ChannelMode
                                case 'fNIR'
                                    data2plot=curGrand.(bioM);
                                case 'ROI'
                                    if(pf2_base.isnestedfield(curGrand,'ROI.HbO'))
                                    data2plot=curGrand.ROI.(bioM);
                                    else
                                       data2plot=[];
                                    end
                                case 'Aux'
                                    data2plot=curGrand.Aux.(bioM);
                            end
            
                            if(~isempty(data2plot))
                                if(strcmp(errorFeature,'MaxMin'))
                                  upperError=data2plot.Max(:,ch);
                                  lowerError=data2plot.Min(:,ch);
                                else
                                  upperError=data2plot.(plotFeature)(:,ch)+data2plot.(errorFeature)(:,ch)*errMulitply;
                                  lowerError=data2plot.(plotFeature)(:,ch)-data2plot.(errorFeature)(:,ch)*errMulitply;
                                end
            
            
                                if(plotShaded)
                                      errAlpha=0.15;
                                      yPatch=[lowerError',fliplr(upperError')];
                                      xPatch=[curGrand.time',fliplr(curGrand.time')];
                                      xPatch(isnan(yPatch))=[];
                                      yPatch(isnan(yPatch))=[];
            
                                      hPatch=patch(curFigH.subH{curSy,curSx},xPatch,yPatch,-1,'facecolor',errColor,'edgecolor','none','facealpha',errAlpha);
                                      if(~isempty(hPatch))
                                          set(hPatch,'HitTest','off');
                                          set(hPatch,'HandleVisibility','off');
            
                                          set(hPatch.Annotation.LegendInformation,'IconDisplayStyle','off');
                                      end
                                end
            
                                if(plotGroupByBioM)
                                      hGrandErr1{b}=plot(curFigH.subH{curSy,curSx},curGrand.time,upperError,'lineStyle',errStyle,'LineWidth',lineWidth,'color',errColor);
                                      hGrandErr2{b}=plot(curFigH.subH{curSy,curSx},curGrand.time,lowerError,'lineStyle',errStyle,'LineWidth',lineWidth,'color',errColor);
                                      set(hGrandErr1{b}.Annotation.LegendInformation,'IconDisplayStyle','off');
                                      set(hGrandErr2{b}.Annotation.LegendInformation,'IconDisplayStyle','off');
                                      curFigH.legendHandles{curSy,curSx}.hE{b}=hGrandErr1{b};
                                else
                                      hGrandErr1{g}=plot(curFigH.subH{curSy,curSx},curGrand.time,upperError,'lineStyle',errStyle,'LineWidth',lineWidth,'color',errColor);
                                      hGrandErr2{g}=plot(curFigH.subH{curSy,curSx},curGrand.time,lowerError,'lineStyle',errStyle,'LineWidth',lineWidth,'color',errColor);
                                      set(hGrandErr1{g}.Annotation.LegendInformation,'IconDisplayStyle','off');
                                      set(hGrandErr2{g}.Annotation.LegendInformation,'IconDisplayStyle','off');
                                      curFigH.legendHandles{curSy,curSx}.hE{g}=hGrandErr1{g};
                                end


                                if(~plotGroupByBioM)
                                   gAerrStrs{g}=sprintf('%s: %s',errorFeature,gbyStrs{g});
                                   set(hGrandErr1{g},'Tag',gAerrStrs{g});
                                   set(hGrandErr2{g},'Tag',gAerrStrs{g});
                                else
                                   gAerrStrs{b}=sprintf('%s: %s',errorFeature,selectedBioM{b});
                                   set(hGrandErr1{b},'Tag',gAerrStrs{b});
                                   set(hGrandErr2{b},'Tag',gAerrStrs{b});
                                end
                            end
                        end
            
                        switch ExFNIRS.settings.ChannelMode
                            case 'fNIR'
                                chNamePart=sprintf('Opt. %i',ch);
                                chNamePartLong=sprintf('Optode %i',ch);
                            case 'ROI'
                                chNamePart=selectedOptStr{chIdx};
                                chNamePartLong=sprintf('ROI: %s',selectedOptStr{chIdx});
                            case 'Aux'
                                chNamePart='Aux:';
                                chNamePartLong=sprintf('Aux: %s',bioM);
                        end
            
                        if(~plotGroupByBioM)
                            if(~strcmp(ExFNIRS.settings.ChannelMode,'Aux'))
                                ylbl=sprintf('\\Delta[%s] (\\muM)',bioM);
                            else
                                ylbl=sprintf('%s',bioM);
                            end
                            if(plotCount)
                                ylbl=(sprintf('N %s',ylbl));
                            end
                            if(ExFNIRS.settings.plot_error)
                                ylbl=(sprintf('%s (%s)',ylbl,ExFNIRS.settings.plot_error_feature));
                            end
                            ylbl=(sprintf('%s %s',chNamePart,ylbl));
                        elseif(plotGroupByBioM)
                            if(~strcmp(ExFNIRS.settings.ChannelMode,'Aux'))
                                ylbl=sprintf('\\Delta[X] (\\muM)');
                            else
                                ylbl=sprintf('Multiple signals');
                            end
                            if(plotCount)
                                ylbl=(sprintf('N %s',ylbl));
                            end
                            if(ExFNIRS.settings.plot_error)
                                ylbl=(sprintf('%s (%s)',ylbl,ExFNIRS.settings.plot_error_feature));
                            end
                            ylbl=(sprintf('%s %s',chNamePart,ylbl));
            
            
                        end
            
                        switch(xType)
                            case 'channels'
                                title(curFigH.subH{curSy,curSx},chNamePartLong);
                            case 'bioM'
                                title(curFigH.subH{curSy,curSx},bioM);
                            case 'groupby'
                                title(curFigH.subH{curSy,curSx},uCurInfoG{curGroupInfoIdx});
                            otherwise
                        end
            
                        switch(yType)
                            case 'channels'
                                ylbl={chNamePartLong;ylbl};
                            case 'bioM'
                                ylbl={bioM;ylbl};
                            case 'groupby'
                                ylbl={uCurInfoG{curGroupInfoIdx};ylbl};
                            otherwise
                        end
                        ylabel(curFigH.subH{curSy,curSx},ylbl);
            
                    if(ExFNIRS.settings.ylim_fixed)
                        xlim(curFigH.subH{curSy,curSx},[ExFNIRS.settings.plot_start,ExFNIRS.settings.plot_end]);
                        ylim(curFigH.subH{curSy,curSx},'auto');
                        cylim=ylim(curFigH.subH{curSy,curSx});
                        ExFNIRS.settings.ylim_fixed_min=min(ExFNIRS.settings.ylim_fixed_min,cylim(1));
                        ExFNIRS.settings.ylim_fixed_max=max(ExFNIRS.settings.ylim_fixed_max,cylim(2));
                    elseif(ExFNIRS.settings.ylim_manual&&~plotCount)
                        ylim(curFigH.subH{curSy,curSx},[ExFNIRS.settings.ylim_manual_min,ExFNIRS.settings.ylim_manual_max]);
                    else
                        ylim(curFigH.subH{curSy,curSx},'auto');
                    end
            
                    curYlim=ylim(curFigH.subH{curSy,curSx});
                    if(plotCount)
                        ExFNIRS.settings.ylim_fixed_min=0;
                        ylim(curFigH.subH{curSy,curSx},[0,curYlim(2)]);
                    end
            
            
                    end
            
            
                end
            end
            
            if(plotCount)
                ExFNIRS.settings.ylim_fixed_min=0;
            end
            
            
            for i=1:size(sH,1)
                for b=1:size(sH,2)
                    for x=1:numSubX
                        for y=1:numSubY
                            xlabel(sH{i,b}.subH{y,x},'Time (s)');
                            xlim(sH{i,b}.subH{y,x},[ExFNIRS.settings.plot_start,ExFNIRS.settings.plot_end]);
            
                            if(ExFNIRS.settings.ylim_fixed)
                                ylim(sH{i,b}.subH{y,x},[ExFNIRS.settings.ylim_fixed_min,ExFNIRS.settings.ylim_fixed_max]);
                            end
            
                            if(ExFNIRS.settings.plot_legend_mode==3||(ExFNIRS.settings.plot_legend_mode==2&&(x==numSubX&&y==numSubY)))
                                if(ExFNIRS.settings.plot_grandaverage)
                                    curHandles=curFigH.legendHandles{curSy,curSx}.hG;
                                elseif(ExFNIRS.settings.plot_individual)
                                    curHandles=curFigH.legendHandles{curSy,curSx}.h;
                                elseif(ExFNIRS.settings.plot_error)
                                    curHandles=curFigH.legendHandles{curSy,curSx}.hE;
                                end
                                legendGFXstrs=cell(0);
                                for h=1:length(curHandles)
                                   if(~isempty(gStrs{h}))
                                       %set(curHandles{h}.Annotation.LegendInformation,'IconDisplayStyle','on');
                                       legendGFXstrs(h)=gStrs(h);
                                   else
            
                                   end
                                end
                                legend(sH{i,b}.subH{y,x},legendGFXstrs(:)','Location', 'Best');
                            end
            
                            if(ExFNIRS.settings.plot_task_lines)
                                pf2_base.external.vline(sH{i,b}.subH{y,x},[ExFNIRS.settings.baseline_start,ExFNIRS.settings.baseline_end],{'--k','HandleVisibility','off'});
                                pf2_base.external.vline(sH{i,b}.subH{y,x},[ExFNIRS.settings.block_start,ExFNIRS.settings.block_end],{'--r','HandleVisibility','off'});
                            end
            
                            hold(sH{i,b}.subH{y,x},'off');
                        end
                    end
            
                    addDebugAnnotation(app, sH{i,b}.h);
                    switch(figType)
                        case 'bioM'
                            pf2_base.external.suptitle(sH{i,b}.h,selectedBioM{i});
                        otherwise
            
                    end
                end
            end
        end

        % Button pushed function: pushbutton_process_selection
        function pushbutton_process_selection_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_process_selection (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            updateSelectedTable(app);
        end

        % Button pushed function: pushbutton_refresh_methods
        function pushbutton_refresh_methods_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_refresh_methods (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            curRawMethods=processFNIRS2_configureMethods('currentRawMethodsCallback',hObject,handles);
            if(~isempty(curRawMethods))
               set(app.listbox_raw_methods,'Items',curRawMethods);
            end
            curOxyMethods=processFNIRS2_configureMethods('currentOxyMethodsCallback',hObject,handles);
            if(~isempty(curOxyMethods))
               set(app.listbox_oxy_methods,'Items',curOxyMethods);
            end
            
            global ExFNIRS
            
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,3);
            end
        end

        % Button pushed function: pushbutton_session_select_all
        function pushbutton_session_select_all_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_session_select_all (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            strs=get(app.listbox_session,'Items');
            if(iscell(strs)||ismatrix(strs))
                set(app.listbox_session,'Value',[1:size(strs,1)]);
            end
            
            global ExFNIRS
            
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,true);
            end
        end

        % Button pushed function: pushbutton_session_select_none
        function pushbutton_session_select_none_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_session_select_none (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(app.listbox_session,'Value',[]);
        end

        % Button pushed function: pushbutton_subjectID_select_all
        function pushbutton_subjectID_select_all_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_subjectID_select_all (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            strs=get(app.listbox_subjectID,'Items');
            if(iscell(strs)||ismatrix(strs))
                set(app.listbox_subjectID,'Value',[1:size(strs,1)]);
            end
            
            global ExFNIRS
            
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,true);
            end
        end

        % Button pushed function: pushbutton_subjectID_select_none
        function pushbutton_subjectID_select_none_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_subjectID_select_none (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            set(app.listbox_subjectID,'Value',[]);
        end

        % Button pushed function: pushbutton_trial_select_all
        function pushbutton_trial_select_all_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_trial_select_all (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            strs=get(app.listbox_trial,'Items');
            if(iscell(strs)||ismatrix(strs))
                set(app.listbox_trial,'Value',[1:size(strs,1)]);
            end
            
            global ExFNIRS
            
            if(ExFNIRS.settings.updateOnChange)
                updateSelectedTable(app);
            else
                flagForUpdate(app,true);
            end
        end

        % Button pushed function: pushbutton_trial_select_none
        function pushbutton_trial_select_none_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_trial_select_none (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(app.listbox_trial,'Value',[]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'exploreFNIRS';
            app.figure1.Tag = 'figure1';
            app.unitgroup = uibuttongroup(app.figure1);
            app.unitgroup.Title = 'fNIRS Settings';
            app.unitgroup.Tag = 'unitgroup';
            app.listbox_optode = uilistbox(app.unitgroup);
            app.listbox_optode.Items = {'No Optodes'};
            app.listbox_optode.Multiselect = 'on';
            app.listbox_optode.Tag = 'listbox_optode';
            app.listbox_optode.Value = {'No Optodes'};
            app.pushbutton_optodes_select_all = uibutton(app.unitgroup, 'push');
            app.pushbutton_optodes_select_all.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_optodes_select_all_Callback, true);
            app.pushbutton_optodes_select_all.Tag = 'pushbutton_optodes_select_all';
            app.pushbutton_optodes_select_all.Text = 'All';
            app.pushbutton_optodes_select_none = uibutton(app.unitgroup, 'push');
            app.pushbutton_optodes_select_none.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_optodes_select_none_Callback, true);
            app.pushbutton_optodes_select_none.Tag = 'pushbutton_optodes_select_none';
            app.pushbutton_optodes_select_none.Text = 'None';
            app.text_optode_label = uilabel(app.unitgroup);
            app.text_optode_label.Tag = 'text_optode_label';
            app.text_optode_label.Text = 'Optode';
            app.text_biomarker_label = uilabel(app.unitgroup);
            app.text_biomarker_label.Tag = 'text_biomarker_label';
            app.text_biomarker_label.Text = 'BioMarker';
            app.listbox_biomarker = uilistbox(app.unitgroup);
            app.listbox_biomarker.Items = {'No Biomarkers'};
            app.listbox_biomarker.Multiselect = 'on';
            app.listbox_biomarker.Tag = 'listbox_biomarker';
            app.listbox_biomarker.Value = {'No Biomarkers'};
            app.pushbutton_biomarker_select_all = uibutton(app.unitgroup, 'push');
            app.pushbutton_biomarker_select_all.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_biomarker_select_all_Callback, true);
            app.pushbutton_biomarker_select_all.Tag = 'pushbutton_biomarker_select_all';
            app.pushbutton_biomarker_select_all.Text = 'All';
            app.pushbutton_biomarker_select_none = uibutton(app.unitgroup, 'push');
            app.pushbutton_biomarker_select_none.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_biomarker_select_none_Callback, true);
            app.pushbutton_biomarker_select_none.Tag = 'pushbutton_biomarker_select_none';
            app.pushbutton_biomarker_select_none.Text = 'None';
            app.listbox_raw_methods = uilistbox(app.unitgroup);
            app.listbox_raw_methods.Items = {'No Loaded Raw Methods'};
            app.listbox_raw_methods.Multiselect = 'on';
            app.listbox_raw_methods.ValueChangedFcn = createCallbackFcn(app, @listbox_raw_methods_Callback, true);
            app.listbox_raw_methods.Tag = 'listbox_raw_methods';
            app.listbox_raw_methods.Value = {'No Loaded Raw Methods'};
            app.listbox_oxy_methods = uilistbox(app.unitgroup);
            app.listbox_oxy_methods.Items = {'No Loaded Oxy Methods'};
            app.listbox_oxy_methods.Multiselect = 'on';
            app.listbox_oxy_methods.ValueChangedFcn = createCallbackFcn(app, @listbox_oxy_methods_Callback, true);
            app.listbox_oxy_methods.Tag = 'listbox_oxy_methods';
            app.listbox_oxy_methods.Value = {'No Loaded Oxy Methods'};
            app.text25 = uilabel(app.unitgroup);
            app.text25.Tag = 'text25';
            app.text25.Text = 'Oxy Methods';
            app.pushbutton_refresh_methods = uibutton(app.unitgroup, 'push');
            app.pushbutton_refresh_methods.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_refresh_methods_Callback, true);
            app.pushbutton_refresh_methods.Tag = 'pushbutton_refresh_methods';
            app.pushbutton_refresh_methods.Text = 'Refresh';
            app.pushbutton_import_raw = uibutton(app.unitgroup, 'push');
            app.pushbutton_import_raw.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_import_raw_Callback, true);
            app.pushbutton_import_raw.Tag = 'pushbutton_import_raw';
            app.pushbutton_import_raw.Text = 'Raw';
            app.pushbutton_import_oxy = uibutton(app.unitgroup, 'push');
            app.pushbutton_import_oxy.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_import_oxy_Callback, true);
            app.pushbutton_import_oxy.Tag = 'pushbutton_import_oxy';
            app.pushbutton_import_oxy.Text = 'Oxy';
            app.text31 = uilabel(app.unitgroup);
            app.text31.Tag = 'text31';
            app.text31.Text = 'Import';
            app.pushbutton_clear_processed = uibutton(app.unitgroup, 'push');
            app.pushbutton_clear_processed.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_clear_processed_Callback, true);
            app.pushbutton_clear_processed.Tag = 'pushbutton_clear_processed';
            app.pushbutton_clear_processed.Tooltip = 'Delete all preloaded processed data';
            app.pushbutton_clear_processed.Text = 'Clear';
            app.popupmenu_ChannelMode = uidropdown(app.unitgroup);
            app.popupmenu_ChannelMode.Items = {'Optodes', 'ROIs', 'Auxillary Channels'};
            app.popupmenu_ChannelMode.ValueChangedFcn = createCallbackFcn(app, @popupmenu_ChannelMode_Callback, true);
            app.popupmenu_ChannelMode.Tag = 'popupmenu_ChannelMode';
            app.popupmenu_ChannelMode.Value = 'Optodes';
            app.checkbox_process_raw = uicheckbox(app.unitgroup);
            app.checkbox_process_raw.ValueChangedFcn = createCallbackFcn(app, @checkbox_process_raw_Callback, true);
            app.checkbox_process_raw.Tag = 'checkbox_process_raw';
            app.checkbox_process_raw.Tooltip = 'Select to enable the full model with all interactions from groupby variables in the LME model calculation';
            app.checkbox_process_raw.Text = 'Raw Methods';
            app.checkbox_process_raw.Value = true;
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Data Grouping Factors';
            app.uipanel1.Tag = 'uipanel1';
            app.listbox_subjectID = uilistbox(app.uipanel1);
            app.listbox_subjectID.Items = {'No Subjects'};
            app.listbox_subjectID.Multiselect = 'on';
            app.listbox_subjectID.ValueChangedFcn = createCallbackFcn(app, @listbox_subjectID_Callback, true);
            app.listbox_subjectID.Tag = 'listbox_subjectID';
            app.listbox_subjectID.Value = {'No Subjects'};
            app.text8 = uilabel(app.uipanel1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'SubjectID';
            app.listbox_group = uilistbox(app.uipanel1);
            app.listbox_group.Items = {'No Groups'};
            app.listbox_group.Multiselect = 'on';
            app.listbox_group.ValueChangedFcn = createCallbackFcn(app, @listbox_group_Callback, true);
            app.listbox_group.Tag = 'listbox_group';
            app.listbox_group.Value = {'No Groups'};
            app.text9 = uilabel(app.uipanel1);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Group';
            app.listbox_session = uilistbox(app.uipanel1);
            app.listbox_session.Items = {'No Sessions'};
            app.listbox_session.Multiselect = 'on';
            app.listbox_session.ValueChangedFcn = createCallbackFcn(app, @listbox_session_Callback, true);
            app.listbox_session.Tag = 'listbox_session';
            app.listbox_session.Value = {'No Sessions'};
            app.text10 = uilabel(app.uipanel1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Session';
            app.listbox_trial = uilistbox(app.uipanel1);
            app.listbox_trial.Items = {'No Trials'};
            app.listbox_trial.Multiselect = 'on';
            app.listbox_trial.ValueChangedFcn = createCallbackFcn(app, @listbox_trial_Callback, true);
            app.listbox_trial.Tag = 'listbox_trial';
            app.listbox_trial.Value = {'No Trials'};
            app.text11 = uilabel(app.uipanel1);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Trial';
            app.listbox_condition = uilistbox(app.uipanel1);
            app.listbox_condition.Items = {'No Conditions'};
            app.listbox_condition.Multiselect = 'on';
            app.listbox_condition.ValueChangedFcn = createCallbackFcn(app, @listbox_condition_Callback, true);
            app.listbox_condition.Tag = 'listbox_condition';
            app.listbox_condition.Value = {'No Conditions'};
            app.text12 = uilabel(app.uipanel1);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Condition';
            app.pushbutton_group_select_all = uibutton(app.uipanel1, 'push');
            app.pushbutton_group_select_all.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_group_select_all_Callback, true);
            app.pushbutton_group_select_all.Tag = 'pushbutton_group_select_all';
            app.pushbutton_group_select_all.Text = 'All';
            app.pushbutton_group_select_none = uibutton(app.uipanel1, 'push');
            app.pushbutton_group_select_none.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_group_select_none_Callback, true);
            app.pushbutton_group_select_none.Tag = 'pushbutton_group_select_none';
            app.pushbutton_group_select_none.Text = 'None';
            app.pushbutton_session_select_all = uibutton(app.uipanel1, 'push');
            app.pushbutton_session_select_all.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_session_select_all_Callback, true);
            app.pushbutton_session_select_all.Tag = 'pushbutton_session_select_all';
            app.pushbutton_session_select_all.Text = 'All';
            app.pushbutton_session_select_none = uibutton(app.uipanel1, 'push');
            app.pushbutton_session_select_none.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_session_select_none_Callback, true);
            app.pushbutton_session_select_none.Tag = 'pushbutton_session_select_none';
            app.pushbutton_session_select_none.Text = 'None';
            app.pushbutton_trial_select_all = uibutton(app.uipanel1, 'push');
            app.pushbutton_trial_select_all.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_trial_select_all_Callback, true);
            app.pushbutton_trial_select_all.Tag = 'pushbutton_trial_select_all';
            app.pushbutton_trial_select_all.Text = 'All';
            app.pushbutton_trial_select_none = uibutton(app.uipanel1, 'push');
            app.pushbutton_trial_select_none.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_trial_select_none_Callback, true);
            app.pushbutton_trial_select_none.Tag = 'pushbutton_trial_select_none';
            app.pushbutton_trial_select_none.Text = 'None';
            app.pushbutton_condition_select_all = uibutton(app.uipanel1, 'push');
            app.pushbutton_condition_select_all.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_condition_select_all_Callback, true);
            app.pushbutton_condition_select_all.Tag = 'pushbutton_condition_select_all';
            app.pushbutton_condition_select_all.Text = 'All';
            app.pushbutton_condition_select_none = uibutton(app.uipanel1, 'push');
            app.pushbutton_condition_select_none.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_condition_select_none_Callback, true);
            app.pushbutton_condition_select_none.Tag = 'pushbutton_condition_select_none';
            app.pushbutton_condition_select_none.Text = 'None';
            app.pushbutton_subjectID_select_all = uibutton(app.uipanel1, 'push');
            app.pushbutton_subjectID_select_all.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_subjectID_select_all_Callback, true);
            app.pushbutton_subjectID_select_all.Tag = 'pushbutton_subjectID_select_all';
            app.pushbutton_subjectID_select_all.Text = 'All';
            app.pushbutton_subjectID_select_none = uibutton(app.uipanel1, 'push');
            app.pushbutton_subjectID_select_none.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_subjectID_select_none_Callback, true);
            app.pushbutton_subjectID_select_none.Tag = 'pushbutton_subjectID_select_none';
            app.pushbutton_subjectID_select_none.Text = 'None';
            app.checkbox_subjectID_plotby = uicheckbox(app.uipanel1);
            app.checkbox_subjectID_plotby.ValueChangedFcn = createCallbackFcn(app, @checkbox_subjectID_plotby_Callback, true);
            app.checkbox_subjectID_plotby.Tag = 'checkbox_subjectID_plotby';
            app.checkbox_subjectID_plotby.Text = 'Group By';
            app.checkbox_group_plotby = uicheckbox(app.uipanel1);
            app.checkbox_group_plotby.ValueChangedFcn = createCallbackFcn(app, @checkbox_group_plotby_Callback, true);
            app.checkbox_group_plotby.Tag = 'checkbox_group_plotby';
            app.checkbox_group_plotby.Text = 'Group By';
            app.checkbox_group_plotby.Value = true;
            app.checkbox_session_plotby = uicheckbox(app.uipanel1);
            app.checkbox_session_plotby.ValueChangedFcn = createCallbackFcn(app, @checkbox_session_plotby_Callback, true);
            app.checkbox_session_plotby.Tag = 'checkbox_session_plotby';
            app.checkbox_session_plotby.Text = 'Group By';
            app.checkbox_trial_plotby = uicheckbox(app.uipanel1);
            app.checkbox_trial_plotby.ValueChangedFcn = createCallbackFcn(app, @checkbox_trial_plotby_Callback, true);
            app.checkbox_trial_plotby.Tag = 'checkbox_trial_plotby';
            app.checkbox_trial_plotby.Text = 'Group By';
            app.checkbox_condition_plotby = uicheckbox(app.uipanel1);
            app.checkbox_condition_plotby.ValueChangedFcn = createCallbackFcn(app, @checkbox_condition_plotby_Callback, true);
            app.checkbox_condition_plotby.Tag = 'checkbox_condition_plotby';
            app.checkbox_condition_plotby.Text = 'Group By';
            app.checkbox_condition_plotby.Value = true;
            app.listbox_block = uilistbox(app.uipanel1);
            app.listbox_block.Items = {'No Trials'};
            app.listbox_block.Multiselect = 'on';
            app.listbox_block.ValueChangedFcn = createCallbackFcn(app, @listbox_block_Callback, true);
            app.listbox_block.Tag = 'listbox_block';
            app.listbox_block.Value = {'No Trials'};
            app.text30 = uilabel(app.uipanel1);
            app.text30.Tag = 'text30';
            app.text30.Text = 'Block';
            app.pushbutton_block_select_all = uibutton(app.uipanel1, 'push');
            app.pushbutton_block_select_all.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_block_select_all_Callback, true);
            app.pushbutton_block_select_all.Tag = 'pushbutton_block_select_all';
            app.pushbutton_block_select_all.Text = 'All';
            app.pushbutton_block_select_none = uibutton(app.uipanel1, 'push');
            app.pushbutton_block_select_none.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_block_select_none_Callback, true);
            app.pushbutton_block_select_none.Tag = 'pushbutton_block_select_none';
            app.pushbutton_block_select_none.Text = 'None';
            app.checkbox_block_plotby = uicheckbox(app.uipanel1);
            app.checkbox_block_plotby.ValueChangedFcn = createCallbackFcn(app, @checkbox_block_plotby_Callback, true);
            app.checkbox_block_plotby.Tag = 'checkbox_block_plotby';
            app.checkbox_block_plotby.Text = 'Group By';
            app.checkbox_auto_update = uicheckbox(app.uipanel1);
            app.checkbox_auto_update.ValueChangedFcn = createCallbackFcn(app, @checkbox_auto_update_Callback, true);
            app.checkbox_auto_update.Tag = 'checkbox_auto_update';
            app.checkbox_auto_update.Text = 'Update On Change';
            app.listbox_subgroup = uilistbox(app.uipanel1);
            app.listbox_subgroup.Items = {'No Groups'};
            app.listbox_subgroup.Multiselect = 'on';
            app.listbox_subgroup.ValueChangedFcn = createCallbackFcn(app, @listbox_subgroup_Callback, true);
            app.listbox_subgroup.Tag = 'listbox_subgroup';
            app.listbox_subgroup.Enable = 'off';
            app.listbox_subgroup.Value = {'No Groups'};
            app.text40 = uilabel(app.uipanel1);
            app.text40.Tag = 'text40';
            app.text40.Text = 'Subgroup';
            app.checkbox_use_group = uicheckbox(app.uipanel1);
            app.checkbox_use_group.ValueChangedFcn = createCallbackFcn(app, @checkbox_use_group_Callback, true);
            app.checkbox_use_group.Tag = 'checkbox_use_group';
            app.checkbox_use_group.Text = '';
            app.checkbox_use_group.Value = true;
            app.checkbox_use_subgroup = uicheckbox(app.uipanel1);
            app.checkbox_use_subgroup.ValueChangedFcn = createCallbackFcn(app, @checkbox_use_subgroup_Callback, true);
            app.checkbox_use_subgroup.Tag = 'checkbox_use_subgroup';
            app.checkbox_use_subgroup.Text = '';
            app.pushbutton_process_selection = uibutton(app.uipanel1, 'push');
            app.pushbutton_process_selection.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_process_selection_Callback, true);
            app.pushbutton_process_selection.Tag = 'pushbutton_process_selection';
            app.pushbutton_process_selection.Text = 'Process';
            app.popupmenu_groupby_info_field = uidropdown(app.uipanel1);
            app.popupmenu_groupby_info_field.Items = {};
            app.popupmenu_groupby_info_field.ValueChangedFcn = createCallbackFcn(app, @popupmenu_groupby_info_field_Callback, true);
            app.popupmenu_groupby_info_field.Tag = 'popupmenu_groupby_info_field';
            app.popupmenu_groupby_info_field.Value = {};
            app.listbox_info_groupby = uilistbox(app.uipanel1);
            app.listbox_info_groupby.Items = {'No Info Data'};
            app.listbox_info_groupby.Multiselect = 'on';
            app.listbox_info_groupby.ValueChangedFcn = createCallbackFcn(app, @listbox_info_groupby_Callback, true);
            app.listbox_info_groupby.Tag = 'listbox_info_groupby';
            app.listbox_info_groupby.Value = {'No Info Data'};
            app.pushbutton_info_groupby_select_all = uibutton(app.uipanel1, 'push');
            app.pushbutton_info_groupby_select_all.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_info_groupby_select_all_Callback, true);
            app.pushbutton_info_groupby_select_all.Tag = 'pushbutton_info_groupby_select_all';
            app.pushbutton_info_groupby_select_all.Text = 'All';
            app.pushbutton_info_groupby_select_none = uibutton(app.uipanel1, 'push');
            app.pushbutton_info_groupby_select_none.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_info_groupby_select_none_Callback, true);
            app.pushbutton_info_groupby_select_none.Tag = 'pushbutton_info_groupby_select_none';
            app.pushbutton_info_groupby_select_none.Text = 'None';
            app.checkbox_info_groupby_plotby = uicheckbox(app.uipanel1);
            app.checkbox_info_groupby_plotby.ValueChangedFcn = createCallbackFcn(app, @checkbox_info_groupby_plotby_Callback, true);
            app.checkbox_info_groupby_plotby.Tag = 'checkbox_info_groupby_plotby';
            app.checkbox_info_groupby_plotby.Text = 'Group By';
            app.text57 = uilabel(app.uipanel1);
            app.text57.Tag = 'text57';
            app.text57.Text = 'Custom Factor (Info Field)';
            app.uipanel5 = uipanel(app.figure1);
            app.uipanel5.Title = 'Figure Settings';
            app.uipanel5.Tag = 'uipanel5';
            app.text41 = uilabel(app.uipanel5);
            app.text41.Tag = 'text41';
            app.text41.Text = 'Show Legend';
            app.popupmenu_legend_mode = uidropdown(app.uipanel5);
            app.popupmenu_legend_mode.Items = {'None', 'Last Figure', 'All Figures'};
            app.popupmenu_legend_mode.ValueChangedFcn = createCallbackFcn(app, @popupmenu_legend_mode_Callback, true);
            app.popupmenu_legend_mode.Tag = 'popupmenu_legend_mode';
            app.popupmenu_legend_mode.Value = 'Last Figure';
            app.checkbox_ylim_fixed = uicheckbox(app.uipanel5);
            app.checkbox_ylim_fixed.ValueChangedFcn = createCallbackFcn(app, @checkbox_ylim_fixed_Callback, true);
            app.checkbox_ylim_fixed.Tag = 'checkbox_ylim_fixed';
            app.checkbox_ylim_fixed.Text = 'Fixed Auto Ylims';
            app.checkbox_ylim_fixed.Value = true;
            app.checkbox_ylim_manual = uicheckbox(app.uipanel5);
            app.checkbox_ylim_manual.ValueChangedFcn = createCallbackFcn(app, @checkbox_ylim_manual_Callback, true);
            app.checkbox_ylim_manual.Tag = 'checkbox_ylim_manual';
            app.checkbox_ylim_manual.Text = 'Manual Ylim';
            app.edit_ylim_min = uieditfield(app.uipanel5, 'text');
            app.edit_ylim_min.ValueChangedFcn = createCallbackFcn(app, @edit_ylim_min_Callback, true);
            app.edit_ylim_min.Tag = 'edit_ylim_min';
            app.edit_ylim_min.Value = '-5';
            app.edit_ylim_max = uieditfield(app.uipanel5, 'text');
            app.edit_ylim_max.ValueChangedFcn = createCallbackFcn(app, @edit_ylim_max_Callback, true);
            app.edit_ylim_max.Tag = 'edit_ylim_max';
            app.edit_ylim_max.Value = '5';
            app.text48 = uilabel(app.uipanel5);
            app.text48.Tag = 'text48';
            app.text48.Text = 'yMin';
            app.text49 = uilabel(app.uipanel5);
            app.text49.Tag = 'text49';
            app.text49.Text = 'yMax';
            app.edit_plot_start = uieditfield(app.uipanel5, 'text');
            app.edit_plot_start.ValueChangedFcn = createCallbackFcn(app, @edit_plot_start_Callback, true);
            app.edit_plot_start.Tag = 'edit_plot_start';
            app.edit_plot_start.Value = '-5';
            app.edit_plot_end = uieditfield(app.uipanel5, 'text');
            app.edit_plot_end.ValueChangedFcn = createCallbackFcn(app, @edit_plot_end_Callback, true);
            app.edit_plot_end.Tag = 'edit_plot_end';
            app.edit_plot_end.Value = '60';
            app.text35 = uilabel(app.uipanel5);
            app.text35.Tag = 'text35';
            app.text35.Text = 's';
            app.text22 = uilabel(app.uipanel5);
            app.text22.Tag = 'text22';
            app.text22.Text = 'Plot Time (xlim)';
            app.text_status = uilabel(app.figure1);
            app.text_status.Tag = 'text_status';
            app.text_status.Text = 'Uninitialized';
            app.uipanel6 = uipanel(app.figure1);
            app.uipanel6.Title = 'Color Palette';
            app.uipanel6.Tag = 'uipanel6';
            app.popupmenu_colors = uidropdown(app.uipanel6);
            app.popupmenu_colors.Items = {'lines', 'brewermap', 'winter', 'summer', 'parula', 'autumn', 'hsv', 'hot', 'colorcube', 'prism', 'myColorMap'};
            app.popupmenu_colors.ValueChangedFcn = createCallbackFcn(app, @popupmenu_colors_Callback, true);
            app.popupmenu_colors.Tag = 'popupmenu_colors';
            app.popupmenu_colors.Value = 'lines';
            app.checkbox_auto_color = uicheckbox(app.uipanel6);
            app.checkbox_auto_color.ValueChangedFcn = createCallbackFcn(app, @checkbox_auto_color_Callback, true);
            app.checkbox_auto_color.Tag = 'checkbox_auto_color';
            app.checkbox_auto_color.Text = 'Auto Color';
            app.checkbox_auto_color.Value = true;
            app.checkbox_gui_colors = uicheckbox(app.uipanel6);
            app.checkbox_gui_colors.ValueChangedFcn = createCallbackFcn(app, @checkbox_gui_colors_Callback, true);
            app.checkbox_gui_colors.Tag = 'checkbox_gui_colors';
            app.checkbox_gui_colors.Text = 'Manual';
            app.pushbutton_gui_color_1 = uibutton(app.uipanel6, 'push');
            app.pushbutton_gui_color_1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_gui_color_1_Callback, true);
            app.pushbutton_gui_color_1.Tag = 'pushbutton_gui_color_1';
            app.pushbutton_gui_color_1.Text = 'Clr1';
            app.pushbutton_gui_color_3 = uibutton(app.uipanel6, 'push');
            app.pushbutton_gui_color_3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_gui_color_3_Callback, true);
            app.pushbutton_gui_color_3.Tag = 'pushbutton_gui_color_3';
            app.pushbutton_gui_color_3.Text = 'Clr3';
            app.pushbutton_gui_color_5 = uibutton(app.uipanel6, 'push');
            app.pushbutton_gui_color_5.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_gui_color_5_Callback, true);
            app.pushbutton_gui_color_5.Tag = 'pushbutton_gui_color_5';
            app.pushbutton_gui_color_5.Text = 'Clr5';
            app.pushbutton_gui_color_7 = uibutton(app.uipanel6, 'push');
            app.pushbutton_gui_color_7.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_gui_color_7_Callback, true);
            app.pushbutton_gui_color_7.Tag = 'pushbutton_gui_color_7';
            app.pushbutton_gui_color_7.Text = 'Clr7';
            app.pushbutton_gui_color_9 = uibutton(app.uipanel6, 'push');
            app.pushbutton_gui_color_9.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_gui_color_9_Callback, true);
            app.pushbutton_gui_color_9.Tag = 'pushbutton_gui_color_9';
            app.pushbutton_gui_color_9.Text = 'Clr9';
            app.pushbutton_gui_color_2 = uibutton(app.uipanel6, 'push');
            app.pushbutton_gui_color_2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_gui_color_2_Callback, true);
            app.pushbutton_gui_color_2.Tag = 'pushbutton_gui_color_2';
            app.pushbutton_gui_color_2.Text = 'Clr2';
            app.pushbutton_gui_color_4 = uibutton(app.uipanel6, 'push');
            app.pushbutton_gui_color_4.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_gui_color_4_Callback, true);
            app.pushbutton_gui_color_4.Tag = 'pushbutton_gui_color_4';
            app.pushbutton_gui_color_4.Text = 'Clr4';
            app.pushbutton_gui_color_6 = uibutton(app.uipanel6, 'push');
            app.pushbutton_gui_color_6.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_gui_color_6_Callback, true);
            app.pushbutton_gui_color_6.Tag = 'pushbutton_gui_color_6';
            app.pushbutton_gui_color_6.Text = 'Clr6';
            app.pushbutton_gui_color_8 = uibutton(app.uipanel6, 'push');
            app.pushbutton_gui_color_8.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_gui_color_8_Callback, true);
            app.pushbutton_gui_color_8.Tag = 'pushbutton_gui_color_8';
            app.pushbutton_gui_color_8.Text = 'Clr8';
            app.pushbutton_gui_color_10 = uibutton(app.uipanel6, 'push');
            app.pushbutton_gui_color_10.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_gui_color_10_Callback, true);
            app.pushbutton_gui_color_10.Tag = 'pushbutton_gui_color_10';
            app.pushbutton_gui_color_10.Text = 'Clr0';
            app.pushbutton_gui_color_save = uibutton(app.uipanel6, 'push');
            app.pushbutton_gui_color_save.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_gui_color_save_Callback, true);
            app.pushbutton_gui_color_save.Tag = 'pushbutton_gui_color_save';
            app.pushbutton_gui_color_save.Text = 'Save';
            app.pushbutton_gui_color_load = uibutton(app.uipanel6, 'push');
            app.pushbutton_gui_color_load.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_gui_color_load_Callback, true);
            app.pushbutton_gui_color_load.Tag = 'pushbutton_gui_color_load';
            app.pushbutton_gui_color_load.Text = 'Load';
            app.listbox_color_order = uilistbox(app.uipanel6);
            app.listbox_color_order.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.listbox_color_order.Tag = 'listbox_color_order';
            app.listbox_color_order.Value = '1';
            app.pushbutton_gui_color_move_up = uibutton(app.uipanel6, 'push');
            app.pushbutton_gui_color_move_up.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_gui_color_move_up_Callback, true);
            app.pushbutton_gui_color_move_up.Tag = 'pushbutton_gui_color_move_up';
            app.pushbutton_gui_color_move_up.Text = '^';
            app.pushbutton_gui_color_move_down = uibutton(app.uipanel6, 'push');
            app.pushbutton_gui_color_move_down.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_gui_color_move_down_Callback, true);
            app.pushbutton_gui_color_move_down.Tag = 'pushbutton_gui_color_move_down';
            app.pushbutton_gui_color_move_down.Text = 'v';
            app.uipanel7 = uipanel(app.figure1);
            app.uipanel7.Title = 'Experiment Info';
            app.uipanel7.Tag = 'uipanel7';
            app.listbox_hierarchy = uilistbox(app.uipanel7);
            app.listbox_hierarchy.Items = {'Session', 'Trial', 'Condition', 'Block'};
            app.listbox_hierarchy.ValueChangedFcn = createCallbackFcn(app, @listbox_hierarchy_Callback, true);
            app.listbox_hierarchy.Tag = 'listbox_hierarchy';
            app.listbox_hierarchy.Value = 'Session';
            app.text43 = uilabel(app.uipanel7);
            app.text43.Tag = 'text43';
            app.text43.Text = 'Within-Subject Order';
            app.pushbutton_move_hierarchy_up = uibutton(app.uipanel7, 'push');
            app.pushbutton_move_hierarchy_up.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_move_hierarchy_up_Callback, true);
            app.pushbutton_move_hierarchy_up.Tag = 'pushbutton_move_hierarchy_up';
            app.pushbutton_move_hierarchy_up.Text = '^';
            app.pushbutton_move_hierarchy_down = uibutton(app.uipanel7, 'push');
            app.pushbutton_move_hierarchy_down.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_move_hierarchy_down_Callback, true);
            app.pushbutton_move_hierarchy_down.Tag = 'pushbutton_move_hierarchy_down';
            app.pushbutton_move_hierarchy_down.Text = 'v';
            app.popupmenu_within_sub_avg = uidropdown(app.uipanel7);
            app.popupmenu_within_sub_avg.Items = {'None', 'Flat', 'Hierarchy'};
            app.popupmenu_within_sub_avg.ValueChangedFcn = createCallbackFcn(app, @popupmenu_within_sub_avg_Callback, true);
            app.popupmenu_within_sub_avg.Tag = 'popupmenu_within_sub_avg';
            app.popupmenu_within_sub_avg.Value = 'Hierarchy';
            app.text44 = uilabel(app.uipanel7);
            app.text44.Tag = 'text44';
            app.text44.Text = 'Avg. Method';
            app.edit_baseline_start = uieditfield(app.uipanel7, 'text');
            app.edit_baseline_start.ValueChangedFcn = createCallbackFcn(app, @edit_baseline_start_Callback, true);
            app.edit_baseline_start.Tag = 'edit_baseline_start';
            app.edit_baseline_start.Value = '-5';
            app.edit_baseline_end = uieditfield(app.uipanel7, 'text');
            app.edit_baseline_end.ValueChangedFcn = createCallbackFcn(app, @edit_baseline_end_Callback, true);
            app.edit_baseline_end.Tag = 'edit_baseline_end';
            app.edit_baseline_end.Value = '0';
            app.text33 = uilabel(app.uipanel7);
            app.text33.Tag = 'text33';
            app.text33.Text = 's';
            app.edit_block_start = uieditfield(app.uipanel7, 'text');
            app.edit_block_start.ValueChangedFcn = createCallbackFcn(app, @edit_block_start_Callback, true);
            app.edit_block_start.Tag = 'edit_block_start';
            app.edit_block_start.Value = '0';
            app.edit_block_end = uieditfield(app.uipanel7, 'text');
            app.edit_block_end.ValueChangedFcn = createCallbackFcn(app, @edit_block_end_Callback, true);
            app.edit_block_end.Tag = 'edit_block_end';
            app.edit_block_end.Value = '60';
            app.text34 = uilabel(app.uipanel7);
            app.text34.Tag = 'text34';
            app.text34.Text = 's';
            app.text20 = uilabel(app.uipanel7);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Start';
            app.text21 = uilabel(app.uipanel7);
            app.text21.Tag = 'text21';
            app.text21.Text = 'End';
            app.text59 = uilabel(app.uipanel7);
            app.text59.Tag = 'text59';
            app.text59.Text = 'Start';
            app.text60 = uilabel(app.uipanel7);
            app.text60.Tag = 'text60';
            app.text60.Text = 'End';
            app.text16 = uilabel(app.uipanel7);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Block (Task)';
            app.checkbox_usebaseline = uicheckbox(app.uipanel7);
            app.checkbox_usebaseline.ValueChangedFcn = createCallbackFcn(app, @checkbox_usebaseline_Callback, true);
            app.checkbox_usebaseline.Tag = 'checkbox_usebaseline';
            app.checkbox_usebaseline.Text = 'Baseline Times';
            app.checkbox_usebaseline.Value = true;
            app.text_versInfo = uilabel(app.figure1);
            app.text_versInfo.Tag = 'text_versInfo';
            app.text_versInfo.Text = 'ExploreFNIRS v0.2';
            app.uipanel12 = uipanel(app.figure1);
            app.uipanel12.Title = 'LME (Barchart Output)';
            app.uipanel12.Tag = 'uipanel12';
            app.checkbox_LME_enable = uicheckbox(app.uipanel12);
            app.checkbox_LME_enable.ValueChangedFcn = createCallbackFcn(app, @checkbox_LME_enable_Callback, true);
            app.checkbox_LME_enable.Tag = 'checkbox_LME_enable';
            app.checkbox_LME_enable.Text = 'Enable';
            app.checkbox_LME_enable.Value = true;
            app.checkbox_LME_all_interactions = uicheckbox(app.uipanel12);
            app.checkbox_LME_all_interactions.ValueChangedFcn = createCallbackFcn(app, @checkbox_LME_all_interactions_Callback, true);
            app.checkbox_LME_all_interactions.Tag = 'checkbox_LME_all_interactions';
            app.checkbox_LME_all_interactions.Tooltip = 'Select to enable the full model with all interactions from groupby variables in the LME model calculation';
            app.checkbox_LME_all_interactions.Text = 'Interactions';
            app.pushbutton_lme_plot_topo = uibutton(app.uipanel12, 'push');
            app.pushbutton_lme_plot_topo.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_lme_plot_topo_Callback, true);
            app.pushbutton_lme_plot_topo.Tag = 'pushbutton_lme_plot_topo';
            app.pushbutton_lme_plot_topo.Text = 'Plot Topo';
            app.checkbox_LME_use_intercept = uicheckbox(app.uipanel12);
            app.checkbox_LME_use_intercept.ValueChangedFcn = createCallbackFcn(app, @checkbox_LME_use_intercept_Callback, true);
            app.checkbox_LME_use_intercept.Tag = 'checkbox_LME_use_intercept';
            app.checkbox_LME_use_intercept.Tooltip = 'Select to enable the full model with all interactions from groupby variables in the LME model calculation';
            app.checkbox_LME_use_intercept.Text = 'Intercept';
            app.checkbox_LME_use_intercept.Value = true;
            app.checkbox_discreteTime = uicheckbox(app.uipanel12);
            app.checkbox_discreteTime.ValueChangedFcn = createCallbackFcn(app, @checkbox_discreteTime_Callback, true);
            app.checkbox_discreteTime.Tag = 'checkbox_discreteTime';
            app.checkbox_discreteTime.Tooltip = 'Select to treat time as a categorical variable or unselect to use continous covariate (Always treated as continous with ANOVA_';
            app.checkbox_discreteTime.Text = 'Discrete Time';
            app.text63 = uilabel(app.uipanel12);
            app.text63.Tag = 'text63';
            app.text63.Text = {'Rand Fx'; ''};
            app.popupmenu_lmer_randomeffects = uidropdown(app.uipanel12);
            app.popupmenu_lmer_randomeffects.Items = {'1|SubjectID', ''};
            app.popupmenu_lmer_randomeffects.ValueChangedFcn = createCallbackFcn(app, @popupmenu_lmer_randomeffects_Callback, true);
            app.popupmenu_lmer_randomeffects.Tag = 'popupmenu_lmer_randomeffects';
            app.popupmenu_lmer_randomeffects.Value = '1|SubjectID';
            app.checkbox_lme_usecustom = uicheckbox(app.uipanel12);
            app.checkbox_lme_usecustom.ValueChangedFcn = createCallbackFcn(app, @checkbox_lme_usecustom_Callback, true);
            app.checkbox_lme_usecustom.Tag = 'checkbox_lme_usecustom';
            app.checkbox_lme_usecustom.Tooltip = 'Select to use custom string instead of others (only main terms)';
            app.checkbox_lme_usecustom.Text = '';
            app.pushbutton_custom_lme = uibutton(app.uipanel12, 'push');
            app.pushbutton_custom_lme.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_custom_lme_Callback, true);
            app.pushbutton_custom_lme.Tag = 'pushbutton_custom_lme';
            app.pushbutton_custom_lme.Text = 'CustomEq';
            app.popupmenu_topoSig = uidropdown(app.uipanel12);
            app.popupmenu_topoSig.Items = {'p=0.1', 'p=0.05', 'p=0.01', 'q=0.1 (FDR)', 'q=0.05 (FDR)', 'q=0.1(adaptive-FDR)', 'q=0.05 (adaptive-FDR)'};
            app.popupmenu_topoSig.ValueChangedFcn = createCallbackFcn(app, @popupmenu_topoSig_Callback, true);
            app.popupmenu_topoSig.Tag = 'popupmenu_topoSig';
            app.popupmenu_topoSig.Value = 'p=0.05';
            app.uipanel13 = uipanel(app.figure1);
            app.uipanel13.Title = 'Temporal Plot';
            app.uipanel13.Tag = 'uipanel13';
            app.checkbox_plot_all_data = uicheckbox(app.uipanel13);
            app.checkbox_plot_all_data.ValueChangedFcn = createCallbackFcn(app, @checkbox_plot_all_data_Callback, true);
            app.checkbox_plot_all_data.Tag = 'checkbox_plot_all_data';
            app.checkbox_plot_all_data.Text = 'Individual Data';
            app.checkbox_plot_all_data.Value = true;
            app.checkbox_plot_grandaverage = uicheckbox(app.uipanel13);
            app.checkbox_plot_grandaverage.ValueChangedFcn = createCallbackFcn(app, @checkbox_plot_grandaverage_Callback, true);
            app.checkbox_plot_grandaverage.Tag = 'checkbox_plot_grandaverage';
            app.checkbox_plot_grandaverage.Text = 'Grand Average';
            app.checkbox_plot_grandaverage.Value = true;
            app.popupmenu_grandaverage_feature = uidropdown(app.uipanel13);
            app.popupmenu_grandaverage_feature.Items = {'Mean', 'Median', 'Count'};
            app.popupmenu_grandaverage_feature.ValueChangedFcn = createCallbackFcn(app, @popupmenu_grandaverage_feature_Callback, true);
            app.popupmenu_grandaverage_feature.Tag = 'popupmenu_grandaverage_feature';
            app.popupmenu_grandaverage_feature.Value = 'Mean';
            app.checkbox_plot_error = uicheckbox(app.uipanel13);
            app.checkbox_plot_error.ValueChangedFcn = createCallbackFcn(app, @checkbox_plot_error_Callback, true);
            app.checkbox_plot_error.Tag = 'checkbox_plot_error';
            app.checkbox_plot_error.Text = 'Error';
            app.popupmenu_errorbar_feature = uidropdown(app.uipanel13);
            app.popupmenu_errorbar_feature.Items = {'SEM', 'SD', 'MaxMin'};
            app.popupmenu_errorbar_feature.ValueChangedFcn = createCallbackFcn(app, @popupmenu_errorbar_feature_Callback, true);
            app.popupmenu_errorbar_feature.Tag = 'popupmenu_errorbar_feature';
            app.popupmenu_errorbar_feature.Value = 'SEM';
            app.edit_error_multiplier = uieditfield(app.uipanel13, 'text');
            app.edit_error_multiplier.ValueChangedFcn = createCallbackFcn(app, @edit_error_multiplier_Callback, true);
            app.edit_error_multiplier.Tag = 'edit_error_multiplier';
            app.edit_error_multiplier.Value = '1';
            app.text28 = uilabel(app.uipanel13);
            app.text28.Tag = 'text28';
            app.text28.Text = 'x';
            app.text36 = uilabel(app.uipanel13);
            app.text36.Tag = 'text36';
            app.text36.Text = 'Grand Avg. Resample Size';
            app.edit_grandavg_resample_size = uieditfield(app.uipanel13, 'text');
            app.edit_grandavg_resample_size.ValueChangedFcn = createCallbackFcn(app, @edit_grandavg_resample_size_Callback, true);
            app.edit_grandavg_resample_size.Tag = 'edit_grandavg_resample_size';
            app.edit_grandavg_resample_size.Value = '0.5';
            app.text37 = uilabel(app.uipanel13);
            app.text37.Tag = 'text37';
            app.text37.Text = 's';
            app.checkbox_mark_task = uicheckbox(app.uipanel13);
            app.checkbox_mark_task.ValueChangedFcn = createCallbackFcn(app, @checkbox_mark_task_Callback, true);
            app.checkbox_mark_task.Tag = 'checkbox_mark_task';
            app.checkbox_mark_task.Text = 'Mark Task & Baseline Period';
            app.checkbox_mark_task.Value = true;
            app.popupmenu_errorbar_style = uidropdown(app.uipanel13);
            app.popupmenu_errorbar_style.Items = {'Dashed', 'Fine', 'Shaded'};
            app.popupmenu_errorbar_style.ValueChangedFcn = createCallbackFcn(app, @popupmenu_errorbar_style_Callback, true);
            app.popupmenu_errorbar_style.Tag = 'popupmenu_errorbar_style';
            app.popupmenu_errorbar_style.Value = 'Shaded';
            app.text50 = uilabel(app.uipanel13);
            app.text50.Tag = 'text50';
            app.text50.Text = 'Error Style';
            app.pushbutton_plot_temporal = uibutton(app.uipanel13, 'push');
            app.pushbutton_plot_temporal.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_plot_temporal_Callback, true);
            app.pushbutton_plot_temporal.Tag = 'pushbutton_plot_temporal';
            app.pushbutton_plot_temporal.Text = 'Plot';
            app.checkbox_yaxis = uicheckbox(app.uipanel13);
            app.checkbox_yaxis.ValueChangedFcn = createCallbackFcn(app, @checkbox_yaxis_Callback, true);
            app.checkbox_yaxis.Tag = 'checkbox_yaxis';
            app.checkbox_yaxis.Text = 'y=0';
            app.uipanel15 = uipanel(app.figure1);
            app.uipanel15.Title = 'Barchart Plot';
            app.uipanel15.Tag = 'uipanel15';
            app.text23 = uilabel(app.uipanel15);
            app.text23.Tag = 'text23';
            app.text23.Text = 'Bar Chart Bin Size';
            app.edit_barchart_resample_size = uieditfield(app.uipanel15, 'text');
            app.edit_barchart_resample_size.ValueChangedFcn = createCallbackFcn(app, @edit_barchart_resample_size_Callback, true);
            app.edit_barchart_resample_size.Tag = 'edit_barchart_resample_size';
            app.edit_barchart_resample_size.Value = '30';
            app.checkbox_plot_barchart_ga = uicheckbox(app.uipanel15);
            app.checkbox_plot_barchart_ga.ValueChangedFcn = createCallbackFcn(app, @checkbox_plot_barchart_ga_Callback, true);
            app.checkbox_plot_barchart_ga.Tag = 'checkbox_plot_barchart_ga';
            app.checkbox_plot_barchart_ga.Enable = 'off';
            app.checkbox_plot_barchart_ga.Text = 'Summary Stat.';
            app.checkbox_plot_barchart_ga.Value = true;
            app.text32 = uilabel(app.uipanel15);
            app.text32.Tag = 'text32';
            app.text32.Text = 's';
            app.popupmenu_bar_feature = uidropdown(app.uipanel15);
            app.popupmenu_bar_feature.Items = {'Mean', 'Median', 'Count'};
            app.popupmenu_bar_feature.ValueChangedFcn = createCallbackFcn(app, @popupmenu_bar_feature_Callback, true);
            app.popupmenu_bar_feature.Tag = 'popupmenu_bar_feature';
            app.popupmenu_bar_feature.Value = 'Mean';
            app.checkbox_plot_barchart_error = uicheckbox(app.uipanel15);
            app.checkbox_plot_barchart_error.ValueChangedFcn = createCallbackFcn(app, @checkbox_plot_barchart_error_Callback, true);
            app.checkbox_plot_barchart_error.Tag = 'checkbox_plot_barchart_error';
            app.checkbox_plot_barchart_error.Enable = 'off';
            app.checkbox_plot_barchart_error.Text = 'Error';
            app.checkbox_plot_barchart_error.Value = true;
            app.popupmenu_bar_error_feature = uidropdown(app.uipanel15);
            app.popupmenu_bar_error_feature.Items = {'SEM', 'SD', 'MaxMin'};
            app.popupmenu_bar_error_feature.ValueChangedFcn = createCallbackFcn(app, @popupmenu_bar_error_feature_Callback, true);
            app.popupmenu_bar_error_feature.Tag = 'popupmenu_bar_error_feature';
            app.popupmenu_bar_error_feature.Value = 'SEM';
            app.edit_bar_error_multiplier = uieditfield(app.uipanel15, 'text');
            app.edit_bar_error_multiplier.ValueChangedFcn = createCallbackFcn(app, @edit_bar_error_multiplier_Callback, true);
            app.edit_bar_error_multiplier.Tag = 'edit_bar_error_multiplier';
            app.edit_bar_error_multiplier.Value = '1';
            app.text38 = uilabel(app.uipanel15);
            app.text38.Tag = 'text38';
            app.text38.Text = 'x';
            app.checkbox_plot_barchart_all_points = uicheckbox(app.uipanel15);
            app.checkbox_plot_barchart_all_points.ValueChangedFcn = createCallbackFcn(app, @checkbox_plot_barchart_all_points_Callback, true);
            app.checkbox_plot_barchart_all_points.Tag = 'checkbox_plot_barchart_all_points';
            app.checkbox_plot_barchart_all_points.Enable = 'off';
            app.checkbox_plot_barchart_all_points.Text = 'All Points';
            app.pushbutton_plot_barchart = uibutton(app.uipanel15, 'push');
            app.pushbutton_plot_barchart.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_plot_barchart_Callback, true);
            app.pushbutton_plot_barchart.Tag = 'pushbutton_plot_barchart';
            app.pushbutton_plot_barchart.Text = 'Plot';
            app.pushbutton_export_csv = uibutton(app.uipanel15, 'push');
            app.pushbutton_export_csv.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_export_csv_Callback, true);
            app.pushbutton_export_csv.Tag = 'pushbutton_export_csv';
            app.pushbutton_export_csv.Text = 'Export Data';
            app.checkbox_export_9999 = uicheckbox(app.uipanel15);
            app.checkbox_export_9999.ValueChangedFcn = createCallbackFcn(app, @checkbox_export_9999_Callback, true);
            app.checkbox_export_9999.Tag = 'checkbox_export_9999';
            app.checkbox_export_9999.Tooltip = 'Replaces missing values with -9999';
            app.checkbox_export_9999.Text = '-9999 as nan';
            app.uipanel16 = uipanel(app.figure1);
            app.uipanel16.Title = 'Scatterplot';
            app.uipanel16.Tag = 'uipanel16';
            app.checkbox_plot_scatter_error = uicheckbox(app.uipanel16);
            app.checkbox_plot_scatter_error.ValueChangedFcn = createCallbackFcn(app, @checkbox_plot_scatter_error_Callback, true);
            app.checkbox_plot_scatter_error.Tag = 'checkbox_plot_scatter_error';
            app.checkbox_plot_scatter_error.Text = 'Error';
            app.popupmenu_scatter_error_feature = uidropdown(app.uipanel16);
            app.popupmenu_scatter_error_feature.Items = {'95%CI', 'SEM', 'SD', '50%PI', '67%PI', '90%PI', '95%PI'};
            app.popupmenu_scatter_error_feature.ValueChangedFcn = createCallbackFcn(app, @popupmenu_scatter_error_feature_Callback, true);
            app.popupmenu_scatter_error_feature.Tag = 'popupmenu_scatter_error_feature';
            app.popupmenu_scatter_error_feature.Value = '90%PI';
            app.edit_scatter_error_multiplier = uieditfield(app.uipanel16, 'text');
            app.edit_scatter_error_multiplier.ValueChangedFcn = createCallbackFcn(app, @edit_scatter_error_multiplier_Callback, true);
            app.edit_scatter_error_multiplier.Tag = 'edit_scatter_error_multiplier';
            app.edit_scatter_error_multiplier.Value = '1';
            app.text55 = uilabel(app.uipanel16);
            app.text55.Tag = 'text55';
            app.text55.Text = 'x';
            app.checkbox_plot_scatter_nonparametric = uicheckbox(app.uipanel16);
            app.checkbox_plot_scatter_nonparametric.ValueChangedFcn = createCallbackFcn(app, @checkbox_plot_scatter_nonparametric_Callback, true);
            app.checkbox_plot_scatter_nonparametric.Tag = 'checkbox_plot_scatter_nonparametric';
            app.checkbox_plot_scatter_nonparametric.Text = 'Non-Parametric';
            app.checkbox_plot_scatter_line = uicheckbox(app.uipanel16);
            app.checkbox_plot_scatter_line.ValueChangedFcn = createCallbackFcn(app, @checkbox_plot_scatter_line_Callback, true);
            app.checkbox_plot_scatter_line.Tag = 'checkbox_plot_scatter_line';
            app.checkbox_plot_scatter_line.Text = 'Best-Fit Line';
            app.checkbox_plot_scatter_line.Value = true;
            app.popupmenu_scatter_error_style = uidropdown(app.uipanel16);
            app.popupmenu_scatter_error_style.Items = {'Dashed', 'Fine', 'Shaded'};
            app.popupmenu_scatter_error_style.ValueChangedFcn = createCallbackFcn(app, @popupmenu_scatter_error_style_Callback, true);
            app.popupmenu_scatter_error_style.Tag = 'popupmenu_scatter_error_style';
            app.popupmenu_scatter_error_style.Value = 'Shaded';
            app.text56 = uilabel(app.uipanel16);
            app.text56.Tag = 'text56';
            app.text56.Text = 'Error Style';
            app.checkbox_plot_scatter_extend = uicheckbox(app.uipanel16);
            app.checkbox_plot_scatter_extend.ValueChangedFcn = createCallbackFcn(app, @checkbox_plot_scatter_extend_Callback, true);
            app.checkbox_plot_scatter_extend.Tag = 'checkbox_plot_scatter_extend';
            app.checkbox_plot_scatter_extend.Text = 'Extend';
            app.checkbox_plot_scatter_extend.Value = true;
            app.checkbox_plot_scatter_flipxy = uicheckbox(app.uipanel16);
            app.checkbox_plot_scatter_flipxy.ValueChangedFcn = createCallbackFcn(app, @checkbox_plot_scatter_flipxy_Callback, true);
            app.checkbox_plot_scatter_flipxy.Tag = 'checkbox_plot_scatter_flipxy';
            app.checkbox_plot_scatter_flipxy.Text = 'Plot [HbX] on X-axis';
            app.pushbutton_plot_scatter = uibutton(app.uipanel16, 'push');
            app.pushbutton_plot_scatter.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_plot_scatter_Callback, true);
            app.pushbutton_plot_scatter.Tag = 'pushbutton_plot_scatter';
            app.pushbutton_plot_scatter.Text = 'Plot';
            app.text62 = uilabel(app.uipanel16);
            app.text62.Tag = 'text62';
            app.text62.Text = 'Plots [HbX] vs Info Field (barchart)';
            app.pushbutton_plot_scatter_topo = uibutton(app.uipanel16, 'push');
            app.pushbutton_plot_scatter_topo.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_plot_scatter_topo_Callback, true);
            app.pushbutton_plot_scatter_topo.Tag = 'pushbutton_plot_scatter_topo';
            app.pushbutton_plot_scatter_topo.Text = 'Plot Topo';
            app.uipanel17 = uipanel(app.figure1);
            app.uipanel17.Title = 'Plot By Factor';
            app.uipanel17.Tag = 'uipanel17';
            app.popupmenu_info_group = uidropdown(app.uipanel17);
            app.popupmenu_info_group.Items = {'', 'SubjectID', 'Group', 'Subgroup', 'Session', 'Trial', 'Condition', 'Block'};
            app.popupmenu_info_group.ValueChangedFcn = createCallbackFcn(app, @popupmenu_info_group_Callback, true);
            app.popupmenu_info_group.Tag = 'popupmenu_info_group';
            app.popupmenu_info_group.Value = '';
            app.uipanel9 = uipanel(app.figure1);
            app.uipanel9.Title = 'Info Field Barchart';
            app.uipanel9.Tag = 'uipanel9';
            app.popupmenu_info_field = uidropdown(app.uipanel9);
            app.popupmenu_info_field.Items = {};
            app.popupmenu_info_field.ValueChangedFcn = createCallbackFcn(app, @popupmenu_info_field_Callback, true);
            app.popupmenu_info_field.Tag = 'popupmenu_info_field';
            app.popupmenu_info_field.Value = {};
            app.text45 = uilabel(app.uipanel9);
            app.text45.Tag = 'text45';
            app.text45.Text = 'Info Field';
            app.pushbutton_infoData_barchart = uibutton(app.uipanel9, 'push');
            app.pushbutton_infoData_barchart.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_infoData_barchart_Callback, true);
            app.pushbutton_infoData_barchart.Tag = 'pushbutton_infoData_barchart';
            app.pushbutton_infoData_barchart.Text = 'Plot';
            app.checkbox_LME_info_covariate = uicheckbox(app.uipanel9);
            app.checkbox_LME_info_covariate.ValueChangedFcn = createCallbackFcn(app, @checkbox_LME_info_covariate_Callback, true);
            app.checkbox_LME_info_covariate.Tag = 'checkbox_LME_info_covariate';
            app.checkbox_LME_info_covariate.Tooltip = 'Select to enable the use of the info field as a covariate';
            app.checkbox_LME_info_covariate.Text = 'LME covariate';
            app.pushbutton_infoData_scatter = uibutton(app.uipanel9, 'push');
            app.pushbutton_infoData_scatter.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_infoData_scatter_Callback, true);
            app.pushbutton_infoData_scatter.Tag = 'pushbutton_infoData_scatter';
            app.pushbutton_infoData_scatter.Tooltip = 'Scatter plot vs custom factor field';
            app.pushbutton_infoData_scatter.Text = 'Scatter';
            app.text_status_text = uilabel(app.figure1);
            app.text_status_text.Tag = 'text_status_text';
            app.text_status_text.Text = '';
            app.uipanel19 = uipanel(app.figure1);
            app.uipanel19.Title = 'Save/Load Experiment';
            app.uipanel19.Tag = 'uipanel19';
            app.pushbutton_exLoad = uibutton(app.uipanel19, 'push');
            app.pushbutton_exLoad.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_exLoad_Callback, true);
            app.pushbutton_exLoad.Tag = 'pushbutton_exLoad';
            app.pushbutton_exLoad.Text = 'Load Ex';
            app.pushbutton_exSave = uibutton(app.uipanel19, 'push');
            app.pushbutton_exSave.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_exSave_Callback, true);
            app.pushbutton_exSave.Tag = 'pushbutton_exSave';
            app.pushbutton_exSave.Text = 'Save Ex';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exploreFNIRS_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)exploreFNIRS_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end